classdef SimTask_TopologyOptimization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ExportReconstructedDesignIsosurfaceMenu  matlab.ui.container.Menu
        OptiHistoryMenu                 matlab.ui.container.Menu
        ComplianceMenu                  matlab.ui.container.Menu
        VolumeFractionMenu              matlab.ui.container.Menu
        SharpnessMenu                   matlab.ui.container.Menu
        PeriterationTimingsMenu         matlab.ui.container.Menu
        TopologyOptimizationPanel       matlab.ui.container.Panel
        ResultDisplayPanel              matlab.ui.container.Panel
        DesignVolumeFractionEditField   matlab.ui.control.NumericEditField
        DesignVolumeFractionEditFieldLabel  matlab.ui.control.Label
        DesignComplianceEditField       matlab.ui.control.NumericEditField
        DesignComplianceEditFieldLabel  matlab.ui.control.Label
        OptimizationProcessSettingsPanel  matlab.ui.container.Panel
        ConvergeMinChangeEditField      matlab.ui.control.NumericEditField
        ConvergeMinChangeEditFieldLabel  matlab.ui.control.Label
        ConvergeSharpnessEditField      matlab.ui.control.NumericEditField
        ConvergeSharpnessEditFieldLabel  matlab.ui.control.Label
        MovingStepsizeEditField         matlab.ui.control.NumericEditField
        MovingStepsizeEditField_2Label  matlab.ui.control.Label
        OptimizerDropDown               matlab.ui.control.DropDown
        OptimizerDropDown_2Label        matlab.ui.control.Label
        IterationsforOptimizationEditField  matlab.ui.control.NumericEditField
        IterationsforOptimizationEditFieldLabel  matlab.ui.control.Label
        ConductOptimizationPanel        matlab.ui.container.Panel
        TailoredStartingGuessifavailableCheckBox  matlab.ui.control.CheckBox
        MaterialBudgetEditField         matlab.ui.control.NumericEditField
        MaterialBudgetEditField_2Label  matlab.ui.control.Label
        RunTopologyOptimizationButton   matlab.ui.control.Button
        DebugModeCheckBox               matlab.ui.control.CheckBox
        FilteringProjectionPenaltyPanel  matlab.ui.container.Panel
        MaximumPenaltyofHeavisideProjectionEditField  matlab.ui.control.NumericEditField
        MaximumPenaltyofHeavisideProjectionEditFieldLabel  matlab.ui.control.Label
        RadiusofDensitybasedFilterEditField  matlab.ui.control.NumericEditField
        RadiusofDensitybasedFilterEditField_2Label  matlab.ui.control.Label
        SpecifyDesignDomainPanel        matlab.ui.container.Panel
        VolumeFractionPassiveElementsEditField  matlab.ui.control.NumericEditField
        VolumeFractionPassiveElementsEditFieldLabel  matlab.ui.control.Label
        ClearPassiveElementsButton      matlab.ui.control.Button
        FixedAreaEditField              matlab.ui.control.NumericEditField
        FixedAreaEditFieldLabel         matlab.ui.control.Label
        LoadingAreaEditField            matlab.ui.control.NumericEditField
        LoadingAreaEditField_2Label     matlab.ui.control.Label
        EntireBoundaryEditField         matlab.ui.control.NumericEditField
        EntireBoundaryEditField_2Label  matlab.ui.control.Label
        SetPassiveElementsButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
            app.FileMenu.Enable = 'off';
            app.OptiHistoryMenu.Enable = 'off';
            app.DesignComplianceEditField.Editable = 'off';
            app.DesignVolumeFractionEditField.Editable = 'off';
            app.VolumeFractionPassiveElementsEditField.Editable = 'off';
            app.VolumeFractionPassiveElementsEditField.Value = TopOpti_GetVolumeFractionOfPassiveElements();
            ShowDesignDomain_Public(app.MainApp);  
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                % app.MainApp.SimulationTasksDropDown.Enable = 'on';
                % app.MainApp.FEAwithSolidDesignDomainButton.Enable = 'on';
                MainWindowCtrl(app.MainApp, 1);
                app.MainApp.SimulationTasksDropDown.Value = 'None';
            end
            delete(app)               
        end

        % Button pushed function: SetPassiveElementsButton
        function SetPassiveElementsButtonPushed(app, event)
            global voxelsOnBoundary_;

            app.SpecifyDesignDomainPanel.Enable = 'off';
            app.ConductOptimizationPanel.Enable = 'off';
            pause(1);

            numLayerboundary = app.EntireBoundaryEditField.Value;
            numLayerLoads = app.LoadingAreaEditField.Value;
            numLayerFixation = app.FixedAreaEditField.Value;            
            [voxelsOnBoundary_,~,~] = TopOpti_SetPassiveElements(numLayerboundary, numLayerLoads, numLayerFixation);
            app.VolumeFractionPassiveElementsEditField.Value = TopOpti_GetVolumeFractionOfPassiveElements();
            ShowDesignDomain_Public(app.MainApp);  

            app.SpecifyDesignDomainPanel.Enable = 'on';
            app.ConductOptimizationPanel.Enable = 'on';           
        end

        % Button pushed function: RunTopologyOptimizationButton
        function RunTopologyOptimizationButton_Pushed(app, event)
            global rMin_;
            global pMax_;
            global constraintType_;
            global V_;
            global nLoop_;
            global maxSharpness_;
            global minChange_;
            global optimizer_;
            global move_;
            global complianceDesign_;
            global volumeFractionDesign_;
            global complianceSolid_;
            global DEBUG_;
            global SGopt_;
            global axHandle_;

            app.SpecifyDesignDomainPanel.Enable = 'off';
            app.FilteringProjectionPenaltyPanel.Enable = 'off';
            app.OptimizationProcessSettingsPanel.Enable = 'off';
            app.ConductOptimizationPanel.Enable = 'off';
            app.ResultDisplayPanel.Enable = 'off';
            pause(1);

            GatherLSSandMPsettings(app.MainApp);
            rMin_ = app.RadiusofDensitybasedFilterEditField.Value; rMin_ = max(rMin_, 1.6);
            pMax_ = app.MaximumPenaltyofHeavisideProjectionEditField.Value; pMax_ = max(pMax_, 128);
            constraintType_ = 'Global';
            V_ = app.MaterialBudgetEditField.Value;
            nLoop_ = app.IterationsforOptimizationEditField.Value;
            maxSharpness_ = app.ConvergeSharpnessEditField.Value;
            minChange_ = app.ConvergeMinChangeEditField.Value;
            optimizer_ = app.OptimizerDropDown.Value;
            move_ = app.MovingStepsizeEditField.Value;
            DEBUG_ = app.DebugModeCheckBox.Value;
            SGopt_ = app.TailoredStartingGuessifavailableCheckBox.Value;

            TopOpti_CallTopOpti(axHandle_);            
            ShowDesignDensityLayout_Public(app.MainApp);           

            app.FileMenu.Enable = 'on';                
            app.OptiHistoryMenu.Enable = 'on';            
            app.SpecifyDesignDomainPanel.Enable = 'on';
            app.FilteringProjectionPenaltyPanel.Enable = 'on';
            app.OptimizationProcessSettingsPanel.Enable = 'on';
            app.ConductOptimizationPanel.Enable = 'on';
            app.ResultDisplayPanel.Enable = 'on';            
                app.DesignComplianceEditField.Value = complianceDesign_;
                app.DesignVolumeFractionEditField.Value = volumeFractionDesign_;
            app.MainApp.ShowDesignbyIsosurfaceNotrecommendedMenu.Enable = 'on'; 
            app.MainApp.SolidComplianceEditField.Value = complianceSolid_;            
            app.MainApp.DesignVolEditField.Value = volumeFractionDesign_;
            app.MainApp.DesignComplianceEditField.Value = complianceDesign_;
            app.MainApp.StressFieldinSolidDomainMenu.Enable = 'on';
            app.MainApp.StressFieldinDesignMenu.Enable = 'on';
        end

        % Button pushed function: ClearPassiveElementsButton
        function ClearPassiveElementsButtonPushed(app, event)
            global passiveElements_;
            passiveElements_ = [];
            app.EntireBoundaryEditField.Value = 0;
            app.LoadingAreaEditField.Value = 0;
            app.FixedAreaEditField.Value = 0;
            app.VolumeFractionPassiveElementsEditField.Value = 0.0;
            ShowDesignDomain_Public(app.MainApp);  
        end

        % Menu selected function: ComplianceMenu
        function ComplianceMenuSelected(app, event)
            global cHist_;
            if ~isempty(cHist_)
                figure;
                plot(cHist_, '-', 'Color', [0 176 80]/255, 'LineWidth', 3);
                xlabel('#Iterations'); ylabel('Compliance');
                set(gca, 'FontName', 'Times New Roman', 'FontSize', 40);
            end            
        end

        % Menu selected function: VolumeFractionMenu
        function VolumeFractionMenuSelected(app, event)
            global volHist_;
            if ~isempty(volHist_)
                figure;
                plot(volHist_, '-', 'Color', [0 176 80]/255, 'LineWidth', 3);
                xlabel('#Iterations'); ylabel('Volume fraction');
                set(gca, 'FontName', 'Times New Roman', 'FontSize', 40);
            end            
        end

        % Menu selected function: SharpnessMenu
        function SharpnessMenuSelected(app, event)
            global sharpHist_;
            if ~isempty(sharpHist_)
                figure;
                plot(sharpHist_, '-', 'Color', [0 176 80]/255, 'LineWidth', 3);
                xlabel('#Iterations'); ylabel('Sharpness');
                set(gca, 'FontName', 'Times New Roman', 'FontSize', 40);
            end             
        end

        % Menu selected function: PeriterationTimingsMenu
        function PeriterationTimingsMenuSelected(app, event)
            global tHist_;
            if ~isempty(tHist_)
                figure;
                plot(tHist_(:,end), '-', 'Color', [0 176 80]/255, 'LineWidth', 3);
                xlabel('#Iterations'); ylabel('Timings');
                set(gca, 'FontName', 'Times New Roman', 'FontSize', 40);
            end            
        end

        % Menu selected function: ExportReconstructedDesignIsosurfaceMenu
        function ExportReconstructedDesignIsosurfaceMenuSelected(app, event)
            global axHandle_;
            global FVreconstructed_;

            if ~ispc, warning('Only Work with Windows OS!'); return; end
            [fileName, dataPath] = uiputfile('*.ply', 'Select a Path to Write');
            if isnumeric(fileName) || isnumeric(dataPath), return; end            
            ofileName = strcat(dataPath,fileName);
            IO_ExtractReconstructExportDesignIsosurface(ofileName); 

            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_); colorbar(axHandle_, 'off'); 
            Vis_DrawMesh3D(axHandle_, FVreconstructed_.nodeCoords, FVreconstructed_.eNodMat, 1);
            view(axHandle_, az, el);       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ExportReconstructedDesignIsosurfaceMenu = uimenu(app.FileMenu);
            app.ExportReconstructedDesignIsosurfaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportReconstructedDesignIsosurfaceMenuSelected, true);
            app.ExportReconstructedDesignIsosurfaceMenu.Text = 'Export Re-constructed Design Isosurface';
            app.OptiHistoryMenu = uimenu(app.UIFigure);
            app.OptiHistoryMenu.Text = 'Opti. History';
            app.ComplianceMenu = uimenu(app.OptiHistoryMenu);
            app.ComplianceMenu.MenuSelectedFcn = createCallbackFcn(app, @ComplianceMenuSelected, true);
            app.ComplianceMenu.Text = 'Compliance';
            app.VolumeFractionMenu = uimenu(app.OptiHistoryMenu);
            app.VolumeFractionMenu.MenuSelectedFcn = createCallbackFcn(app, @VolumeFractionMenuSelected, true);
            app.VolumeFractionMenu.Text = 'Volume Fraction';
            app.SharpnessMenu = uimenu(app.OptiHistoryMenu);
            app.SharpnessMenu.MenuSelectedFcn = createCallbackFcn(app, @SharpnessMenuSelected, true);
            app.SharpnessMenu.Text = 'Sharpness';
            app.PeriterationTimingsMenu = uimenu(app.OptiHistoryMenu);
            app.PeriterationTimingsMenu.MenuSelectedFcn = createCallbackFcn(app, @PeriterationTimingsMenuSelected, true);
            app.PeriterationTimingsMenu.Text = 'Per-iteration Timings';
            app.TopologyOptimizationPanel = uipanel(app.UIFigure);
            app.TopologyOptimizationPanel.Title = 'Topology Optimization';
            app.SpecifyDesignDomainPanel = uipanel(app.TopologyOptimizationPanel);
            app.SpecifyDesignDomainPanel.Title = 'Specify Design Domain';
            app.SetPassiveElementsButton = uibutton(app.SpecifyDesignDomainPanel, 'push');
            app.SetPassiveElementsButton.ButtonPushedFcn = createCallbackFcn(app, @SetPassiveElementsButtonPushed, true);
            app.SetPassiveElementsButton.Text = 'Set Passive Elements';
            app.EntireBoundaryEditField_2Label = uilabel(app.SpecifyDesignDomainPanel);
            app.EntireBoundaryEditField_2Label.Text = 'Entire Boundary';
            app.EntireBoundaryEditField = uieditfield(app.SpecifyDesignDomainPanel, 'numeric');
            app.EntireBoundaryEditField.ValueDisplayFormat = '%.0f';
            app.LoadingAreaEditField_2Label = uilabel(app.SpecifyDesignDomainPanel);
            app.LoadingAreaEditField_2Label.Text = 'Loading Area';
            app.LoadingAreaEditField = uieditfield(app.SpecifyDesignDomainPanel, 'numeric');
            app.LoadingAreaEditField.ValueDisplayFormat = '%.0f';
            app.FixedAreaEditFieldLabel = uilabel(app.SpecifyDesignDomainPanel);
            app.FixedAreaEditFieldLabel.Text = 'Fixed Area';
            app.FixedAreaEditField = uieditfield(app.SpecifyDesignDomainPanel, 'numeric');
            app.FixedAreaEditField.ValueDisplayFormat = '%.0f';
            app.ClearPassiveElementsButton = uibutton(app.SpecifyDesignDomainPanel, 'push');
            app.ClearPassiveElementsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPassiveElementsButtonPushed, true);
            app.ClearPassiveElementsButton.Text = 'Clear Passive Elements';
            app.VolumeFractionPassiveElementsEditFieldLabel = uilabel(app.SpecifyDesignDomainPanel);
            app.VolumeFractionPassiveElementsEditFieldLabel.Text = 'Volume Fraction (Passive Elements)';
            app.VolumeFractionPassiveElementsEditField = uieditfield(app.SpecifyDesignDomainPanel, 'numeric');
            app.FilteringProjectionPenaltyPanel = uipanel(app.TopologyOptimizationPanel);
            app.FilteringProjectionPenaltyPanel.Title = 'Filtering&Projection&Penalty';
            app.RadiusofDensitybasedFilterEditField_2Label = uilabel(app.FilteringProjectionPenaltyPanel);
            app.RadiusofDensitybasedFilterEditField_2Label.Text = 'Radius of Density-based Filter';
            app.RadiusofDensitybasedFilterEditField = uieditfield(app.FilteringProjectionPenaltyPanel, 'numeric');
            app.RadiusofDensitybasedFilterEditField.Value = 2.6;
            app.MaximumPenaltyofHeavisideProjectionEditFieldLabel = uilabel(app.FilteringProjectionPenaltyPanel);
            app.MaximumPenaltyofHeavisideProjectionEditFieldLabel.Text = 'Maximum Penalty of Heaviside Projection';
            app.MaximumPenaltyofHeavisideProjectionEditField = uieditfield(app.FilteringProjectionPenaltyPanel, 'numeric');
            app.MaximumPenaltyofHeavisideProjectionEditField.ValueDisplayFormat = '%.0f';
            app.MaximumPenaltyofHeavisideProjectionEditField.Value = 128;
            app.ConductOptimizationPanel = uipanel(app.TopologyOptimizationPanel);
            app.ConductOptimizationPanel.Title = 'Conduct Optimization';
            app.DebugModeCheckBox = uicheckbox(app.ConductOptimizationPanel);
            app.DebugModeCheckBox.Text = 'Debug Mode';
            app.RunTopologyOptimizationButton = uibutton(app.ConductOptimizationPanel, 'push');
            app.RunTopologyOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @RunTopologyOptimizationButton_Pushed, true);
            app.RunTopologyOptimizationButton.Text = 'Run Topology Optimization';
            app.MaterialBudgetEditField_2Label = uilabel(app.ConductOptimizationPanel);
            app.MaterialBudgetEditField_2Label.Text = 'Material Budget';
            app.MaterialBudgetEditField = uieditfield(app.ConductOptimizationPanel, 'numeric');
            app.MaterialBudgetEditField.Value = 0.4;
            app.TailoredStartingGuessifavailableCheckBox = uicheckbox(app.ConductOptimizationPanel);
            app.TailoredStartingGuessifavailableCheckBox.Text = 'Tailored Starting Guess (if available)';
            app.OptimizationProcessSettingsPanel = uipanel(app.TopologyOptimizationPanel);
            app.OptimizationProcessSettingsPanel.Title = 'Optimization Process Settings';
            app.IterationsforOptimizationEditFieldLabel = uilabel(app.OptimizationProcessSettingsPanel);
            app.IterationsforOptimizationEditFieldLabel.Text = '#Iterations for Optimization';
            app.IterationsforOptimizationEditField = uieditfield(app.OptimizationProcessSettingsPanel, 'numeric');
            app.IterationsforOptimizationEditField.ValueDisplayFormat = '%.0f';
            app.IterationsforOptimizationEditField.Value = 50;
            app.OptimizerDropDown_2Label = uilabel(app.OptimizationProcessSettingsPanel);
            app.OptimizerDropDown_2Label.Text = 'Optimizer';
            app.OptimizerDropDown = uidropdown(app.OptimizationProcessSettingsPanel);
            app.OptimizerDropDown.Items = {'OC', 'MMA'};
            app.OptimizerDropDown.Value = 'OC';
            app.MovingStepsizeEditField_2Label = uilabel(app.OptimizationProcessSettingsPanel);
            app.MovingStepsizeEditField_2Label.Text = 'Moving Stepsize';
            app.MovingStepsizeEditField = uieditfield(app.OptimizationProcessSettingsPanel, 'numeric');
            app.MovingStepsizeEditField.Value = 0.1;
            app.ConvergeSharpnessEditFieldLabel = uilabel(app.OptimizationProcessSettingsPanel);
            app.ConvergeSharpnessEditFieldLabel.Text = 'Converge Sharpness';
            app.ConvergeSharpnessEditField = uieditfield(app.OptimizationProcessSettingsPanel, 'numeric');
            app.ConvergeSharpnessEditField.Value = 0.05;
            app.ConvergeMinChangeEditFieldLabel = uilabel(app.OptimizationProcessSettingsPanel);
            app.ConvergeMinChangeEditFieldLabel.Text = 'Converge Min Change';
            app.ConvergeMinChangeEditField = uieditfield(app.OptimizationProcessSettingsPanel, 'numeric');
            app.ConvergeMinChangeEditField.Value = 0.0001;
            app.ResultDisplayPanel = uipanel(app.TopologyOptimizationPanel);
            app.ResultDisplayPanel.Title = 'Result Display';
            app.DesignComplianceEditFieldLabel = uilabel(app.ResultDisplayPanel);
            app.DesignComplianceEditFieldLabel.Text = 'Design Compliance';
            app.DesignComplianceEditField = uieditfield(app.ResultDisplayPanel, 'numeric');
            app.DesignVolumeFractionEditFieldLabel = uilabel(app.ResultDisplayPanel);
            app.DesignVolumeFractionEditFieldLabel.Text = 'Design Volume Fraction';
            app.DesignVolumeFractionEditField = uieditfield(app.ResultDisplayPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimTask_TopologyOptimization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end