classdef Mdl_CreateSphereDesignDomain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CreateSphereShellDesignDomainPanel  matlab.ui.container.Panel
        CreateButton                    matlab.ui.control.Button
        InnerRadiusRatioEditField       matlab.ui.control.NumericEditField
        InnerRadiusRatioEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.BuiltinShapesMenu.Enable = 'on';
            end
            delete(app)            
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            ratioInnerRadius = app.InnerRadiusRatioEditField.Value;
            if ratioInnerRadius<0 || ratioInnerRadius>1, return; end
            Shape_BuiltInSphereShell(ratioInnerRadius);
            app.MainApp.ShowTriSurfaceMesh_public();
            % app.MainApp.BuiltinShapesMenu.Enable = 'on';
            % delete(app);
            app.MainApp.VoxelizingButton.Enable = 'on';
            app.MainApp.InputTriSurfaceMeshMenu.Enable = 'on';            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CreateSphereShellDesignDomainPanel = uipanel(app.UIFigure);
            app.CreateSphereShellDesignDomainPanel.Title = 'Create Sphere (Shell) Design Domain';
            app.InnerRadiusRatioEditFieldLabel = uilabel(app.CreateSphereShellDesignDomainPanel);
            app.InnerRadiusRatioEditFieldLabel.Text = 'Inner Radius Ratio';
            app.InnerRadiusRatioEditField = uieditfield(app.CreateSphereShellDesignDomainPanel, 'numeric');
            app.CreateButton = uibutton(app.CreateSphereShellDesignDomainPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mdl_CreateSphereDesignDomain(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end