classdef ObjectSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ObjectSelectionPanel          matlab.ui.container.Panel
        P_zEditField                  matlab.ui.control.NumericEditField
        P_zEditFieldLabel             matlab.ui.control.Label
        P_yEditField                  matlab.ui.control.NumericEditField
        P_yEditFieldLabel             matlab.ui.control.Label
        P_xEditField                  matlab.ui.control.NumericEditField
        P_xEditFieldLabel             matlab.ui.control.Label
        WaystoLocatethePositionDropDown  matlab.ui.control.DropDown
        WaystoLocatethePositionDropDownLabel  matlab.ui.control.Label
        UnpickaPointButton            matlab.ui.control.Button
        PickaPointButton              matlab.ui.control.Button
        ClearallSelectionButton       matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        LineTab                       matlab.ui.container.Tab
        EndEditField_3                matlab.ui.control.NumericEditField
        EndEditField_3Label           matlab.ui.control.Label
        StartEditField_3              matlab.ui.control.NumericEditField
        StartEditField_3Label         matlab.ui.control.Label
        SetLineLengthCheckBox         matlab.ui.control.CheckBox
        ZDirCheckBox                  matlab.ui.control.CheckBox
        YDirCheckBox                  matlab.ui.control.CheckBox
        XDirCheckBox                  matlab.ui.control.CheckBox
        LineSelectionButton           matlab.ui.control.Button
        CircleTab                     matlab.ui.container.Tab
        CircleRadiusEditField_2       matlab.ui.control.NumericEditField
        CircleRadiusEditField_2Label  matlab.ui.control.Label
        PrescribedRadiusCheckBox      matlab.ui.control.CheckBox
        CircleSelectionButton         matlab.ui.control.Button
        FaceTab                       matlab.ui.container.Tab
        SelectionDirectionDropDown    matlab.ui.control.DropDown
        SelectionDirectionDropDownLabel  matlab.ui.control.Label
        ZXCheckBox                    matlab.ui.control.CheckBox
        YZCheckBox                    matlab.ui.control.CheckBox
        XYCheckBox                    matlab.ui.control.CheckBox
        FaceSelectionButton           matlab.ui.control.Button
        CylinderTab                   matlab.ui.container.Tab
        ZDirCheckBox_2                matlab.ui.control.CheckBox
        YDirCheckBox_2                matlab.ui.control.CheckBox
        XDirCheckBox_2                matlab.ui.control.CheckBox
        SetCylinderDepthCheckBox      matlab.ui.control.CheckBox
        EndEditField_4                matlab.ui.control.NumericEditField
        EndEditField_4Label           matlab.ui.control.Label
        StartEditField_4              matlab.ui.control.NumericEditField
        StartEditField_4Label         matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        CylinderSelectionButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            global axHandle_;
            global hdPickedNode_; hdPickedNode_ = [];
            global pickedNodeCache_; pickedNodeCache_ = [];
            app.MainApp = mainapp;
            ShowProblemDescription_Public(app.MainApp);
            axis(axHandle_, 'on'); xlabel('X'); ylabel('Y'); zlabel('Z');
            app.WaystoLocatethePositionDropDown.Value = 'Cursor';
            app.P_xEditField.Enable = 'off';
            app.P_yEditField.Enable = 'off';
            app.P_zEditField.Enable = 'off';
            app.XDirCheckBox.Value = 1;
            app.YDirCheckBox.Value = 0;
            app.ZDirCheckBox.Value = 0;
            app.SetLineLengthCheckBox.Value = 0;
            app.StartEditField_3.Enable = 'off';
            app.EndEditField_3.Enable = 'off';
            app.PrescribedRadiusCheckBox.Value = 0;
            app.CircleRadiusEditField_2.Enable = 'off';
            app.XYCheckBox.Value = 1;
            app.YZCheckBox.Value = 0;
            app.ZXCheckBox.Value = 0;
            app.SelectionDirectionDropDown.Value = 'Negative';
            app.SetCylinderDepthCheckBox.Value = 0;
            app.StartEditField_4.Enable = 'off';
            app.EndEditField_4.Enable = 'off';
            app.XDirCheckBox_2.Value = 1;
            app.YDirCheckBox_2.Value = 0;
            app.ZDirCheckBox_2.Value = 0;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.AdditionalNodeSelectionOptionsButton.Enable = 'on'; 
            end
            delete(app)
            
        end

        % Button pushed function: PickaPointButton
        function PickaPointButtonPushed(app, event)
            global axHandle_;
            switch app.WaystoLocatethePositionDropDown.Value
                case 'Cursor'
                    Interaction_PickByPoint(axHandle_);
                case 'TypeIn'
                    typedPosition = [app.P_xEditField.Value app.P_yEditField.Value app.P_zEditField.Value];
                    Interaction_PickByPoint(axHandle_, typedPosition);
            end
        end

        % Value changed function: WaystoLocatethePositionDropDown
        function WaystoLocatethePositionDropDownValueChanged(app, event)
            value = app.WaystoLocatethePositionDropDown.Value;
            switch value
                case 'TypeIn'
                    app.P_xEditField.Enable = 'on';
                    app.P_yEditField.Enable = 'on';
                    app.P_zEditField.Enable = 'on';
                case 'Cursor'
                    app.P_xEditField.Enable = 'off';
                    app.P_yEditField.Enable = 'off';
                    app.P_zEditField.Enable = 'off';                    
            end
        end

        % Button pushed function: UnpickaPointButton
        function UnpickaPointButtonPushed(app, event)
            global axHandle_;
            Interaction_UnPickPoint(axHandle_);
        end

        % Button pushed function: ClearallSelectionButton
        function ClearallSelectionButtonPushed(app, event)
            % global hdPickedNode_;
            % global pickedNodeCache_; 
            % set(hdPickedNode_, 'visible', 'off');
            % pickedNodeCache_ = [];
            Interaction_ClearPickedNodes();
        end

        % Value changed function: XDirCheckBox
        function XDirCheckBoxValueChanged(app, event)
            value = app.XDirCheckBox.Value;
            if value
                app.YDirCheckBox.Value = 0;
                app.ZDirCheckBox.Value = 0;
            end
        end

        % Value changed function: YDirCheckBox
        function YDirCheckBoxValueChanged(app, event)
            value = app.YDirCheckBox.Value;
            if value
                app.XDirCheckBox.Value = 0;
                app.ZDirCheckBox.Value = 0;
            end            
        end

        % Value changed function: ZDirCheckBox
        function ZDirCheckBoxValueChanged(app, event)
            value = app.ZDirCheckBox.Value;
            if value
                app.XDirCheckBox.Value = 0;
                app.YDirCheckBox.Value = 0;
            end            
        end

        % Value changed function: SetLineLengthCheckBox
        function SetLineLengthCheckBoxValueChanged(app, event)
            value = app.SetLineLengthCheckBox.Value;
            if value
                app.StartEditField_3.Enable = 'on';
                app.EndEditField_3.Enable = 'on';                
            else
                app.StartEditField_3.Enable = 'off';
                app.EndEditField_3.Enable = 'off';
            end
        end

        % Button pushed function: LineSelectionButton
        function LineSelectionButtonPushed(app, event)
            global axHandle_;
            if ~(app.XDirCheckBox.Value || app.YDirCheckBox.Value || app.ZDirCheckBox.Value), return; end
            if app.XDirCheckBox.Value, lineDir = "X"; end
            if app.YDirCheckBox.Value, lineDir = "Y"; end
            if app.ZDirCheckBox.Value, lineDir = "Z"; end
            if app.SetLineLengthCheckBox.Value
                endPoints = [app.StartEditField_3.Value, app.EndEditField_3.Value];
                Interaction_PickByLine(axHandle_, lineDir, endPoints);
            else
                Interaction_PickByLine(axHandle_, lineDir);
            end         
        end

        % Value changed function: PrescribedRadiusCheckBox
        function PrescribedRadiusCheckBoxValueChanged(app, event)
            value = app.PrescribedRadiusCheckBox.Value;
            if value
                app.CircleRadiusEditField_2.Enable = 'on';
            else
                app.CircleRadiusEditField_2.Enable = 'off';
            end
        end

        % Button pushed function: CircleSelectionButton
        function CircleSelectionButtonPushed(app, event)
            global axHandle_;
            if app.PrescribedRadiusCheckBox.Value            
                Interaction_PickByCircle(axHandle_, app.CircleRadiusEditField_2.Value);
            else
                Interaction_PickByCircle(axHandle_);
            end            
        end

        % Value changed function: XYCheckBox
        function XYCheckBoxValueChanged(app, event)
            value = app.XYCheckBox.Value;
            if value
                app.YZCheckBox.Value = 0;
                app.ZXCheckBox.Value = 0;
            end
        end

        % Value changed function: YZCheckBox
        function YZCheckBoxValueChanged(app, event)
            value = app.YZCheckBox.Value;
            if value
                app.XYCheckBox.Value = 0;
                app.ZXCheckBox.Value = 0;
            end            
        end

        % Value changed function: ZXCheckBox
        function ZXCheckBoxValueChanged(app, event)
            value = app.ZXCheckBox.Value;
            if value
                app.XYCheckBox.Value = 0;
                app.YZCheckBox.Value = 0;
            end            
        end

        % Button pushed function: FaceSelectionButton
        function FaceSelectionButtonPushed(app, event)
            global axHandle_;
            if app.XYCheckBox.Value && app.YZCheckBox.Value && app.ZXCheckBox.Value, return; end
            if app.XYCheckBox.Value, constantDir = "Z"; end
            if app.YZCheckBox.Value, constantDir = "X"; end
            if app.ZXCheckBox.Value, constantDir = "Y"; end
            switch app.SelectionDirectionDropDown.Value
                case 'Positive', opt = 1;
                case 'Negative', opt = -1;
                case 'Neutral', opt = 0;
            end
            Interaction_PickByFace(axHandle_, constantDir, opt);           
        end

        % Value changed function: SetCylinderDepthCheckBox
        function SetCylinderDepthCheckBoxValueChanged(app, event)
            value = app.SetCylinderDepthCheckBox.Value;
            if value
                app.StartEditField_4.Enable = 'on';
                app.EndEditField_4.Enable = 'on';                
            else
                app.StartEditField_4.Enable = 'off';
                app.EndEditField_4.Enable = 'off';
            end            
        end

        % Button pushed function: CylinderSelectionButton
        function CylinderSelectionButtonPushed(app, event)
            global axHandle_;
            if ~(app.XDirCheckBox_2.Value || app.YDirCheckBox_2.Value || app.ZDirCheckBox_2.Value), return; end
            if app.XDirCheckBox_2.Value, cylinderDir = "X"; end
            if app.YDirCheckBox_2.Value, cylinderDir = "Y"; end
            if app.ZDirCheckBox_2.Value, cylinderDir = "Z"; end
            if app.SetCylinderDepthCheckBox.Value
                ctrlPoints = [app.StartEditField_4.Value, app.EndEditField_4.Value];
                Interaction_PickByCylinder(axHandle_, cylinderDir, ctrlPoints);
            else
                Interaction_PickByCylinder(axHandle_, cylinderDir);
            end            
        end

        % Value changed function: XDirCheckBox_2
        function XDirCheckBox_2ValueChanged(app, event)
            value = app.XDirCheckBox_2.Value;
            if value
                app.YDirCheckBox_2.Value = 0;
                app.ZDirCheckBox_2.Value = 0;
            end            
        end

        % Value changed function: YDirCheckBox_2
        function YDirCheckBox_2ValueChanged(app, event)
            value = app.YDirCheckBox_2.Value;
            if value
                app.ZDirCheckBox_2.Value = 0;
                app.XDirCheckBox_2.Value = 0;
            end             
        end

        % Value changed function: ZDirCheckBox_2
        function ZDirCheckBox_2ValueChanged(app, event)
            value = app.ZDirCheckBox_2.Value;
            if value
                app.XDirCheckBox_2.Value = 0;
                app.YDirCheckBox_2.Value = 0;
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ObjectSelectionPanel = uipanel(app.UIFigure);
            app.ObjectSelectionPanel.Title = 'Object Selection';
            app.TabGroup = uitabgroup(app.ObjectSelectionPanel);
            app.LineTab = uitab(app.TabGroup);
            app.LineTab.Title = 'Line';
            app.LineSelectionButton = uibutton(app.LineTab, 'push');
            app.LineSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @LineSelectionButtonPushed, true);
            app.LineSelectionButton.Text = 'Line Selection';
            app.XDirCheckBox = uicheckbox(app.LineTab);
            app.XDirCheckBox.ValueChangedFcn = createCallbackFcn(app, @XDirCheckBoxValueChanged, true);
            app.XDirCheckBox.Text = 'X Dir.';
            app.XDirCheckBox.Value = true;
            app.YDirCheckBox = uicheckbox(app.LineTab);
            app.YDirCheckBox.ValueChangedFcn = createCallbackFcn(app, @YDirCheckBoxValueChanged, true);
            app.YDirCheckBox.Text = 'Y Dir.';
            app.ZDirCheckBox = uicheckbox(app.LineTab);
            app.ZDirCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZDirCheckBoxValueChanged, true);
            app.ZDirCheckBox.Text = 'Z Dir.';
            app.SetLineLengthCheckBox = uicheckbox(app.LineTab);
            app.SetLineLengthCheckBox.ValueChangedFcn = createCallbackFcn(app, @SetLineLengthCheckBoxValueChanged, true);
            app.SetLineLengthCheckBox.Text = 'Set Line Length';
            app.StartEditField_3Label = uilabel(app.LineTab);
            app.StartEditField_3Label.Text = 'Start';
            app.StartEditField_3 = uieditfield(app.LineTab, 'numeric');
            app.EndEditField_3Label = uilabel(app.LineTab);
            app.EndEditField_3Label.Text = 'End';
            app.EndEditField_3 = uieditfield(app.LineTab, 'numeric');
            app.CircleTab = uitab(app.TabGroup);
            app.CircleTab.Title = 'Circle';
            app.CircleSelectionButton = uibutton(app.CircleTab, 'push');
            app.CircleSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @CircleSelectionButtonPushed, true);
            app.CircleSelectionButton.Text = 'Circle Selection';
            app.PrescribedRadiusCheckBox = uicheckbox(app.CircleTab);
            app.PrescribedRadiusCheckBox.ValueChangedFcn = createCallbackFcn(app, @PrescribedRadiusCheckBoxValueChanged, true);
            app.PrescribedRadiusCheckBox.Text = 'Prescribed Radius';
            app.CircleRadiusEditField_2Label = uilabel(app.CircleTab);
            app.CircleRadiusEditField_2Label.Text = 'Circle Radius';
            app.CircleRadiusEditField_2 = uieditfield(app.CircleTab, 'numeric');
            app.CircleRadiusEditField_2.Value = 3;
            app.FaceTab = uitab(app.TabGroup);
            app.FaceTab.Title = 'Face';
            app.FaceSelectionButton = uibutton(app.FaceTab, 'push');
            app.FaceSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @FaceSelectionButtonPushed, true);
            app.FaceSelectionButton.Text = 'Face Selection';
            app.XYCheckBox = uicheckbox(app.FaceTab);
            app.XYCheckBox.ValueChangedFcn = createCallbackFcn(app, @XYCheckBoxValueChanged, true);
            app.XYCheckBox.Text = 'X-Y';
            app.XYCheckBox.Value = true;
            app.YZCheckBox = uicheckbox(app.FaceTab);
            app.YZCheckBox.ValueChangedFcn = createCallbackFcn(app, @YZCheckBoxValueChanged, true);
            app.YZCheckBox.Text = 'Y-Z';
            app.ZXCheckBox = uicheckbox(app.FaceTab);
            app.ZXCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZXCheckBoxValueChanged, true);
            app.ZXCheckBox.Text = 'Z-X';
            app.SelectionDirectionDropDownLabel = uilabel(app.FaceTab);
            app.SelectionDirectionDropDownLabel.Text = 'Selection Direction';
            app.SelectionDirectionDropDown = uidropdown(app.FaceTab);
            app.SelectionDirectionDropDown.Items = {'Positive', 'Negative', 'Neutral'};
            app.SelectionDirectionDropDown.Value = 'Positive';
            app.CylinderTab = uitab(app.TabGroup);
            app.CylinderTab.Title = 'Cylinder';
            app.CylinderSelectionButton = uibutton(app.CylinderTab, 'push');
            app.CylinderSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @CylinderSelectionButtonPushed, true);
            app.CylinderSelectionButton.Text = 'Cylinder Selection';
            app.Label = uilabel(app.CylinderTab);
            app.Label.Text = '*';
            app.TextArea = uitextarea(app.CylinderTab);
            app.TextArea.Value = {'Sequentially Select 4 reference nodes on the hole edge to determine cylinder axis and radius'};
            app.StartEditField_4Label = uilabel(app.CylinderTab);
            app.StartEditField_4Label.Text = 'Start';
            app.StartEditField_4 = uieditfield(app.CylinderTab, 'numeric');
            app.EndEditField_4Label = uilabel(app.CylinderTab);
            app.EndEditField_4Label.Text = 'End';
            app.EndEditField_4 = uieditfield(app.CylinderTab, 'numeric');
            app.SetCylinderDepthCheckBox = uicheckbox(app.CylinderTab);
            app.SetCylinderDepthCheckBox.ValueChangedFcn = createCallbackFcn(app, @SetCylinderDepthCheckBoxValueChanged, true);
            app.SetCylinderDepthCheckBox.Text = 'Set Cylinder Depth';
            app.XDirCheckBox_2 = uicheckbox(app.CylinderTab);
            app.XDirCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @XDirCheckBox_2ValueChanged, true);
            app.XDirCheckBox_2.Text = 'X Dir.';
            app.XDirCheckBox_2.Value = true;
            app.YDirCheckBox_2 = uicheckbox(app.CylinderTab);
            app.YDirCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @YDirCheckBox_2ValueChanged, true);
            app.YDirCheckBox_2.Text = 'Y Dir.';
            app.ZDirCheckBox_2 = uicheckbox(app.CylinderTab);
            app.ZDirCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ZDirCheckBox_2ValueChanged, true);
            app.ZDirCheckBox_2.Text = 'Z Dir.';
            app.ClearallSelectionButton = uibutton(app.ObjectSelectionPanel, 'push');
            app.ClearallSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallSelectionButtonPushed, true);
            app.ClearallSelectionButton.Text = 'Clear all Selection';
            app.PickaPointButton = uibutton(app.ObjectSelectionPanel, 'push');
            app.PickaPointButton.ButtonPushedFcn = createCallbackFcn(app, @PickaPointButtonPushed, true);
            app.PickaPointButton.Text = 'Pick a Point';
            app.UnpickaPointButton = uibutton(app.ObjectSelectionPanel, 'push');
            app.UnpickaPointButton.ButtonPushedFcn = createCallbackFcn(app, @UnpickaPointButtonPushed, true);
            app.UnpickaPointButton.Text = 'Unpick a Point';
            app.WaystoLocatethePositionDropDownLabel = uilabel(app.ObjectSelectionPanel);
            app.WaystoLocatethePositionDropDownLabel.Text = 'Ways to Locate the Position';
            app.WaystoLocatethePositionDropDown = uidropdown(app.ObjectSelectionPanel);
            app.WaystoLocatethePositionDropDown.Items = {'Cursor', 'TypeIn'};
            app.WaystoLocatethePositionDropDown.ValueChangedFcn = createCallbackFcn(app, @WaystoLocatethePositionDropDownValueChanged, true);
            app.WaystoLocatethePositionDropDown.Value = 'Cursor';
            app.P_xEditFieldLabel = uilabel(app.ObjectSelectionPanel);
            app.P_xEditFieldLabel.Text = 'P_x';
            app.P_xEditField = uieditfield(app.ObjectSelectionPanel, 'numeric');
            app.P_yEditFieldLabel = uilabel(app.ObjectSelectionPanel);
            app.P_yEditFieldLabel.Text = 'P_y';
            app.P_yEditField = uieditfield(app.ObjectSelectionPanel, 'numeric');
            app.P_zEditFieldLabel = uilabel(app.ObjectSelectionPanel);
            app.P_zEditFieldLabel.Text = 'P_z';
            app.P_zEditField = uieditfield(app.ObjectSelectionPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ObjectSelection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end