classdef Setting_LinearSystemSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TextArea                    matlab.ui.control.TextArea
        Label                       matlab.ui.control.Label
        SettingsforLinearSystemSolverPanel  matlab.ui.container.Panel
        MaximumElementsontheCoarsestLevelEditField  matlab.ui.control.NumericEditField
        MaximumElementsontheCoarsestLevelEditFieldLabel  matlab.ui.control.Label
        RelaxationFactorofJacobiSmoothingProcessEditField  matlab.ui.control.NumericEditField
        RelaxationFactorofJacobiSmoothingProcessEditFieldLabel  matlab.ui.control.Label
        MaximumIterationsEditField  matlab.ui.control.NumericEditField
        MaximumIterationsEditFieldLabel  matlab.ui.control.Label
        ResidualThresholdEditField  matlab.ui.control.NumericEditField
        ResidualThresholdEditFieldLabel  matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            global tol_;
            global maxIT_;
            global weightFactorJacobi_;
            global coarsestResolutionControl_;                 
            app.MainApp = mainapp;
            app.ResidualThresholdEditField.Value = tol_;
            app.MaximumIterationsEditField.Value = maxIT_;
            app.RelaxationFactorofJacobiSmoothingProcessEditField.Value = weightFactorJacobi_;
            app.MaximumElementsontheCoarsestLevelEditField.Value = coarsestResolutionControl_;            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global tol_;
            global maxIT_;
            global weightFactorJacobi_;
            global coarsestResolutionControl_;
            tol_ = app.ResidualThresholdEditField.Value;
            maxIT_ = app.MaximumIterationsEditField.Value;
            weightFactorJacobi_ = app.RelaxationFactorofJacobiSmoothingProcessEditField.Value;
            coarsestResolutionControl_ = app.MaximumElementsontheCoarsestLevelEditField.Value;
            app.MainApp.LinearSystemSolverMenu.Enable = 'on';
            delete(app);            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.LinearSystemSolverMenu.Enable = 'on'; 
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SettingsforLinearSystemSolverPanel = uipanel(app.UIFigure);
            app.SettingsforLinearSystemSolverPanel.Title = 'Settings for Linear System Solver';
            app.ResetButton = uibutton(app.SettingsforLinearSystemSolverPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ResidualThresholdEditFieldLabel = uilabel(app.SettingsforLinearSystemSolverPanel);
            app.ResidualThresholdEditFieldLabel.Text = 'Residual Threshold';
            app.ResidualThresholdEditField = uieditfield(app.SettingsforLinearSystemSolverPanel, 'numeric');
            app.ResidualThresholdEditField.Value = 0.001;
            app.MaximumIterationsEditFieldLabel = uilabel(app.SettingsforLinearSystemSolverPanel);
            app.MaximumIterationsEditFieldLabel.Text = '#Maximum Iterations';
            app.MaximumIterationsEditField = uieditfield(app.SettingsforLinearSystemSolverPanel, 'numeric');
            app.MaximumIterationsEditField.Value = 200;
            app.RelaxationFactorofJacobiSmoothingProcessEditFieldLabel = uilabel(app.SettingsforLinearSystemSolverPanel);
            app.RelaxationFactorofJacobiSmoothingProcessEditFieldLabel.Text = 'Relaxation Factor of Jacobi Smoothing Process';
            app.RelaxationFactorofJacobiSmoothingProcessEditField = uieditfield(app.SettingsforLinearSystemSolverPanel, 'numeric');
            app.RelaxationFactorofJacobiSmoothingProcessEditField.Value = 0.35;
            app.MaximumElementsontheCoarsestLevelEditFieldLabel = uilabel(app.SettingsforLinearSystemSolverPanel);
            app.MaximumElementsontheCoarsestLevelEditFieldLabel.Text = '#Maximum Elements on the Coarsest Level';
            app.MaximumElementsontheCoarsestLevelEditField = uieditfield(app.SettingsforLinearSystemSolverPanel, 'numeric');
            app.MaximumElementsontheCoarsestLevelEditField.Value = 10000;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '*';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'The conjugate gradient method preconditioned by a geometric multigrid solver is utilized to solve the linear system. A matrix-free computing stencil is employed to save space.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Setting_LinearSystemSolver(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end