classdef Setting_MaterialProperties < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TextArea                     matlab.ui.control.TextArea
        TextAreaLabel                matlab.ui.control.Label
        SettingsforMaterialPropertiesPanel  matlab.ui.container.Panel
        ResetButton                  matlab.ui.control.Button
        YoungsModulusCompliantmaterialEditField  matlab.ui.control.NumericEditField
        YoungsModulusCompliantmaterialEditFieldLabel  matlab.ui.control.Label
        PoissonsRatioEditField       matlab.ui.control.NumericEditField
        PoissonsRatioEditFieldLabel  matlab.ui.control.Label
        YoungsModulusStiffmaterialEditField  matlab.ui.control.NumericEditField
        YoungsModulusStiffmaterialEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            global modulus_;
            global poissonRatio_;
            global modulusMin_;
            app.MainApp = mainapp;
            app.YoungsModulusStiffmaterialEditField.Value = modulus_;
            app.PoissonsRatioEditField.Value = poissonRatio_;
            app.YoungsModulusCompliantmaterialEditField.Value = modulusMin_;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global modulus_;
            global poissonRatio_;
            global modulusMin_;
            modulus_ = app.YoungsModulusStiffmaterialEditField.Value;
            poissonRatio_ = app.PoissonsRatioEditField.Value;
            modulusMin_ = app.YoungsModulusCompliantmaterialEditField.Value;
            app.MainApp.MaterialPropertiesMenu.Enable = 'on';
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.MaterialPropertiesMenu.Enable = 'on'; 
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SettingsforMaterialPropertiesPanel = uipanel(app.UIFigure);
            app.SettingsforMaterialPropertiesPanel.Title = 'Settings for Material Properties';
            app.YoungsModulusStiffmaterialEditFieldLabel = uilabel(app.SettingsforMaterialPropertiesPanel);
            app.YoungsModulusStiffmaterialEditFieldLabel.Text = 'Young''s Modulus (Stiff material)';
            app.YoungsModulusStiffmaterialEditField = uieditfield(app.SettingsforMaterialPropertiesPanel, 'numeric');
            app.YoungsModulusStiffmaterialEditField.Value = 1;
            app.PoissonsRatioEditFieldLabel = uilabel(app.SettingsforMaterialPropertiesPanel);
            app.PoissonsRatioEditFieldLabel.Text = 'Poisson''s Ratio';
            app.PoissonsRatioEditField = uieditfield(app.SettingsforMaterialPropertiesPanel, 'numeric');
            app.PoissonsRatioEditField.Value = 0.3;
            app.YoungsModulusCompliantmaterialEditFieldLabel = uilabel(app.SettingsforMaterialPropertiesPanel);
            app.YoungsModulusCompliantmaterialEditFieldLabel.Text = 'Young''s Modulus (Compliant material)';
            app.YoungsModulusCompliantmaterialEditField = uieditfield(app.SettingsforMaterialPropertiesPanel, 'numeric');
            app.YoungsModulusCompliantmaterialEditField.Value = 1e-06;
            app.ResetButton = uibutton(app.SettingsforMaterialPropertiesPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = '*';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Compliant material is introduced to avoid singularity, which shares the same Poisson''s ratio with the stiff material, but has a significantly lower Young''s modulus.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Setting_MaterialProperties(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end