classdef SimTask_EvaluateExternalVoxelBasedDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VoxelbasedFEAStressAnalysisPanel  matlab.ui.container.Panel
        VolumeFractionEditField         matlab.ui.control.NumericEditField
        VolumeFractionEditFieldLabel    matlab.ui.control.Label
        DesignComplianceEditField       matlab.ui.control.NumericEditField
        DesignComplianceEditFieldLabel  matlab.ui.control.Label
        SolvingtheStaticEquilibriumEquationButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
            GatherLSSandMPsettings(app.MainApp);
        end

        % Button pushed function: SolvingtheStaticEquilibriumEquationButton
        function SolvingtheStaticEquilibriumEquationButtonPushed(app, event)
            global complianceDesign_;
            global volumeFraction_;
            global densityLayout_;
            app.SolvingtheStaticEquilibriumEquationButton.Enable = 'off'; 
            app.MainApp.SimulationTasksDropDown.Enable = 'off';
            pause(1);            
            if isempty(densityLayout_)
                app.SolvingtheStaticEquilibriumEquationButton.Enable = 'on';
                UIFigureCloseRequest(app, event);
                warning('No Voxel-based Design is Available!');
                return;                
            end
            [complianceDesign_, volumeFraction_] = FEA_ComputeComplianceVoxel(densityLayout_);
            %%
            app.DesignComplianceEditField.Value = complianceDesign_;
            app.VolumeFractionEditField.Value = volumeFraction_;
            app.SolvingtheStaticEquilibriumEquationButton.Enable = 'on';
            app.MainApp.SimulationTasksDropDown.Enable = 'on';
            app.MainApp.ShowDeformationMenu.Enable = 'on';
            ShowDeformation_Public(app.MainApp);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.SimulationTasksDropDown.Enable = 'on';                
                app.MainApp.EvaluationTasksDropDown.Enable = 'on';
                app.MainApp.FEAwithSolidDesignDomainButton.Enable = 'on';
                app.MainApp.SimulationTasksDropDown.Value = 'None';                
                app.MainApp.EvaluationTasksDropDown.Value = 'None';                
            end
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.VoxelbasedFEAStressAnalysisPanel = uipanel(app.UIFigure);
            app.VoxelbasedFEAStressAnalysisPanel.Title = 'Voxel-based FEA&Stress Analysis';
            app.SolvingtheStaticEquilibriumEquationButton = uibutton(app.VoxelbasedFEAStressAnalysisPanel, 'push');
            app.SolvingtheStaticEquilibriumEquationButton.ButtonPushedFcn = createCallbackFcn(app, @SolvingtheStaticEquilibriumEquationButtonPushed, true);
            app.SolvingtheStaticEquilibriumEquationButton.Text = 'Solving the Static Equilibrium Equation';
            app.DesignComplianceEditFieldLabel = uilabel(app.VoxelbasedFEAStressAnalysisPanel);
            app.DesignComplianceEditFieldLabel.Text = 'Design Compliance';
            app.DesignComplianceEditField = uieditfield(app.VoxelbasedFEAStressAnalysisPanel, 'numeric');
            app.VolumeFractionEditFieldLabel = uilabel(app.VoxelbasedFEAStressAnalysisPanel);
            app.VolumeFractionEditFieldLabel.Text = 'Volume Fraction';
            app.VolumeFractionEditField = uieditfield(app.VoxelbasedFEAStressAnalysisPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimTask_EvaluateExternalVoxelBasedDesign(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end