classdef SimTask_PSLsGuidedStructDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SettingsforPSLGenerationPanel  matlab.ui.container.Panel
        MinorCheckBox                  matlab.ui.control.CheckBox
        MediumCheckBox                 matlab.ui.control.CheckBox
        MajorCheckBox                  matlab.ui.control.CheckBox
        PSLsguidedStructuralDesignPanel  matlab.ui.container.Panel
        ResultDisplayPanel             matlab.ui.container.Panel
        DesignVolumeFractionEditField_2  matlab.ui.control.NumericEditField
        DesignVolumeFractionEditField_2Label  matlab.ui.control.Label
        DesignComplianceEditField_2    matlab.ui.control.NumericEditField
        DesignComplianceEditField_2Label  matlab.ui.control.Label
        GenerationSimulationPanel      matlab.ui.container.Panel
        RestartLinearSystemSolvingButton  matlab.ui.control.Button
        StressAnalysisonDesignButton   matlab.ui.control.Button
        PSLsguidedInfillGenerationButton  matlab.ui.control.Button
        EvaluateStressAlignmentScaleButton  matlab.ui.control.Button
        StiffnessEvaluationofVoxelbasedStructuralDesignButton  matlab.ui.control.Button
        SettingsforMaterialLayoutConvertionPanel  matlab.ui.container.Panel
        FixedAreaEditField_4           matlab.ui.control.NumericEditField
        FixedAreaEditField_4Label      matlab.ui.control.Label
        LoadingAreaEditField_4         matlab.ui.control.NumericEditField
        LoadingAreaEditField_4Label    matlab.ui.control.Label
        EntireBoundaryEditField        matlab.ui.control.NumericEditField
        EntireBoundaryEditFieldLabel   matlab.ui.control.Label
        MaterialBudgetEditField        matlab.ui.control.NumericEditField
        MaterialBudgetEditFieldLabel   matlab.ui.control.Label
        TargetedTrajectoryThicknessEditField  matlab.ui.control.NumericEditField
        TargetedTrajectoryThicknessLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;           
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'off';
            app.RestartLinearSystemSolvingButton.Enable = 'off';
            app.StressAnalysisonDesignButton.Enable = 'off';
            app.EvaluateStressAlignmentScaleButton.Enable = 'off';
            PSLs_Preparation4TSV();
            %app.DesignComplianceEditField_2.Editable = 'off';
            %app.DesignVolumeFractionEditField_2.Editable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global U_; U_ = zeros(size(U_));
            if isvalid(app.MainApp)
                app.MainApp.SimulationTasksDropDown.Enable = 'on';
                app.MainApp.EvaluationTasksDropDown.Enable = 'on';
                app.MainApp.FEAwithSolidDesignDomainButton.Enable = 'on';
                app.MainApp.SimulationTasksDropDown.Value = 'None';
                app.MainApp.EvaluationTasksDropDown.Value = 'None';
            end            
            delete(app)              
        end

        % Button pushed function: 
        % StiffnessEvaluationofVoxelbasedStructuralDesignButton
        function StiffnessEvaluationofVoxelbasedStructuralDesignButtonPushed(app, event)
            global complianceDesign_;
            global volumeFraction_;
            global densityLayout_;            

            app.PSLsguidedInfillGenerationButton.Enable = 'off';
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'off'; 
            pause(1);

            GatherLSSandMPsettings(app.MainApp);

            [complianceDesign_, volumeFraction_] = FEA_ComputeComplianceVoxel(densityLayout_);

            app.DesignComplianceEditField_2.Value = complianceDesign_;
            app.DesignVolumeFractionEditField_2.Value = volumeFraction_;            
            app.PSLsguidedInfillGenerationButton.Enable = 'on';
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'on';
            app.RestartLinearSystemSolvingButton.Enable = 'on';
            app.StressAnalysisonDesignButton.Enable = 'on';

            app.MainApp.SimulationTasksDropDown.Enable = 'on';
            app.MainApp.ShowDeformationMenu.Enable = 'on';
            ShowDeformation_Public(app.MainApp);             
        end

        % Button pushed function: EvaluateStressAlignmentScaleButton
        function EvaluateStressAlignmentScaleButtonPushed(app, event)
            global outPath_;
            dominantDirSolid = niftiread(strcat(outPath_, 'dominantDirSolid.nii'));
            dominantDirDesign = niftiread(strcat(outPath_, 'dominantDirDesign.nii'));
            disp('Compute Stress Aligment Scale between Solid and Design...');
            tStressAligmentAna = tic;
            alignmentMetricVolumeByStressAlignment = Common_ComputeStressAlignmentDeviation(dominantDirSolid, dominantDirDesign);
            niftiwrite(alignmentMetricVolumeByStressAlignment, strcat(outPath_, 'alignmentMetricVolume_byStress.nii'));            
            alignmentMetricVolumeByEdgeAlignment = Common_ComputeEdgeAlignmentDeviation(dominantDirDesign);
            niftiwrite(alignmentMetricVolumeByEdgeAlignment, strcat(outPath_, 'alignmentMetricVolume_byEdge.nii'));
            disp(['Done with Stress Alignment Analysis after ', sprintf('%.f', toc(tStressAligmentAna)), 's']);
            % system('"./src/vape4d.exe" ./out/alignmentMetricVolume_byStress.nii');          
        end

        % Button pushed function: PSLsguidedInfillGenerationButton
        function PSLsguidedInfillGenerationButtonPushed(app, event)
            global outPath_;
            global cartesianStressField_;
            global volumeFractionDesign_;
            app.PSLsguidedInfillGenerationButton.Enable = 'off'; 
            app.DesignComplianceEditField_2.Value = 0;
            app.DesignVolumeFractionEditField_2.Value = 0;
            pause(1);
            if isempty(cartesianStressField_)
                warning('No Stress Field Existing!');
                return; 
            end
            if ~(app.MajorCheckBox.Value || app.MediumCheckBox.Value || app.MinorCheckBox.Value)
                return;
            end
            psDirIndicator = zeros(1,3);
            if app.MajorCheckBox.Value, psDirIndicator(1) = 1; end
            if app.MediumCheckBox.Value, psDirIndicator(2) = 1; end
            if app.MinorCheckBox.Value, psDirIndicator(3) = 1; end

            targetDepositionRatio = app.MaterialBudgetEditField.Value;
            numLayerPSLs = round(app.TargetedTrajectoryThicknessEditField.Value);
            numLayerboundary = round(app.EntireBoundaryEditField.Value);
            numLayerLoads = round(app.LoadingAreaEditField_4.Value);
            numLayerFixation = round(app.FixedAreaEditField_4.Value);            
            if targetDepositionRatio<=0, return; end
            if numLayerPSLs<2, return; end
            
            PSLs_GeneratePSLsGuidedInfillDesign(psDirIndicator, numLayerPSLs, targetDepositionRatio, numLayerboundary, numLayerLoads, numLayerFixation);
	        app.DesignVolumeFractionEditField_2.Value = volumeFractionDesign_;
            app.PSLsguidedInfillGenerationButton.Enable = 'on';
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'on'; pause(1);
            ShowPSLs_Public(app.MainApp);

            %%Output&Vis Design
            fileName = strcat(outPath_, 'DesignVolume.nii');
	        IO_ExportDesignInVolume_Geo_nii(fileName);
            % system('"./src/vape4d.exe" ./out/DesignVolume.nii');            
        end

        % Button pushed function: RestartLinearSystemSolvingButton
        function RestartLinearSystemSolvingButtonPushed(app, event)
            global meshHierarchy_;
            global complianceDesign_;

            app.RestartLinearSystemSolvingButton.Enable = 'off';
            app.PSLsguidedInfillGenerationButton.Enable = 'off';
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'off'; 
            app.StressAnalysisonDesignButton.Enable = 'off';
            pause(1);

            tStart = tic;
            GatherLSSandMPsettings(app.MainApp);
            Solving_CG_GMGS('printP_ON');
            ceList = TopOpti_ComputeUnitCompliance();
            complianceDesign_ = meshHierarchy_(1).eleModulus*ceList;
            disp(['Re-start Linear System Costs: ', sprintf('%.f', toc(tStart)), 's']);
            
            app.DesignComplianceEditField_2.Value = complianceDesign_;            
            app.RestartLinearSystemSolvingButton.Enable = 'on';
            app.PSLsguidedInfillGenerationButton.Enable = 'on';
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'on'; 
            app.StressAnalysisonDesignButton.Enable = 'on';
        end

        % Button pushed function: StressAnalysisonDesignButton
        function StressAnalysisonDesignButtonPushed(app, event)
            global outPath_;
            app.RestartLinearSystemSolvingButton.Enable = 'off';
            app.PSLsguidedInfillGenerationButton.Enable = 'off';
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'off'; 
            app.StressAnalysisonDesignButton.Enable = 'off';
            pause(1);            
            disp('Stress Analysis on Design ...');            
            tStressAnalysis = tic;
            dominantDirDesign = Common_ExtractDominantDirectionsFromPrincipalStressDirections();
            if isempty(dominantDirDesign), return; end
            niftiwrite(dominantDirDesign, strcat(outPath_, 'dominantDirDesign.nii'));
            disp(['Done with Stress Analysis after ', sprintf('%.f', toc(tStressAnalysis)), 's']);
            app.RestartLinearSystemSolvingButton.Enable = 'on';
            app.PSLsguidedInfillGenerationButton.Enable = 'on';
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'on'; 
            app.StressAnalysisonDesignButton.Enable = 'on';
            app.EvaluateStressAlignmentScaleButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PSLsguidedStructuralDesignPanel = uipanel(app.UIFigure);
            app.PSLsguidedStructuralDesignPanel.Title = 'PSLs-guided Structural Design';
            app.SettingsforMaterialLayoutConvertionPanel = uipanel(app.PSLsguidedStructuralDesignPanel);
            app.SettingsforMaterialLayoutConvertionPanel.Title = 'Settings for Material Layout Convertion';
            app.TargetedTrajectoryThicknessLabel = uilabel(app.SettingsforMaterialLayoutConvertionPanel);
            app.TargetedTrajectoryThicknessLabel.Text = 'Targeted Trajectory Thickness';
            app.TargetedTrajectoryThicknessEditField = uieditfield(app.SettingsforMaterialLayoutConvertionPanel, 'numeric');
            app.TargetedTrajectoryThicknessEditField.Value = 3;
            app.MaterialBudgetEditFieldLabel = uilabel(app.SettingsforMaterialLayoutConvertionPanel);
            app.MaterialBudgetEditFieldLabel.Text = 'Material Budget';
            app.MaterialBudgetEditField = uieditfield(app.SettingsforMaterialLayoutConvertionPanel, 'numeric');
            app.MaterialBudgetEditField.Value = 0.5;
            app.EntireBoundaryEditFieldLabel = uilabel(app.SettingsforMaterialLayoutConvertionPanel);
            app.EntireBoundaryEditFieldLabel.Text = 'Entire Boundary';
            app.EntireBoundaryEditField = uieditfield(app.SettingsforMaterialLayoutConvertionPanel, 'numeric');
            app.EntireBoundaryEditField.Value = 2;
            app.LoadingAreaEditField_4Label = uilabel(app.SettingsforMaterialLayoutConvertionPanel);
            app.LoadingAreaEditField_4Label.Text = 'Loading Area';
            app.LoadingAreaEditField_4 = uieditfield(app.SettingsforMaterialLayoutConvertionPanel, 'numeric');
            app.FixedAreaEditField_4Label = uilabel(app.SettingsforMaterialLayoutConvertionPanel);
            app.FixedAreaEditField_4Label.Text = 'Fixed Area';
            app.FixedAreaEditField_4 = uieditfield(app.SettingsforMaterialLayoutConvertionPanel, 'numeric');
            app.GenerationSimulationPanel = uipanel(app.PSLsguidedStructuralDesignPanel);
            app.GenerationSimulationPanel.Title = 'Generation & Simulation';
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton = uibutton(app.GenerationSimulationPanel, 'push');
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.ButtonPushedFcn = createCallbackFcn(app, @StiffnessEvaluationofVoxelbasedStructuralDesignButtonPushed, true);
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Text = 'Stiffness Evaluation of Voxel based Structural Design';
            app.EvaluateStressAlignmentScaleButton = uibutton(app.GenerationSimulationPanel, 'push');
            app.EvaluateStressAlignmentScaleButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateStressAlignmentScaleButtonPushed, true);
            app.EvaluateStressAlignmentScaleButton.Text = 'Evaluate Stress Alignment Scale';
            app.PSLsguidedInfillGenerationButton = uibutton(app.GenerationSimulationPanel, 'push');
            app.PSLsguidedInfillGenerationButton.ButtonPushedFcn = createCallbackFcn(app, @PSLsguidedInfillGenerationButtonPushed, true);
            app.PSLsguidedInfillGenerationButton.Text = 'PSLs-guided Infill Generation';
            app.StressAnalysisonDesignButton = uibutton(app.GenerationSimulationPanel, 'push');
            app.StressAnalysisonDesignButton.ButtonPushedFcn = createCallbackFcn(app, @StressAnalysisonDesignButtonPushed, true);
            app.StressAnalysisonDesignButton.Text = 'Stress Analysis on Design';
            app.RestartLinearSystemSolvingButton = uibutton(app.GenerationSimulationPanel, 'push');
            app.RestartLinearSystemSolvingButton.ButtonPushedFcn = createCallbackFcn(app, @RestartLinearSystemSolvingButtonPushed, true);
            app.RestartLinearSystemSolvingButton.Text = 'Re-start Linear System Solving';
            app.ResultDisplayPanel = uipanel(app.PSLsguidedStructuralDesignPanel);
            app.ResultDisplayPanel.Title = 'Result Display';
            app.DesignComplianceEditField_2Label = uilabel(app.ResultDisplayPanel);
            app.DesignComplianceEditField_2Label.Text = 'Design Compliance';
            app.DesignComplianceEditField_2 = uieditfield(app.ResultDisplayPanel, 'numeric');
            app.DesignVolumeFractionEditField_2Label = uilabel(app.ResultDisplayPanel);
            app.DesignVolumeFractionEditField_2Label.Text = 'Design Volume Fraction';
            app.DesignVolumeFractionEditField_2 = uieditfield(app.ResultDisplayPanel, 'numeric');
            app.SettingsforPSLGenerationPanel = uipanel(app.UIFigure);
            app.SettingsforPSLGenerationPanel.Title = 'Settings for PSL Generation';
            app.MajorCheckBox = uicheckbox(app.SettingsforPSLGenerationPanel);
            app.MajorCheckBox.Text = 'Major';
            app.MajorCheckBox.Value = true;
            app.MediumCheckBox = uicheckbox(app.SettingsforPSLGenerationPanel);
            app.MediumCheckBox.Text = 'Medium';
            app.MinorCheckBox = uicheckbox(app.SettingsforPSLGenerationPanel);
            app.MinorCheckBox.Text = 'Minor';
            app.MinorCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimTask_PSLsGuidedStructDesign(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end