classdef SimTask_StressAwareGeoSyn_3rdParty_AroraMethod < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StressalignedGeometricParametrizationbasedDesignPanel  matlab.ui.container.Panel
        StressFieldPreparationPanel     matlab.ui.container.Panel
        SmoothPrincipalStressFieldButton  matlab.ui.control.Button
        TetrahedraEditField             matlab.ui.control.NumericEditField
        TetrahedraEditFieldLabel        matlab.ui.control.Label
        StressPreparationButton         matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        ResultDisplayPanel              matlab.ui.container.Panel
        DesignVolumeFractionEditField   matlab.ui.control.NumericEditField
        DesignVolumeFractionEditFieldLabel  matlab.ui.control.Label
        DesignComplianceEditField       matlab.ui.control.NumericEditField
        DesignComplianceEditFieldLabel  matlab.ui.control.Label
        GenerationSimulationPanel       matlab.ui.container.Panel
        EvaluateStressAlignmentScaleButton  matlab.ui.control.Button
        StressAnalysisonDesignButton    matlab.ui.control.Button
        RestartLinearSystemSolvingButton  matlab.ui.control.Button
        StressalignedVolumetricMichellsTrussesGenerationButton  matlab.ui.control.Button
        StiffnessEvaluationofVoxelbasedStructuralDesignButton  matlab.ui.control.Button
        SettingsforMaterialLayoutConvertionPanel  matlab.ui.container.Panel
        MaterialBudgetEditField         matlab.ui.control.NumericEditField
        MaterialBudgetEditFieldLabel    matlab.ui.control.Label
        FixedAreaEditField              matlab.ui.control.NumericEditField
        FixedAreaEditFieldLabel         matlab.ui.control.Label
        LoadingAreaEditField            matlab.ui.control.NumericEditField
        LoadingAreaEditFieldLabel       matlab.ui.control.Label
        EntireBoundaryEditField         matlab.ui.control.NumericEditField
        EntireBoundaryEditFieldLabel    matlab.ui.control.Label
        EdgeThicknessEditField          matlab.ui.control.NumericEditField
        EdgeThicknessEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            addpath('./externalModules/VolumetricTruss-master/');
            addpath('./externalModules/gptoolbox-master/mesh');
            addpath('./externalModules/gptoolbox-master/matrix');
            app.MainApp = mainapp;
            % app.StressalignedVolumetricMichellsTrussesGenerationButton.Enable = 'off';
            % app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'off';
            % app.RestartLinearSystemSolvingButton.Enable = 'off';
            % app.StressAnalysisonDesignButton.Enable = 'off';
            % app.EvaluateStressAlignmentScaleButton.Enable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.SimulationTasksDropDown.Enable = 'on';
                app.MainApp.FEAwithSolidDesignDomainButton.Enable = 'on';
                app.MainApp.SimulationTasksDropDown.Value = 'None';
            end
            delete(app) 
        end

        % Button pushed function: StressPreparationButton
        function StressPreparationButtonPushed(app, event)
            global cartesianStressField_;
            app.StressPreparationButton.Enable = 'off';
            pause(1);

            if isempty(cartesianStressField_)
                warning('No Stress Field is Available!');
                app.StressPreparationButton.Enable = 'on';
                return;
            end

            app.StressPreparationButton.Enable = 'on';
            app.SmoothPrincipalStressFieldButton.Enable = 'on';  
            
            disp('Generating Gateway Tet-mesh for Stress-aligned Parametrization ...');
            numTetrahedraInGatewayMesh = app.TetrahedraEditField.Value;
            numTetrahedraInGatewayMesh = round(numTetrahedraInGatewayMesh);
            numTetrahedraInGatewayMesh = min(numTetrahedraInGatewayMesh, 100000);
            SAGS_GenerateDelaunayTetMeshFromInputSurfaceMesh(numTetrahedraInGatewayMesh);
            disp('Interpolating Stress Field to Tet-mesh From High-res Cartesian Mesh ...');            
            SAGS_InterpolatingStressFieldOnTetMesh();
        end

        % Button pushed function: 
        % StressalignedVolumetricMichellsTrussesGenerationButton
        function StressalignedVolumetricMichellsTrussesGenerationButtonPushed(app, event)
            global outPath_;
            global volumeFractionDesign_;
            app.StressPreparationButton.Enable = 'off';
            app.SmoothPrincipalStressFieldButton.Enable = 'off';
            app.StressalignedVolumetricMichellsTrussesGenerationButton.Enable = 'off';
            app.DesignComplianceEditField.Value = 0;
            app.DesignVolumeFractionEditField.Value = 0;
            pause(1);

            targetDepositionRatio = app.MaterialBudgetEditField.Value;
            edgeWidth = round(app.EdgeThicknessEditField.Value); edgeWidth = max(edgeWidth,1);
            numLayerboundary = round(app.EntireBoundaryEditField.Value); numLayerboundary = max(numLayerboundary,0);
            numLayerLoads = round(app.LoadingAreaEditField.Value); numLayerLoads = max(numLayerLoads,0);
            numLayerFixation = round(app.FixedAreaEditField.Value); numLayerFixation = max(numLayerFixation,0);

            if targetDepositionRatio<=0, return; end
            
            SAGS_StressAlignedVolumetricMichellTrussesGeneration(edgeWidth, targetDepositionRatio, numLayerboundary, ...
                numLayerLoads, numLayerFixation);

            app.DesignVolumeFractionEditField.Value = volumeFractionDesign_;            
            app.StressPreparationButton.Enable = 'on';
            app.SmoothPrincipalStressFieldButton.Enable = 'on';
            app.StressalignedVolumetricMichellsTrussesGenerationButton.Enable = 'on';
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'on';
            
            app.MainApp.ShowDesignbyDensityFieldMenu.Enable = 'on';            
            app.MainApp.InputEdgeVertexGraphMenu.Enable = 'on';
            ShowEdgeVertexGraph_Public(app.MainApp);            

            %%Output&Vis Design
            fileName = strcat(outPath_, 'DesignVolume.nii');
	        IO_ExportDesignInVolume_Geo_nii(fileName);
            %system('"./src/vape4d.exe" ./out/DesignVolume.nii');    
        end

        % Button pushed function: 
        % StiffnessEvaluationofVoxelbasedStructuralDesignButton
        function StiffnessEvaluationofVoxelbasedStructuralDesignButtonPushed(app, event)
            global complianceDesign_;
            global volumeFraction_;
            global densityLayout_;            

            app.StressPreparationButton.Enable = 'off';
            app.SmoothPrincipalStressFieldButton.Enable = 'off';
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'off'; 
            pause(1);

            GatherLSSandMPsettings(app.MainApp);

            [complianceDesign_, volumeFraction_] = FEA_ComputeComplianceVoxel(densityLayout_);

            app.DesignComplianceEditField.Value = complianceDesign_;
            app.StressPreparationButton.Enable = 'on';
            app.SmoothPrincipalStressFieldButton.Enable = 'on';                
            app.StressalignedVolumetricMichellsTrussesGenerationButton.Enable = 'on';
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'on';
            app.RestartLinearSystemSolvingButton.Enable = 'on';
            app.StressAnalysisonDesignButton.Enable = 'on';        

            app.MainApp.SimulationTasksDropDown.Enable = 'on';
            app.MainApp.ShowDeformationMenu.Enable = 'on';
            ShowDeformation_Public(app.MainApp);            
        end

        % Button pushed function: RestartLinearSystemSolvingButton
        function RestartLinearSystemSolvingButtonPushed(app, event)
            global meshHierarchy_;
            global complianceDesign_;

            app.RestartLinearSystemSolvingButton.Enable = 'off';
            app.StressPreparationButton.Enable = 'off';
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'off'; 
            app.StressAnalysisonDesignButton.Enable = 'off';
            app.StressalignedVolumetricMichellsTrussesGenerationButton.Enable = 'off';

            pause(1);

            tStart = tic;
            GatherLSSandMPsettings(app.MainApp);
            Solving_CG_GMGS('printP_ON');
            ceList = TopOpti_ComputeUnitCompliance();
            complianceDesign_ = meshHierarchy_(1).eleModulus*ceList;
            disp(['Re-start Linear System Costs: ', sprintf('%.f', toc(tStart)), 's']);
            
            app.DesignComplianceEditField.Value = complianceDesign_;
            app.RestartLinearSystemSolvingButton.Enable = 'on';
            app.StressalignedVolumetricMichellsTrussesGenerationButton.Enable = 'on';
            app.StressPreparationButton.Enable = 'on';
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'on'; 
            app.StressAnalysisonDesignButton.Enable = 'on';        
        end

        % Button pushed function: StressAnalysisonDesignButton
        function StressAnalysisonDesignButtonPushed(app, event)
            global outPath_;
            global U_; 
            app.RestartLinearSystemSolvingButton.Enable = 'off';
            app.StressPreparationButton.Enable = 'off';
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'off'; 
            app.StressAnalysisonDesignButton.Enable = 'off';
            app.StressalignedVolumetricMichellsTrussesGenerationButton.Enable = 'off';
            pause(1);            
            
            disp('Stress Analysis on Design ...');            
            tStressAnalysis = tic;
            dominantDirDesign = Common_ExtractDominantDirectionsFromPrincipalStressDirections();            
            if isempty(dominantDirDesign), return; end
            niftiwrite(dominantDirDesign, strcat(outPath_, 'dominantDirDesign.nii'));
            disp(['Done with Stress Analysis after ', sprintf('%.f', toc(tStressAnalysis)), 's']);
            
            app.StressalignedVolumetricMichellsTrussesGenerationButton.Enable = 'on';
            app.RestartLinearSystemSolvingButton.Enable = 'on';
            app.StressPreparationButton.Enable = 'on';
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'on'; 
            app.StressAnalysisonDesignButton.Enable = 'on';
            app.EvaluateStressAlignmentScaleButton.Enable = 'on';
            U_ = zeros(size(U_));
        end

        % Button pushed function: EvaluateStressAlignmentScaleButton
        function EvaluateStressAlignmentScaleButtonPushed(app, event)
            global outPath_;
            dominantDirSolid = niftiread(strcat(outPath_, 'dominantDirSolid.nii'));
            dominantDirDesign = niftiread(strcat(outPath_, 'dominantDirDesign.nii'));
            disp('Compute Stress Aligment Scale between Solid and Design...');
            tStressAligmentAna = tic;
            alignmentMetricVolumeByStressAlignment = Common_ComputeStressAlignmentDeviation(dominantDirSolid, dominantDirDesign);
            niftiwrite(alignmentMetricVolumeByStressAlignment, strcat(outPath_, 'alignmentMetricVolume_byStress.nii'));            
            alignmentMetricVolumeByEdgeAlignment = Common_ComputeEdgeAlignmentDeviation(dominantDirDesign);
            niftiwrite(alignmentMetricVolumeByEdgeAlignment, strcat(outPath_, 'alignmentMetricVolume_byEdge.nii'));
            disp(['Done with Stress Alignment Analysis after ', sprintf('%.f', toc(tStressAligmentAna)), 's']);
            %system('"./src/vape4d.exe" ./out/alignmentMetricVolume_byStress.nii');             
        end

        % Button pushed function: SmoothPrincipalStressFieldButton
        function SmoothPrincipalStressFieldButtonPushed(app, event)
            app.StressPreparationButton.Enable = 'off';
            app.SmoothPrincipalStressFieldButton.Enable = 'off';
            pause(1);

            SAGS_CallArora2019MatlabSuite_Smoothing();

            app.StressPreparationButton.Enable = 'on';
            app.SmoothPrincipalStressFieldButton.Enable = 'on';
            app.StressalignedVolumetricMichellsTrussesGenerationButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StressalignedGeometricParametrizationbasedDesignPanel = uipanel(app.UIFigure);
            app.StressalignedGeometricParametrizationbasedDesignPanel.Title = 'Stress-aware Geometry Systhesis (The 3rd Party Method&Program from [Arora et al. Arora, SCF, 2019])';
            app.SettingsforMaterialLayoutConvertionPanel = uipanel(app.StressalignedGeometricParametrizationbasedDesignPanel);
            app.SettingsforMaterialLayoutConvertionPanel.Title = 'Settings for Material Layout Convertion';
            app.EdgeThicknessEditFieldLabel = uilabel(app.SettingsforMaterialLayoutConvertionPanel);
            app.EdgeThicknessEditFieldLabel.Text = 'Edge Thickness';
            app.EdgeThicknessEditField = uieditfield(app.SettingsforMaterialLayoutConvertionPanel, 'numeric');
            app.EdgeThicknessEditField.Value = 3;
            app.EntireBoundaryEditFieldLabel = uilabel(app.SettingsforMaterialLayoutConvertionPanel);
            app.EntireBoundaryEditFieldLabel.Text = 'Entire Boundary';
            app.EntireBoundaryEditField = uieditfield(app.SettingsforMaterialLayoutConvertionPanel, 'numeric');
            app.EntireBoundaryEditField.Value = 2;
            app.LoadingAreaEditFieldLabel = uilabel(app.SettingsforMaterialLayoutConvertionPanel);
            app.LoadingAreaEditFieldLabel.Text = 'Loading Area';
            app.LoadingAreaEditField = uieditfield(app.SettingsforMaterialLayoutConvertionPanel, 'numeric');
            app.FixedAreaEditFieldLabel = uilabel(app.SettingsforMaterialLayoutConvertionPanel);
            app.FixedAreaEditFieldLabel.Text = 'Fixed Area';
            app.FixedAreaEditField = uieditfield(app.SettingsforMaterialLayoutConvertionPanel, 'numeric');
            app.MaterialBudgetEditFieldLabel = uilabel(app.SettingsforMaterialLayoutConvertionPanel);
            app.MaterialBudgetEditFieldLabel.Text = 'Material Budget';
            app.MaterialBudgetEditField = uieditfield(app.SettingsforMaterialLayoutConvertionPanel, 'numeric');
            app.MaterialBudgetEditField.Value = 0.5;
            app.GenerationSimulationPanel = uipanel(app.StressalignedGeometricParametrizationbasedDesignPanel);
            app.GenerationSimulationPanel.Title = 'Generation & Simulation';
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton = uibutton(app.GenerationSimulationPanel, 'push');
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.ButtonPushedFcn = createCallbackFcn(app, @StiffnessEvaluationofVoxelbasedStructuralDesignButtonPushed, true);
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Text = 'Stiffness Evaluation of Voxel based Structural Design';
            app.StressalignedVolumetricMichellsTrussesGenerationButton = uibutton(app.GenerationSimulationPanel, 'push');
            app.StressalignedVolumetricMichellsTrussesGenerationButton.ButtonPushedFcn = createCallbackFcn(app, @StressalignedVolumetricMichellsTrussesGenerationButtonPushed, true);
            app.StressalignedVolumetricMichellsTrussesGenerationButton.Text = 'Stress-aligned Volumetric Michell''s Trusses Generation';
            app.RestartLinearSystemSolvingButton = uibutton(app.GenerationSimulationPanel, 'push');
            app.RestartLinearSystemSolvingButton.ButtonPushedFcn = createCallbackFcn(app, @RestartLinearSystemSolvingButtonPushed, true);
            app.RestartLinearSystemSolvingButton.Text = 'Re-start Linear System Solving';
            app.StressAnalysisonDesignButton = uibutton(app.GenerationSimulationPanel, 'push');
            app.StressAnalysisonDesignButton.ButtonPushedFcn = createCallbackFcn(app, @StressAnalysisonDesignButtonPushed, true);
            app.StressAnalysisonDesignButton.Text = 'Stress Analysis on Design';
            app.EvaluateStressAlignmentScaleButton = uibutton(app.GenerationSimulationPanel, 'push');
            app.EvaluateStressAlignmentScaleButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateStressAlignmentScaleButtonPushed, true);
            app.EvaluateStressAlignmentScaleButton.Text = 'Evaluate Stress Alignment Scale';
            app.ResultDisplayPanel = uipanel(app.StressalignedGeometricParametrizationbasedDesignPanel);
            app.ResultDisplayPanel.Title = 'Result Display';
            app.DesignComplianceEditFieldLabel = uilabel(app.ResultDisplayPanel);
            app.DesignComplianceEditFieldLabel.Text = 'Design Compliance';
            app.DesignComplianceEditField = uieditfield(app.ResultDisplayPanel, 'numeric');
            app.DesignVolumeFractionEditFieldLabel = uilabel(app.ResultDisplayPanel);
            app.DesignVolumeFractionEditFieldLabel.Text = 'Design Volume Fraction';
            app.DesignVolumeFractionEditField = uieditfield(app.ResultDisplayPanel, 'numeric');
            app.Label = uilabel(app.StressalignedGeometricParametrizationbasedDesignPanel);
            app.Label.Text = '*';
            app.TextArea = uitextarea(app.StressalignedGeometricParametrizationbasedDesignPanel);
            app.TextArea.Value = {'[Arora et al. 2017, TOG] Arora, Rahul, et al. "Volumetric michell trusses for parametric design & fabrication." Proceedings of the 3rd Annual ACM Symposium on Computational Fabrication. 2019.'};
            app.StressFieldPreparationPanel = uipanel(app.StressalignedGeometricParametrizationbasedDesignPanel);
            app.StressFieldPreparationPanel.Title = 'Stress Field Preparation';
            app.StressPreparationButton = uibutton(app.StressFieldPreparationPanel, 'push');
            app.StressPreparationButton.ButtonPushedFcn = createCallbackFcn(app, @StressPreparationButtonPushed, true);
            app.StressPreparationButton.Text = 'Stress Preparation';
            app.TetrahedraEditFieldLabel = uilabel(app.StressFieldPreparationPanel);
            app.TetrahedraEditFieldLabel.Text = '#Tetrahedra';
            app.TetrahedraEditField = uieditfield(app.StressFieldPreparationPanel, 'numeric');
            app.TetrahedraEditField.Value = 10000;
            app.SmoothPrincipalStressFieldButton = uibutton(app.StressFieldPreparationPanel, 'push');
            app.SmoothPrincipalStressFieldButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothPrincipalStressFieldButtonPushed, true);
            app.SmoothPrincipalStressFieldButton.Text = 'Smooth Principal Stress Field';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimTask_StressAwareGeoSyn_3rdParty_AroraMethod(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end