classdef SimTask_StressAwareGeoSyn_3rdParty_AroraMethod_previous < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StressFieldPreparationPanel     matlab.ui.container.Panel
        StressFieldSelectionDropDown    matlab.ui.control.DropDown
        StressFieldSelectionDropDownLabel  matlab.ui.control.Label
        StressAnalysisButton            matlab.ui.control.Button
        StressalignedGeometricParametrizationbasedDesignPanel  matlab.ui.container.Panel
        MeshGraphGenerationPanel_2      matlab.ui.container.Panel
        OutputResolutionEditField       matlab.ui.control.NumericEditField
        OutputResolutionEditFieldLabel  matlab.ui.control.Label
        RunStressalignedParametrizationButton_2  matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        StiffnessEvaluationPanel        matlab.ui.container.Panel
        StiffnessEvaluateofVoxelbasedStructuralDesignButton  matlab.ui.control.Button
        DesignVolumeFractionEditField   matlab.ui.control.NumericEditField
        DesignVolumeFractionEditFieldLabel  matlab.ui.control.Label
        DesignComplianceEditField       matlab.ui.control.NumericEditField
        DesignComplianceEditFieldLabel  matlab.ui.control.Label
        ConvertHexmeshtoVoxelbasedMaterialLayoutPanel  matlab.ui.container.Panel
        FixedAreaEditField              matlab.ui.control.NumericEditField
        FixedAreaEditFieldLabel         matlab.ui.control.Label
        LoadingAreaEditField            matlab.ui.control.NumericEditField
        LoadingAreaEditFieldLabel       matlab.ui.control.Label
        EntireBoundaryEditField         matlab.ui.control.NumericEditField
        EntireBoundaryEditFieldLabel    matlab.ui.control.Label
        EdgeThicknessEditField          matlab.ui.control.NumericEditField
        EdgeThicknessEditFieldLabel     matlab.ui.control.Label
        CreateVoxelbasedStructuralDesignButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            global inputSolidMesh_;
            app.MainApp = mainapp;
            GatherLSSandMPsettings(app.MainApp);
            app.OutputResolutionEditField.Enable = 'off';
            app.RunStressalignedParametrizationButton_2.Enable = "off";
            app.EdgeThicknessEditField.Enable = "off";
            app.LoadingAreaEditField.Enable = 'off';
            app.FixedAreaEditField.Enable = 'off';
            app.EntireBoundaryEditField.Enable = 'off';
            app.CreateVoxelbasedStructuralDesignButton.Enable = 'off';
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.Enable = 'off';
            app.DesignComplianceEditField.Editable = 'off';
            app.DesignVolumeFractionEditField.Editable = 'off';
            if 0==inputSolidMesh_.state
                app.StressFieldSelectionDropDown.Value = 'On Cartesian Mesh';
                app.StressFieldSelectionDropDown.Enable = 'off';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.SimulationTasksDropDown.Enable = 'on';
                app.MainApp.EvaluationTasksDropDown.Enable = 'on';
                app.MainApp.FEAwithSolidDesignDomainButton.Enable = 'on';
                app.MainApp.SimulationTasksDropDown.Value = 'None';
                app.MainApp.EvaluationTasksDropDown.Value = 'None';
            end
            delete(app) 
        end

        % Button pushed function: StressAnalysisButton
        function StressAnalysisButtonPushed(app, event)
            global U_;
            global cartesianStressField_;
            app.StressAnalysisButton.Enable = 'off';
            pause(1);
            switch app.StressFieldSelectionDropDown.Value
                case 'On Cartesian Mesh'
                    disp('High-resolution voxel model is not recommended for this functionality');
                    if isempty(U_)
                        FEA_Public(app.MainApp);
                    end
                    if isempty(cartesianStressField_)
                        StressAnalysis_Public(app.MainApp);
                    end
                    SAGS_AdaptCartesianStressData();
                case 'On Input Tet/Hex Mesh'
                    SAGS_ObtainStressField_ArbitrarySolidMesh();
            end            
            app.StressAnalysisButton.Enable = 'on';
            app.OutputResolutionEditField.Enable = 'on';
            app.RunStressalignedParametrizationButton_2.Enable = "on";            
        end

        % Button pushed function: RunStressalignedParametrizationButton_2
        function RunStressalignedParametrizationButton_2Pushed(app, event)
            app.StressAnalysisButton.Enable = 'off';
            app.RunStressalignedParametrizationButton_2.Enable = 'off';
            pause(1);
            resolution = app.OutputResolutionEditField.Value;
            if resolution<0, return; end
            SAGS_CallArora2019MatlabSuite(resolution);
            app.StressAnalysisButton.Enable = 'on';
            app.RunStressalignedParametrizationButton_2.Enable = 'on';
            app.EdgeThicknessEditField.Enable = "on";
            app.LoadingAreaEditField.Enable = 'on';
            app.FixedAreaEditField.Enable = 'on';
            app.EntireBoundaryEditField.Enable = 'on';
            app.CreateVoxelbasedStructuralDesignButton.Enable = 'on';
            ShowEdgeVertexGraph_Public(app.MainApp);
            app.MainApp.InputEdgeVertexGraphMenu.Enable = 'on';
        end

        % Button pushed function: CreateVoxelbasedStructuralDesignButton
        function CreateVoxelbasedStructuralDesignButtonPushed(app, event)
            global volumeFraction_;
            app.StressAnalysisButton.Enable = 'off';
            app.RunStressalignedParametrizationButton_2.Enable = 'off';
            app.CreateVoxelbasedStructuralDesignButton.Enable = 'off';
            app.EdgeThicknessEditField.Enable = "off";
            app.LoadingAreaEditField.Enable = 'off';
            app.FixedAreaEditField.Enable = 'off';
            app.EntireBoundaryEditField.Enable = 'off';            
            pause(1);
            edgeWidth = app.EdgeThicknessEditField.Value; edgeWidth = round(edgeWidth);
            numLayerboundary = app.EntireBoundaryEditField.Value; numLayerboundary = round(numLayerboundary);
            numLayerLoads = app.LoadingAreaEditField.Value; numLayerLoads = round(numLayerLoads);
            numLayerFixation = app.FixedAreaEditField.Value; numLayerFixation = round(numLayerFixation);
            MGD_VoxelizeMeshEdges();
            volumeFraction_ = MGD_CoatMeshEdgesWithVoxels(edgeWidth, numLayerboundary, numLayerLoads, numLayerFixation);
            app.DesignVolumeFractionEditField.Value = volumeFraction_;
            app.StressAnalysisButton.Enable = 'on';
            app.RunStressalignedParametrizationButton_2.Enable = 'on';
            app.CreateVoxelbasedStructuralDesignButton.Enable = 'on';
            app.EdgeThicknessEditField.Enable = "on";
            app.LoadingAreaEditField.Enable = 'on';
            app.FixedAreaEditField.Enable = 'on';
            app.EntireBoundaryEditField.Enable = 'on';
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.Enable = 'on';
            app.MainApp.ShowDesignbyDensityFieldMenu.Enable = 'on';
        end

        % Button pushed function: 
        % StiffnessEvaluateofVoxelbasedStructuralDesignButton
        function StiffnessEvaluateofVoxelbasedStructuralDesignButtonPushed(app, event)
            global complianceDesign_;
            global volumeFraction_;
            global densityLayout_;
            app.StressAnalysisButton.Enable = 'off';
            app.RunStressalignedParametrizationButton_2.Enable = 'off';
            app.CreateVoxelbasedStructuralDesignButton.Enable = 'off';
            app.EdgeThicknessEditField.Enable = "off";
            app.LoadingAreaEditField.Enable = 'off';
            app.FixedAreaEditField.Enable = 'off';
            app.EntireBoundaryEditField.Enable = 'off';
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.Enable = 'off';
            pause(1);    
            if isempty(densityLayout_)
                app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.Enable = 'on';
                warning('No Voxel-based Design is Available!');
                return;                
            end
            [complianceDesign_, volumeFraction_] = FEA_ComputeComplianceVoxel(densityLayout_);            
            app.DesignVolumeFractionEditField.Value = volumeFraction_;
            app.DesignComplianceEditField.Value = complianceDesign_;
            app.StressAnalysisButton.Enable = 'on';
            app.RunStressalignedParametrizationButton_2.Enable = 'on';
            app.CreateVoxelbasedStructuralDesignButton.Enable = 'on';
            app.EdgeThicknessEditField.Enable = "on";
            app.LoadingAreaEditField.Enable = 'on';
            app.FixedAreaEditField.Enable = 'on';
            app.EntireBoundaryEditField.Enable = 'on';
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.Enable = 'on';
            app.MainApp.SimulationTasksDropDown.Enable = 'on';
            app.MainApp.ShowDeformationMenu.Enable = 'on';
            ShowDeformation_Public(app.MainApp);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StressalignedGeometricParametrizationbasedDesignPanel = uipanel(app.UIFigure);
            app.StressalignedGeometricParametrizationbasedDesignPanel.Title = 'Stress-aware Geometry Systhesis (The 3rd Party Method&Program from [Arora et al. Arora, SCF, 2019])';
            app.ConvertHexmeshtoVoxelbasedMaterialLayoutPanel = uipanel(app.StressalignedGeometricParametrizationbasedDesignPanel);
            app.ConvertHexmeshtoVoxelbasedMaterialLayoutPanel.Title = 'Convert Hex-mesh to Voxel-based Material Layout';
            app.CreateVoxelbasedStructuralDesignButton = uibutton(app.ConvertHexmeshtoVoxelbasedMaterialLayoutPanel, 'push');
            app.CreateVoxelbasedStructuralDesignButton.ButtonPushedFcn = createCallbackFcn(app, @CreateVoxelbasedStructuralDesignButtonPushed, true);
            app.CreateVoxelbasedStructuralDesignButton.Text = 'Create Voxel based Structural Design';
            app.EdgeThicknessEditFieldLabel = uilabel(app.ConvertHexmeshtoVoxelbasedMaterialLayoutPanel);
            app.EdgeThicknessEditFieldLabel.Text = 'Edge Thickness';
            app.EdgeThicknessEditField = uieditfield(app.ConvertHexmeshtoVoxelbasedMaterialLayoutPanel, 'numeric');
            app.EdgeThicknessEditField.Value = 2;
            app.EntireBoundaryEditFieldLabel = uilabel(app.ConvertHexmeshtoVoxelbasedMaterialLayoutPanel);
            app.EntireBoundaryEditFieldLabel.Text = 'Entire Boundary';
            app.EntireBoundaryEditField = uieditfield(app.ConvertHexmeshtoVoxelbasedMaterialLayoutPanel, 'numeric');
            app.EntireBoundaryEditField.Value = 2;
            app.LoadingAreaEditFieldLabel = uilabel(app.ConvertHexmeshtoVoxelbasedMaterialLayoutPanel);
            app.LoadingAreaEditFieldLabel.Text = 'Loading Area';
            app.LoadingAreaEditField = uieditfield(app.ConvertHexmeshtoVoxelbasedMaterialLayoutPanel, 'numeric');
            app.FixedAreaEditFieldLabel = uilabel(app.ConvertHexmeshtoVoxelbasedMaterialLayoutPanel);
            app.FixedAreaEditFieldLabel.Text = 'Fixed Area';
            app.FixedAreaEditField = uieditfield(app.ConvertHexmeshtoVoxelbasedMaterialLayoutPanel, 'numeric');
            app.StiffnessEvaluationPanel = uipanel(app.StressalignedGeometricParametrizationbasedDesignPanel);
            app.StiffnessEvaluationPanel.Title = 'Stiffness Evaluation';
            app.DesignComplianceEditFieldLabel = uilabel(app.StiffnessEvaluationPanel);
            app.DesignComplianceEditFieldLabel.Text = 'Design Compliance';
            app.DesignComplianceEditField = uieditfield(app.StiffnessEvaluationPanel, 'numeric');
            app.DesignVolumeFractionEditFieldLabel = uilabel(app.StiffnessEvaluationPanel);
            app.DesignVolumeFractionEditFieldLabel.Text = 'Design Volume Fraction';
            app.DesignVolumeFractionEditField = uieditfield(app.StiffnessEvaluationPanel, 'numeric');
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton = uibutton(app.StiffnessEvaluationPanel, 'push');
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.ButtonPushedFcn = createCallbackFcn(app, @StiffnessEvaluateofVoxelbasedStructuralDesignButtonPushed, true);
            app.StiffnessEvaluateofVoxelbasedStructuralDesignButton.Text = 'Stiffness Evaluate of Voxel based Structural Design';
            app.Label = uilabel(app.StressalignedGeometricParametrizationbasedDesignPanel);
            app.Label.Text = '*';
            app.TextArea = uitextarea(app.StressalignedGeometricParametrizationbasedDesignPanel);
            app.TextArea.Value = {'[Arora et al. 2017, TOG] Arora, Rahul, et al. "Volumetric michell trusses for parametric design & fabrication." Proceedings of the 3rd Annual ACM Symposium on Computational Fabrication. 2019.'};
            app.MeshGraphGenerationPanel_2 = uipanel(app.StressalignedGeometricParametrizationbasedDesignPanel);
            app.MeshGraphGenerationPanel_2.Title = 'Mesh\Graph Generation';
            app.RunStressalignedParametrizationButton_2 = uibutton(app.MeshGraphGenerationPanel_2, 'push');
            app.RunStressalignedParametrizationButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunStressalignedParametrizationButton_2Pushed, true);
            app.RunStressalignedParametrizationButton_2.Text = 'Run Stress-aligned Parametrization';
            app.OutputResolutionEditFieldLabel = uilabel(app.MeshGraphGenerationPanel_2);
            app.OutputResolutionEditFieldLabel.Text = 'Output Resolution';
            app.OutputResolutionEditField = uieditfield(app.MeshGraphGenerationPanel_2, 'numeric');
            app.OutputResolutionEditField.Value = 20;
            app.StressFieldPreparationPanel = uipanel(app.UIFigure);
            app.StressFieldPreparationPanel.Title = 'Stress Field Preparation';
            app.StressAnalysisButton = uibutton(app.StressFieldPreparationPanel, 'push');
            app.StressAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StressAnalysisButtonPushed, true);
            app.StressAnalysisButton.Text = 'Stress Analysis';
            app.StressFieldSelectionDropDownLabel = uilabel(app.StressFieldPreparationPanel);
            app.StressFieldSelectionDropDownLabel.Text = 'Stress Field Selection';
            app.StressFieldSelectionDropDown = uidropdown(app.StressFieldPreparationPanel);
            app.StressFieldSelectionDropDown.Items = {'On Input Tet/Hex Mesh', 'On Cartesian Mesh'};
            app.StressFieldSelectionDropDown.Value = 'On Input Tet/Hex Mesh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimTask_StressAwareGeoSyn_3rdParty_AroraMethod_previous(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end