classdef SimTask_StressAwareGeoSyn_3rdParty_GaoMethod < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StressalignedGeometricParametrizationbasedDesignPanel  matlab.ui.container.Panel
        InputDataPreparationTetmeshStressFieldPanel  matlab.ui.container.Panel
        TetrahedraApproxiEditField      matlab.ui.control.NumericEditField
        TetrahedraApproxiEditFieldLabel  matlab.ui.control.Label
        DataPreparationButton           matlab.ui.control.Button
        RefTextArea                     matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        ResultDisplayPanel              matlab.ui.container.Panel
        DesignVolumeFractionEditField   matlab.ui.control.NumericEditField
        DesignVolumeFractionEditFieldLabel  matlab.ui.control.Label
        DesignComplianceEditField       matlab.ui.control.NumericEditField
        DesignComplianceEditFieldLabel  matlab.ui.control.Label
        GenerationSimulationPanel       matlab.ui.container.Panel
        LatticeAspectRatioEditField     matlab.ui.control.NumericEditField
        LatticeAspectRatioEditFieldLabel  matlab.ui.control.Label
        EvaluateStressAlignmentScaleButton  matlab.ui.control.Button
        StressAnalysisonDesignButton    matlab.ui.control.Button
        RestartLinearSystemSolvingButton  matlab.ui.control.Button
        StressalignedConformingLatticeInfillDesignButton  matlab.ui.control.Button
        StiffnessEvaluationofVoxelbasedStructuralDesignButton  matlab.ui.control.Button
        SettingsforMaterialLayoutConvertionPanel  matlab.ui.container.Panel
        MaterialBudgetEditField         matlab.ui.control.NumericEditField
        MaterialBudgetEditFieldLabel    matlab.ui.control.Label
        FixedAreaEditField              matlab.ui.control.NumericEditField
        FixedAreaEditFieldLabel         matlab.ui.control.Label
        LoadingAreaEditField            matlab.ui.control.NumericEditField
        LoadingAreaEditFieldLabel       matlab.ui.control.Label
        EntireBoundaryEditField         matlab.ui.control.NumericEditField
        EntireBoundaryEditFieldLabel    matlab.ui.control.Label
        EdgeThicknessEditField          matlab.ui.control.NumericEditField
        EdgeThicknessEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            addpath('./externalModules/Gao2017/');
            app.MainApp = mainapp;
            app.GenerationSimulationPanel.Enable = 'off';
            % app.StressalignedConformingLatticeInfillDesignButton.Enable = 'off';
            % app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'off';
            % app.RestartLinearSystemSolvingButton.Enable = 'off';
            % app.StressAnalysisonDesignButton.Enable = 'off';
            % app.EvaluateStressAlignmentScaleButton.Enable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                % app.MainApp.SimulationTasksDropDown.Enable = 'on';
                % app.MainApp.FEAwithSolidDesignDomainButton.Enable = 'on';
                MainWindowCtrl(app.MainApp, 1);
                app.MainApp.SimulationTasksDropDown.Value = 'None';
            end
            delete(app) 
        end

        % Button pushed function: DataPreparationButton
        function DataPreparationButtonPushed(app, event)
            global cartesianStressField_;
            app.DataPreparationButton.Enable = 'off';
            pause(1);

            if isempty(cartesianStressField_)
                warning('No Stress Field is Available!');
                app.DataPreparationButton.Enable = 'on';
                return;
            end
            
            disp('Input Data is Preparing...');
            numTetrahedraInGatewayMesh = app.TetrahedraApproxiEditField.Value;
            numTetrahedraInGatewayMesh = max(numTetrahedraInGatewayMesh, 50000);
            SAGS_GenerateDelaunayTetMeshFromInputSurfaceMesh(round(numTetrahedraInGatewayMesh/2));            
            SAGS_InterpolatingStressFieldOnTetMesh();

            app.DataPreparationButton.Enable = 'on';
            app.GenerationSimulationPanel.Enable = 'on';
                app.StressalignedConformingLatticeInfillDesignButton.Enable = 'on';
                app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'off';
                app.RestartLinearSystemSolvingButton.Enable = 'off';
                app.StressAnalysisonDesignButton.Enable = 'off';
                app.EvaluateStressAlignmentScaleButton.Enable = 'off';                    
            disp('Input Data is Ready!');
        end

        % Button pushed function: 
        % StressalignedConformingLatticeInfillDesignButton
        function StressalignedConformingLatticeInfillDesignButtonPushed(app, event)
            global outPath_;
            global volumeFractionDesign_;

            app.InputDataPreparationTetmeshStressFieldPanel.Enable = 'off';
            app.SettingsforMaterialLayoutConvertionPanel.Enable = 'off';
            app.GenerationSimulationPanel.Enable = 'off';
            app.ResultDisplayPanel.Enable = 'off';
                app.DesignComplianceEditField.Value = 0;
                app.DesignVolumeFractionEditField.Value = 0;            
            pause(1);

            targetDepositionRatio = app.MaterialBudgetEditField.Value;
            edgeWidth = app.EdgeThicknessEditField.Value; edgeWidth = max(edgeWidth,1);
            numLayerboundary = app.EntireBoundaryEditField.Value; numLayerboundary = max(numLayerboundary,0);
            numLayerLoads = app.LoadingAreaEditField.Value; numLayerLoads = max(numLayerLoads,0);
            numLayerFixation = app.FixedAreaEditField.Value; numLayerFixation = max(numLayerFixation,0);
            aspectRatio = app.LatticeAspectRatioEditField.Value;
            if aspectRatio<0, return; end
            aspectRatio = min(aspectRatio,1);
            aspectRatio = max(aspectRatio,0.25);
            app.LatticeAspectRatioEditField.Value = aspectRatio;
            if targetDepositionRatio<=0, return; end
            
            SAGS_StressAlignedConformingLatticeGeneration(edgeWidth, targetDepositionRatio, numLayerboundary, ...
                numLayerLoads, numLayerFixation, aspectRatio);
            
            app.InputDataPreparationTetmeshStressFieldPanel.Enable = 'on';
            app.SettingsforMaterialLayoutConvertionPanel.Enable = 'on';
            app.GenerationSimulationPanel.Enable = 'on';
                app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Enable = 'on';
            app.ResultDisplayPanel.Enable = 'on';
                app.DesignVolumeFractionEditField.Value = volumeFractionDesign_;    
            
            % app.MainApp.ShowDesignbyDensityFieldMenu.Enable = 'on';            
            % app.MainApp.InputEdgeVertexGraphMenu.Enable = 'on';
            ShowEdgeVertexGraph_Public(app.MainApp);            

            %%Output&Vis Design
            fileName = strcat(outPath_, 'DesignVolume.nii');
	        IO_ExportDesignInVolume_Geo_nii(fileName);
            %system('"./src/quokka.exe" ./out/DesignVolume.nii');    
        end

        % Button pushed function: 
        % StiffnessEvaluationofVoxelbasedStructuralDesignButton
        function StiffnessEvaluationofVoxelbasedStructuralDesignButtonPushed(app, event)
            global complianceDesign_;
            global volumeFraction_;
            global densityLayout_;            

            app.InputDataPreparationTetmeshStressFieldPanel.Enable = 'off';
            app.SettingsforMaterialLayoutConvertionPanel.Enable = 'off';
            app.GenerationSimulationPanel.Enable = 'off';
            app.ResultDisplayPanel.Enable = 'off';    
            pause(1);

            GatherLSSandMPsettings(app.MainApp);
            [complianceDesign_, volumeFraction_] = FEA_ComputeComplianceVoxel(densityLayout_);
            
            app.InputDataPreparationTetmeshStressFieldPanel.Enable = 'on';
            app.SettingsforMaterialLayoutConvertionPanel.Enable = 'on';
            app.GenerationSimulationPanel.Enable = 'on';
                app.RestartLinearSystemSolvingButton.Enable = 'on';
                app.StressAnalysisonDesignButton.Enable = 'on';                
            app.ResultDisplayPanel.Enable = 'on'; 
                app.DesignComplianceEditField.Value = complianceDesign_;

            % app.MainApp.SimulationTasksDropDown.Enable = 'on';
            % app.MainApp.ShowDeformationMenu.Enable = 'on';
            ShowDeformation_Public(app.MainApp);            
        end

        % Button pushed function: RestartLinearSystemSolvingButton
        function RestartLinearSystemSolvingButtonPushed(app, event)
            global meshHierarchy_;
            global complianceDesign_;

            app.InputDataPreparationTetmeshStressFieldPanel.Enable = 'off';
            app.SettingsforMaterialLayoutConvertionPanel.Enable = 'off';
            app.GenerationSimulationPanel.Enable = 'off';
            app.ResultDisplayPanel.Enable = 'off';    
            pause(1);

            tStart = tic;
            GatherLSSandMPsettings(app.MainApp);
            Solving_CG_GMGS('printP_ON');
            ceList = TopOpti_ComputeUnitCompliance();
            complianceDesign_ = meshHierarchy_(1).eleModulus*ceList;
            disp(['Re-start Linear System Costs: ', sprintf('%.f', toc(tStart)), 's']);
            
            app.InputDataPreparationTetmeshStressFieldPanel.Enable = 'on';
            app.SettingsforMaterialLayoutConvertionPanel.Enable = 'on';
            app.GenerationSimulationPanel.Enable = 'on';              
            app.ResultDisplayPanel.Enable = 'on'; 
                app.DesignComplianceEditField.Value = complianceDesign_;       
        end

        % Button pushed function: StressAnalysisonDesignButton
        function StressAnalysisonDesignButtonPushed(app, event)
            global outPath_;
            global U_;

            app.InputDataPreparationTetmeshStressFieldPanel.Enable = 'off';
            app.SettingsforMaterialLayoutConvertionPanel.Enable = 'off';
            app.GenerationSimulationPanel.Enable = 'off';
            app.ResultDisplayPanel.Enable = 'off';    
            pause(1);         
            
            disp('Stress Analysis on Design ...');            
            tStressAnalysis = tic;
            [cartesianStressFieldDesign, ~] = FEA_StressAnalysis();  
            dominantDirDesign = Common_ExtractDominantDirectionsFromPrincipalStressDirections(cartesianStressFieldDesign);             
            if isempty(dominantDirDesign), return; end
            niftiwrite(dominantDirDesign, strcat(outPath_, 'dominantDirDesign.nii'));
            disp(['Done with Stress Analysis after ', sprintf('%.f', toc(tStressAnalysis)), 's']);
            
            app.InputDataPreparationTetmeshStressFieldPanel.Enable = 'on';
            app.SettingsforMaterialLayoutConvertionPanel.Enable = 'on';
            app.GenerationSimulationPanel.Enable = 'on';
                app.EvaluateStressAlignmentScaleButton.Enable = 'on';
            app.ResultDisplayPanel.Enable = 'on'; 

            U_ = zeros(size(U_));
        end

        % Button pushed function: EvaluateStressAlignmentScaleButton
        function EvaluateStressAlignmentScaleButtonPushed(app, event)
            global outPath_;
            dominantDirSolid = niftiread(strcat(outPath_, 'dominantDirSolid.nii'));
            dominantDirDesign = niftiread(strcat(outPath_, 'dominantDirDesign.nii'));
            disp('Compute Stress Aligment Scale between Solid and Design...');
            tStressAligmentAna = tic;
            alignmentMetricVolumeByStressAlignment = Common_ComputeStressAlignmentDeviation(dominantDirSolid, dominantDirDesign);
            niftiwrite(alignmentMetricVolumeByStressAlignment, strcat(outPath_, 'alignmentMetricVolume_byStress.nii'));            
            alignmentMetricVolumeByEdgeAlignment = Common_ComputeEdgeAlignmentDeviation(dominantDirDesign);
            niftiwrite(alignmentMetricVolumeByEdgeAlignment, strcat(outPath_, 'alignmentMetricVolume_byEdge.nii'));
            disp(['Done with Stress Alignment Analysis after ', sprintf('%.1f', toc(tStressAligmentAna)), 's']);
            %system('"./src/quokka.exe" ./out/alignmentMetricVolume_byStress.nii');                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StressalignedGeometricParametrizationbasedDesignPanel = uipanel(app.UIFigure);
            app.StressalignedGeometricParametrizationbasedDesignPanel.Title = 'Conforming Lattice Structure';
            app.SettingsforMaterialLayoutConvertionPanel = uipanel(app.StressalignedGeometricParametrizationbasedDesignPanel);
            app.SettingsforMaterialLayoutConvertionPanel.Title = 'Settings for Material Layout Convertion';
            app.EdgeThicknessEditFieldLabel = uilabel(app.SettingsforMaterialLayoutConvertionPanel);
            app.EdgeThicknessEditFieldLabel.Text = 'Edge Thickness';
            app.EdgeThicknessEditField = uieditfield(app.SettingsforMaterialLayoutConvertionPanel, 'numeric');
            app.EdgeThicknessEditField.ValueDisplayFormat = '%.0f';
            app.EdgeThicknessEditField.Value = 3;
            app.EntireBoundaryEditFieldLabel = uilabel(app.SettingsforMaterialLayoutConvertionPanel);
            app.EntireBoundaryEditFieldLabel.Text = 'Entire Boundary';
            app.EntireBoundaryEditField = uieditfield(app.SettingsforMaterialLayoutConvertionPanel, 'numeric');
            app.EntireBoundaryEditField.ValueDisplayFormat = '%.0f';
            app.EntireBoundaryEditField.Value = 2;
            app.LoadingAreaEditFieldLabel = uilabel(app.SettingsforMaterialLayoutConvertionPanel);
            app.LoadingAreaEditFieldLabel.Text = 'Loading Area';
            app.LoadingAreaEditField = uieditfield(app.SettingsforMaterialLayoutConvertionPanel, 'numeric');
            app.LoadingAreaEditField.ValueDisplayFormat = '%.0f';
            app.FixedAreaEditFieldLabel = uilabel(app.SettingsforMaterialLayoutConvertionPanel);
            app.FixedAreaEditFieldLabel.Text = 'Fixed Area';
            app.FixedAreaEditField = uieditfield(app.SettingsforMaterialLayoutConvertionPanel, 'numeric');
            app.FixedAreaEditField.ValueDisplayFormat = '%.0f';
            app.MaterialBudgetEditFieldLabel = uilabel(app.SettingsforMaterialLayoutConvertionPanel);
            app.MaterialBudgetEditFieldLabel.Text = 'Material Budget';
            app.MaterialBudgetEditField = uieditfield(app.SettingsforMaterialLayoutConvertionPanel, 'numeric');
            app.MaterialBudgetEditField.Value = 0.4;
            app.GenerationSimulationPanel = uipanel(app.StressalignedGeometricParametrizationbasedDesignPanel);
            app.GenerationSimulationPanel.Title = 'Generation & Simulation';
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton = uibutton(app.GenerationSimulationPanel, 'push');
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.ButtonPushedFcn = createCallbackFcn(app, @StiffnessEvaluationofVoxelbasedStructuralDesignButtonPushed, true);
            app.StiffnessEvaluationofVoxelbasedStructuralDesignButton.Text = 'Stiffness Evaluation of Voxel based Structural Design';
            app.StressalignedConformingLatticeInfillDesignButton = uibutton(app.GenerationSimulationPanel, 'push');
            app.StressalignedConformingLatticeInfillDesignButton.ButtonPushedFcn = createCallbackFcn(app, @StressalignedConformingLatticeInfillDesignButtonPushed, true);
            app.StressalignedConformingLatticeInfillDesignButton.Text = 'Stress-aligned Conforming Lattice Infill Design';
            app.RestartLinearSystemSolvingButton = uibutton(app.GenerationSimulationPanel, 'push');
            app.RestartLinearSystemSolvingButton.ButtonPushedFcn = createCallbackFcn(app, @RestartLinearSystemSolvingButtonPushed, true);
            app.RestartLinearSystemSolvingButton.Text = 'Re-start Linear System Solving';
            app.StressAnalysisonDesignButton = uibutton(app.GenerationSimulationPanel, 'push');
            app.StressAnalysisonDesignButton.ButtonPushedFcn = createCallbackFcn(app, @StressAnalysisonDesignButtonPushed, true);
            app.StressAnalysisonDesignButton.Text = 'Stress Analysis on Design';
            app.EvaluateStressAlignmentScaleButton = uibutton(app.GenerationSimulationPanel, 'push');
            app.EvaluateStressAlignmentScaleButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateStressAlignmentScaleButtonPushed, true);
            app.EvaluateStressAlignmentScaleButton.Text = 'Evaluate Stress Alignment Scale';
            app.LatticeAspectRatioEditFieldLabel = uilabel(app.GenerationSimulationPanel);
            app.LatticeAspectRatioEditFieldLabel.Text = 'Lattice Aspect Ratio';
            app.LatticeAspectRatioEditField = uieditfield(app.GenerationSimulationPanel, 'numeric');
            app.LatticeAspectRatioEditField.Value = 1;
            app.ResultDisplayPanel = uipanel(app.StressalignedGeometricParametrizationbasedDesignPanel);
            app.ResultDisplayPanel.Title = 'Result Display';
            app.DesignComplianceEditFieldLabel = uilabel(app.ResultDisplayPanel);
            app.DesignComplianceEditFieldLabel.Text = 'Design Compliance';
            app.DesignComplianceEditField = uieditfield(app.ResultDisplayPanel, 'numeric');
            app.DesignVolumeFractionEditFieldLabel = uilabel(app.ResultDisplayPanel);
            app.DesignVolumeFractionEditFieldLabel.Text = 'Design Volume Fraction';
            app.DesignVolumeFractionEditField = uieditfield(app.ResultDisplayPanel, 'numeric');
            app.Label = uilabel(app.StressalignedGeometricParametrizationbasedDesignPanel);
            app.Label.Text = 'Ref.';
            app.RefTextArea = uitextarea(app.StressalignedGeometricParametrizationbasedDesignPanel);
            app.RefTextArea.Value = {'[1] Gao, Xifeng, et al. "Robust hex-dominant mesh generation using field-guided polyhedral agglomeration." ACM Transactions on Graphics (TOG) 36.4 (2017): 1-13.'; '[2] Wu, Jun, Weiming Wang, and Xifeng Gao. "Design and optimization of conforming lattice structures." IEEE transactions on visualization and computer graphics 27.1 (2019): 43-56.'};
            app.InputDataPreparationTetmeshStressFieldPanel = uipanel(app.StressalignedGeometricParametrizationbasedDesignPanel);
            app.InputDataPreparationTetmeshStressFieldPanel.Title = 'Input Data Preparation (Tet-mesh & Stress Field)';
            app.DataPreparationButton = uibutton(app.InputDataPreparationTetmeshStressFieldPanel, 'push');
            app.DataPreparationButton.ButtonPushedFcn = createCallbackFcn(app, @DataPreparationButtonPushed, true);
            app.DataPreparationButton.Text = 'Data Preparation';
            app.TetrahedraApproxiEditFieldLabel = uilabel(app.InputDataPreparationTetmeshStressFieldPanel);
            app.TetrahedraApproxiEditFieldLabel.Text = '#Tetrahedra Approxi.';
            app.TetrahedraApproxiEditField = uieditfield(app.InputDataPreparationTetmeshStressFieldPanel, 'numeric');
            app.TetrahedraApproxiEditField.ValueDisplayFormat = '%.0f';
            app.TetrahedraApproxiEditField.Value = 200000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimTask_StressAwareGeoSyn_3rdParty_GaoMethod(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end