classdef SimTask_TopologyOptimization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TopologyOptimizationPanel       matlab.ui.container.Panel
        OptimizationProcessSettingsPanel  matlab.ui.container.Panel
        ConvergeMinChangeEditField      matlab.ui.control.NumericEditField
        ConvergeMinChangeEditFieldLabel  matlab.ui.control.Label
        ConvergeSharpnessEditField      matlab.ui.control.NumericEditField
        ConvergeSharpnessEditFieldLabel  matlab.ui.control.Label
        MovingStepsizeEditField_2       matlab.ui.control.NumericEditField
        MovingStepsizeEditField_2Label  matlab.ui.control.Label
        OptimizerDropDown_2             matlab.ui.control.DropDown
        OptimizerDropDown_2Label        matlab.ui.control.Label
        IterationsforOptimizationEditField  matlab.ui.control.NumericEditField
        IterationsforOptimizationEditFieldLabel  matlab.ui.control.Label
        ConductOptimizationPanel        matlab.ui.container.Panel
        DesignVolumeFractionEditField   matlab.ui.control.NumericEditField
        DesignVolumeFractionEditFieldLabel  matlab.ui.control.Label
        DesignComplianceEditField       matlab.ui.control.NumericEditField
        DesignComplianceEditFieldLabel  matlab.ui.control.Label
        RunTopologyOptimizationButton_2  matlab.ui.control.Button
        DebugModeCheckBox_2             matlab.ui.control.CheckBox
        SpecifyOptimizationEquationPanel  matlab.ui.container.Panel
        EffectingRadiusEditField_2      matlab.ui.control.NumericEditField
        EffectingRadiusEditField_2Label  matlab.ui.control.Label
        LocalVolumeFractionEditField_2  matlab.ui.control.NumericEditField
        LocalVolumeFractionEditField_2Label  matlab.ui.control.Label
        MaterialBudgetEditField_2       matlab.ui.control.NumericEditField
        MaterialBudgetEditField_2Label  matlab.ui.control.Label
        VolumeConstraintDropDown_2      matlab.ui.control.DropDown
        VolumeConstraintDropDown_2Label  matlab.ui.control.Label
        FilteringProjectionPenaltyPanel  matlab.ui.container.Panel
        PenalyuUpdateEveryIterationEditField  matlab.ui.control.NumericEditField
        PenalyuUpdateEveryIterationEditFieldLabel  matlab.ui.control.Label
        PenaltyUpdateIncrementEditField  matlab.ui.control.NumericEditField
        PenaltyUpdateIncrementEditFieldLabel  matlab.ui.control.Label
        PenaltyinSIMPEditField          matlab.ui.control.NumericEditField
        PenaltyinSIMPEditFieldLabel     matlab.ui.control.Label
        MaximumPenaltyofHeavisideProjectionEditField  matlab.ui.control.NumericEditField
        MaximumPenaltyofHeavisideProjectionEditFieldLabel  matlab.ui.control.Label
        RadiusofDensitybasedFilterEditField_2  matlab.ui.control.NumericEditField
        RadiusofDensitybasedFilterEditField_2Label  matlab.ui.control.Label
        SpecifyDesignDomainPanel        matlab.ui.container.Panel
        VolumeFractionPassiveElementsEditField  matlab.ui.control.NumericEditField
        VolumeFractionPassiveElementsEditFieldLabel  matlab.ui.control.Label
        ClearPassiveElementsButton      matlab.ui.control.Button
        FixedAreaEditField              matlab.ui.control.NumericEditField
        FixedAreaEditFieldLabel         matlab.ui.control.Label
        LoadingAreaEditField_2          matlab.ui.control.NumericEditField
        LoadingAreaEditField_2Label     matlab.ui.control.Label
        EntireBoundaryEditField_2       matlab.ui.control.NumericEditField
        EntireBoundaryEditField_2Label  matlab.ui.control.Label
        SetPassiveElementsButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;            
            % app.VolumeConstraintDropDown_2.Enable = 'off';
            % app.OptimizerDropDown_2.Enable = 'off';
            % app.RunTopologyOptimizationButton_2.Enable = 'off';
            % app.EffectingRadiusEditField_2.Enable = 'off';
            % app.LocalVolumeFractionEditField_2.Enable = 'off';
            app.DesignComplianceEditField.Editable = 'off';
            app.DesignVolumeFractionEditField.Editable = 'off';
            app.VolumeFractionPassiveElementsEditField.Editable = 'off';
            app.VolumeFractionPassiveElementsEditField.Value = TopOpti_GetVolumeFractionOfPassiveElements();
            ShowDesignDomain_Public(app.MainApp);  
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.MainApp)
                app.MainApp.SimulationTasksDropDown.Enable = 'on';
                app.MainApp.FEAwithSolidDesignDomainButton.Enable = 'on';
                app.MainApp.SimulationTasksDropDown.Value = 'None';
            end
            delete(app)               
        end

        % Button pushed function: SetPassiveElementsButton
        function SetPassiveElementsButtonPushed(app, event)
            global voxelsOnBoundary_;
            app.SetPassiveElementsButton.Enable = 'off';
            app.VolumeConstraintDropDown_2.Enable = 'off';
            app.OptimizerDropDown_2.Enable = 'off';
            app.RunTopologyOptimizationButton_2.Enable = 'off';              
            pause(1);
            numLayerboundary = app.EntireBoundaryEditField_2.Value;
            numLayerLoads = app.LoadingAreaEditField_2.Value;
            numLayerFixation = app.FixedAreaEditField.Value;
            [voxelsOnBoundary_,~,~] = TopOpti_SetPassiveElements(numLayerboundary, numLayerLoads, numLayerFixation);
            app.VolumeFractionPassiveElementsEditField.Value = TopOpti_GetVolumeFractionOfPassiveElements();
            ShowDesignDomain_Public(app.MainApp);            
            app.SetPassiveElementsButton.Enable = 'on';
            app.VolumeConstraintDropDown_2.Enable = 'on';
            app.OptimizerDropDown_2.Enable = 'on';
            app.RunTopologyOptimizationButton_2.Enable = 'on';            
        end

        % Value changed function: VolumeConstraintDropDown_2
        function VolumeConstraintDropDown_2ValueChanged(app, event)
            value = app.VolumeConstraintDropDown_2.Value;
            if strcmp(value, 'Local')
                app.OptimizerDropDown_2.Value = 'MMA';
                app.OptimizerDropDown_2.Enable = 'off';
                app.MaterialBudgetEditField_2.Enable = 'off';
                app.EffectingRadiusEditField_2.Enable = 'on';
                app.LocalVolumeFractionEditField_2.Enable = 'on';                
            else
                app.OptimizerDropDown_2.Enable = 'on';
                app.MaterialBudgetEditField_2.Enable = 'on';
                app.EffectingRadiusEditField_2.Enable = 'off';
                app.LocalVolumeFractionEditField_2.Enable = 'off';
            end
        end

        % Button pushed function: RunTopologyOptimizationButton_2
        function RunTopologyOptimizationButton_2Pushed(app, event)
            global rMin_;
            global pMax_;
            global constraintType_;
            global V_;
            global rHatMin_;
            global alphaMin_;
            global nLoop_;
            global maxSharpness_;
            global minChange_;
            % global continueTopOpt_;
            global optimizer_;
            global move_;
            global penalty_;
            global penaltyIncrement_;
            global penaltyUpdateIterations_;
            % global continuePosition_;
            global complianceDesign_;
            global volumeFractionDesign_;
            global complianceSolid_;
            global DEBUG_;
            global axHandle_;
            app.SetPassiveElementsButton.Enable = 'off';
            app.VolumeConstraintDropDown_2.Enable = 'off';
            app.OptimizerDropDown_2.Enable = 'off';
            app.RunTopologyOptimizationButton_2.Enable = 'off';
            pause(1);
            GatherLSSandMPsettings(app.MainApp);
            rMin_ = app.RadiusofDensitybasedFilterEditField_2.Value; rMin_ = max(rMin_, 1.6);
            pMax_ = app.MaximumPenaltyofHeavisideProjectionEditField.Value; pMax_ = max(pMax_, 128);
            penalty_ = app.PenaltyinSIMPEditField.Value;
            if penalty_<0, penalty_ = 1.0; app.PenaltyinSIMPEditField.Value = 1.0; end
            if penalty_>3.0, penalty_ = 3.0; app.PenaltyinSIMPEditField.Value = 3.0; end
            penaltyIncrement_ = app.PenaltyUpdateIncrementEditField.Value;
            if penaltyIncrement_<0, penaltyIncrement_ = 0.2; app.PenaltyUpdateIncrementEditField.Value = 0.2; end
            if penaltyIncrement_>3.0, penaltyIncrement_ = 0.2; app.PenaltyUpdateIncrementEditField.Value = 0.2; end
            penaltyUpdateIterations_ = app.PenalyuUpdateEveryIterationEditField.Value;
            % continuePosition_ = round(app.RestartPositionEditField.Value);
            constraintType_ = app.VolumeConstraintDropDown_2.Value;
            V_ = app.MaterialBudgetEditField_2.Value;
            rHatMin_ = app.EffectingRadiusEditField_2.Value;
            alphaMin_ = app.LocalVolumeFractionEditField_2.Value;
            nLoop_ = app.IterationsforOptimizationEditField.Value;
            maxSharpness_ = app.ConvergeSharpnessEditField.Value;
            minChange_ = app.ConvergeMinChangeEditField.Value;
            % continueTopOpt_ = app.FurtherOptimizationCheckBox.Value;
            optimizer_ = app.OptimizerDropDown_2.Value;
            move_ = app.MovingStepsizeEditField_2.Value;
            DEBUG_ = app.DebugModeCheckBox_2.Value;

            TopOpti_CallTopOpti(axHandle_);
            app.DesignComplianceEditField.Value = complianceDesign_;
            app.DesignVolumeFractionEditField.Value = volumeFractionDesign_;
            ShowDesignDensityLayout_Public(app.MainApp);
            app.SetPassiveElementsButton.Enable = 'on';
            app.VolumeConstraintDropDown_2.Enable = 'on';
            app.OptimizerDropDown_2.Enable = 'on';
            app.RunTopologyOptimizationButton_2.Enable = 'on';
            app.MainApp.SolidComplianceEditField.Value = complianceSolid_;
            app.MainApp.ShowDesignbyDensityFieldMenu.Enable = 'on';
            system('"./src/vape4d.exe" ./out/DesignVolume.nii');
        end

        % Button pushed function: ClearPassiveElementsButton
        function ClearPassiveElementsButtonPushed(app, event)
            global passiveElements_;
            passiveElements_ = [];
            app.EntireBoundaryEditField_2.Value = 0;
            app.LoadingAreaEditField_2.Value = 0;
            app.FixedAreaEditField.Value = 0;
            app.VolumeFractionPassiveElementsEditField.Value = 0.0;
            ShowDesignDomain_Public(app.MainApp);  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TopologyOptimizationPanel = uipanel(app.UIFigure);
            app.TopologyOptimizationPanel.Title = 'Topology Optimization';
            app.SpecifyDesignDomainPanel = uipanel(app.TopologyOptimizationPanel);
            app.SpecifyDesignDomainPanel.Title = 'Specify Design Domain';
            app.SetPassiveElementsButton = uibutton(app.SpecifyDesignDomainPanel, 'push');
            app.SetPassiveElementsButton.ButtonPushedFcn = createCallbackFcn(app, @SetPassiveElementsButtonPushed, true);
            app.SetPassiveElementsButton.Text = 'Set Passive Elements';
            app.EntireBoundaryEditField_2Label = uilabel(app.SpecifyDesignDomainPanel);
            app.EntireBoundaryEditField_2Label.Text = 'Entire Boundary';
            app.EntireBoundaryEditField_2 = uieditfield(app.SpecifyDesignDomainPanel, 'numeric');
            app.LoadingAreaEditField_2Label = uilabel(app.SpecifyDesignDomainPanel);
            app.LoadingAreaEditField_2Label.Text = 'Loading Area';
            app.LoadingAreaEditField_2 = uieditfield(app.SpecifyDesignDomainPanel, 'numeric');
            app.FixedAreaEditFieldLabel = uilabel(app.SpecifyDesignDomainPanel);
            app.FixedAreaEditFieldLabel.Text = 'Fixed Area';
            app.FixedAreaEditField = uieditfield(app.SpecifyDesignDomainPanel, 'numeric');
            app.ClearPassiveElementsButton = uibutton(app.SpecifyDesignDomainPanel, 'push');
            app.ClearPassiveElementsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPassiveElementsButtonPushed, true);
            app.ClearPassiveElementsButton.Text = 'Clear Passive Elements';
            app.VolumeFractionPassiveElementsEditFieldLabel = uilabel(app.SpecifyDesignDomainPanel);
            app.VolumeFractionPassiveElementsEditFieldLabel.Text = 'Volume Fraction (Passive Elements)';
            app.VolumeFractionPassiveElementsEditField = uieditfield(app.SpecifyDesignDomainPanel, 'numeric');
            app.FilteringProjectionPenaltyPanel = uipanel(app.TopologyOptimizationPanel);
            app.FilteringProjectionPenaltyPanel.Title = 'Filtering&Projection&Penalty';
            app.RadiusofDensitybasedFilterEditField_2Label = uilabel(app.FilteringProjectionPenaltyPanel);
            app.RadiusofDensitybasedFilterEditField_2Label.Text = 'Radius of Density-based Filter';
            app.RadiusofDensitybasedFilterEditField_2 = uieditfield(app.FilteringProjectionPenaltyPanel, 'numeric');
            app.RadiusofDensitybasedFilterEditField_2.Value = 1.6;
            app.MaximumPenaltyofHeavisideProjectionEditFieldLabel = uilabel(app.FilteringProjectionPenaltyPanel);
            app.MaximumPenaltyofHeavisideProjectionEditFieldLabel.Text = 'Maximum Penalty of Heaviside Projection';
            app.MaximumPenaltyofHeavisideProjectionEditField = uieditfield(app.FilteringProjectionPenaltyPanel, 'numeric');
            app.MaximumPenaltyofHeavisideProjectionEditField.Value = 128;
            app.PenaltyinSIMPEditFieldLabel = uilabel(app.FilteringProjectionPenaltyPanel);
            app.PenaltyinSIMPEditFieldLabel.Text = 'Penalty in SIMP';
            app.PenaltyinSIMPEditField = uieditfield(app.FilteringProjectionPenaltyPanel, 'numeric');
            app.PenaltyinSIMPEditField.Value = 3;
            app.PenaltyUpdateIncrementEditFieldLabel = uilabel(app.FilteringProjectionPenaltyPanel);
            app.PenaltyUpdateIncrementEditFieldLabel.Text = 'Penalty Update Increment';
            app.PenaltyUpdateIncrementEditField = uieditfield(app.FilteringProjectionPenaltyPanel, 'numeric');
            app.PenaltyUpdateIncrementEditField.Value = 0.2;
            app.PenalyuUpdateEveryIterationEditFieldLabel = uilabel(app.FilteringProjectionPenaltyPanel);
            app.PenalyuUpdateEveryIterationEditFieldLabel.Text = 'Penalyu Update Every #Iteration';
            app.PenalyuUpdateEveryIterationEditField = uieditfield(app.FilteringProjectionPenaltyPanel, 'numeric');
            app.PenalyuUpdateEveryIterationEditField.Value = 30;
            app.SpecifyOptimizationEquationPanel = uipanel(app.TopologyOptimizationPanel);
            app.SpecifyOptimizationEquationPanel.Title = 'Specify Optimization Equation';
            app.VolumeConstraintDropDown_2Label = uilabel(app.SpecifyOptimizationEquationPanel);
            app.VolumeConstraintDropDown_2Label.Text = 'Volume Constraint';
            app.VolumeConstraintDropDown_2 = uidropdown(app.SpecifyOptimizationEquationPanel);
            app.VolumeConstraintDropDown_2.Items = {'Global', 'Local'};
            app.VolumeConstraintDropDown_2.ValueChangedFcn = createCallbackFcn(app, @VolumeConstraintDropDown_2ValueChanged, true);
            app.VolumeConstraintDropDown_2.Value = 'Global';
            app.MaterialBudgetEditField_2Label = uilabel(app.SpecifyOptimizationEquationPanel);
            app.MaterialBudgetEditField_2Label.Text = 'Material Budget';
            app.MaterialBudgetEditField_2 = uieditfield(app.SpecifyOptimizationEquationPanel, 'numeric');
            app.MaterialBudgetEditField_2.Value = 0.4;
            app.LocalVolumeFractionEditField_2Label = uilabel(app.SpecifyOptimizationEquationPanel);
            app.LocalVolumeFractionEditField_2Label.Text = 'Local Volume Fraction';
            app.LocalVolumeFractionEditField_2 = uieditfield(app.SpecifyOptimizationEquationPanel, 'numeric');
            app.LocalVolumeFractionEditField_2.Value = 0.6;
            app.EffectingRadiusEditField_2Label = uilabel(app.SpecifyOptimizationEquationPanel);
            app.EffectingRadiusEditField_2Label.Text = 'Effecting Radius';
            app.EffectingRadiusEditField_2 = uieditfield(app.SpecifyOptimizationEquationPanel, 'numeric');
            app.EffectingRadiusEditField_2.Value = 6;
            app.ConductOptimizationPanel = uipanel(app.TopologyOptimizationPanel);
            app.ConductOptimizationPanel.Title = 'Conduct Optimization';
            app.DebugModeCheckBox_2 = uicheckbox(app.ConductOptimizationPanel);
            app.DebugModeCheckBox_2.Text = 'Debug Mode';
            app.DebugModeCheckBox_2.Value = true;
            app.RunTopologyOptimizationButton_2 = uibutton(app.ConductOptimizationPanel, 'push');
            app.RunTopologyOptimizationButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunTopologyOptimizationButton_2Pushed, true);
            app.RunTopologyOptimizationButton_2.Text = 'Run Topology Optimization';
            app.DesignComplianceEditFieldLabel = uilabel(app.ConductOptimizationPanel);
            app.DesignComplianceEditFieldLabel.Text = 'Design Compliance';
            app.DesignComplianceEditField = uieditfield(app.ConductOptimizationPanel, 'numeric');
            app.DesignVolumeFractionEditFieldLabel = uilabel(app.ConductOptimizationPanel);
            app.DesignVolumeFractionEditFieldLabel.Text = 'Design Volume Fraction';
            app.DesignVolumeFractionEditField = uieditfield(app.ConductOptimizationPanel, 'numeric');
            app.OptimizationProcessSettingsPanel = uipanel(app.TopologyOptimizationPanel);
            app.OptimizationProcessSettingsPanel.Title = 'Optimization Process Settings';
            app.IterationsforOptimizationEditFieldLabel = uilabel(app.OptimizationProcessSettingsPanel);
            app.IterationsforOptimizationEditFieldLabel.Text = '#Iterations for Optimization';
            app.IterationsforOptimizationEditField = uieditfield(app.OptimizationProcessSettingsPanel, 'numeric');
            app.IterationsforOptimizationEditField.Value = 300;
            app.OptimizerDropDown_2Label = uilabel(app.OptimizationProcessSettingsPanel);
            app.OptimizerDropDown_2Label.Text = 'Optimizer';
            app.OptimizerDropDown_2 = uidropdown(app.OptimizationProcessSettingsPanel);
            app.OptimizerDropDown_2.Items = {'MMA', 'OC'};
            app.OptimizerDropDown_2.Value = 'MMA';
            app.MovingStepsizeEditField_2Label = uilabel(app.OptimizationProcessSettingsPanel);
            app.MovingStepsizeEditField_2Label.Text = 'Moving Stepsize';
            app.MovingStepsizeEditField_2 = uieditfield(app.OptimizationProcessSettingsPanel, 'numeric');
            app.MovingStepsizeEditField_2.Value = 0.1;
            app.ConvergeSharpnessEditFieldLabel = uilabel(app.OptimizationProcessSettingsPanel);
            app.ConvergeSharpnessEditFieldLabel.Text = 'Converge Sharpness';
            app.ConvergeSharpnessEditField = uieditfield(app.OptimizationProcessSettingsPanel, 'numeric');
            app.ConvergeSharpnessEditField.Value = 0.1;
            app.ConvergeMinChangeEditFieldLabel = uilabel(app.OptimizationProcessSettingsPanel);
            app.ConvergeMinChangeEditFieldLabel.Text = 'Converge Min Change';
            app.ConvergeMinChangeEditField = uieditfield(app.OptimizationProcessSettingsPanel, 'numeric');
            app.ConvergeMinChangeEditField.Value = 0.0001;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimTask_TopologyOptimization(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end