classdef SGLDBench < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        BuiltinShapesMenu               matlab.ui.container.Menu
        CuboidMenu                      matlab.ui.container.Menu
        LshapeMenu                      matlab.ui.container.Menu
        SphereMenu                      matlab.ui.container.Menu
        CylinderMenu                    matlab.ui.container.Menu
        ImportArbitraryMeshASCIIMenu    matlab.ui.container.Menu
        SurfaceTriMeshplyobjMenu        matlab.ui.container.Menu
        SolidHexTetMeshmeshmshvtkMenu   matlab.ui.container.Menu
        EdgeVertexGraphobjMenu          matlab.ui.container.Menu
        ImportVoxelModelTopVoxelMenu    matlab.ui.container.Menu
        ExportVoxelModelTopVoxelMenu    matlab.ui.container.Menu
        ExportDesigninVolumeniiMenu     matlab.ui.container.Menu
        ExportDesigninTriangularMeshMenu  matlab.ui.container.Menu
        stlMenu                         matlab.ui.container.Menu
        objMenu                         matlab.ui.container.Menu
        plyMenu                         matlab.ui.container.Menu
        TempImportWrappedVoxelModeltxtMenu  matlab.ui.container.Menu
        TempExportWrappedVoxelModeltxtMenu  matlab.ui.container.Menu
        TempExportDensityLayouttopoptiMenu  matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        VisualizationMenu               matlab.ui.container.Menu
        ShowProblemDescriptionMenu      matlab.ui.container.Menu
        ShowDesignDomainMenu            matlab.ui.container.Menu
        ShowDeformationMenu             matlab.ui.container.Menu
        UxMenu                          matlab.ui.container.Menu
        UyMenu                          matlab.ui.container.Menu
        UzMenu                          matlab.ui.container.Menu
        TotalMenu_2                     matlab.ui.container.Menu
        ShowStressScalarComponentsMenu  matlab.ui.container.Menu
        PSLsMenu                        matlab.ui.container.Menu
        Sigma_xxMenu                    matlab.ui.container.Menu
        Sigma_yyMenu                    matlab.ui.container.Menu
        Sigma_zzMenu                    matlab.ui.container.Menu
        Tau_yzMenu                      matlab.ui.container.Menu
        Tau_zxMenu                      matlab.ui.container.Menu
        Tau_xyMenu                      matlab.ui.container.Menu
        VonMisesMenu                    matlab.ui.container.Menu
        ShowDesignbyDensityFieldMenu    matlab.ui.container.Menu
        ShowDesignVolumeRenderingTmpMenu  matlab.ui.container.Menu
        ResultVisualAnalyticsVolumeRenderingIsosurfaceMenu  matlab.ui.container.Menu
        OptimizationHistoryMenu         matlab.ui.container.Menu
        FormattedDisplayMenu            matlab.ui.container.Menu
        VisInputMeshGraphVoxelsMenu     matlab.ui.container.Menu
        InputTetHexSolidMeshMenu        matlab.ui.container.Menu
        InputTriSurfaceMeshMenu         matlab.ui.container.Menu
        InputEdgeVertexGraphMenu        matlab.ui.container.Menu
        InputVoxelsMenu                 matlab.ui.container.Menu
        TabGroup3                       matlab.ui.container.TabGroup
        ModelingTab                     matlab.ui.container.Tab
        BuiltinBoundaryConditionsforCantileverModelPanel  matlab.ui.container.Panel
        BuiltinBCsforCuboidDesignDomainDropDown  matlab.ui.control.DropDown
        BuiltinBCsforCuboidDesignDomainDropDownLabel  matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        LoadingTab                      matlab.ui.container.Tab
        FzNEditField                    matlab.ui.control.NumericEditField
        FzNEditFieldLabel               matlab.ui.control.Label
        FyNEditField                    matlab.ui.control.NumericEditField
        FyNEditFieldLabel               matlab.ui.control.Label
        ClearLoadsButton                matlab.ui.control.Button
        ApplyforLoadsButton             matlab.ui.control.Button
        FxNEditField                    matlab.ui.control.NumericEditField
        FxLabel                         matlab.ui.control.Label
        FixingTab                       matlab.ui.container.Tab
        XDirFixedCheckBox               matlab.ui.control.CheckBox
        YDirFixedCheckBox               matlab.ui.control.CheckBox
        ClearFixationButton             matlab.ui.control.Button
        ApplyforFixationButton          matlab.ui.control.Button
        ZDirFixedCheckBox               matlab.ui.control.CheckBox
        ApplyforBoundaryConditionsPanel  matlab.ui.container.Panel
        NodeSelectionButton_2           matlab.ui.control.Button
        NodeUnSelectionButton           matlab.ui.control.Button
        UpdateSelectionBoxButton        matlab.ui.control.Button
        CornerPot2ZEditField            matlab.ui.control.NumericEditField
        CornerPot2ZEditFieldLabel       matlab.ui.control.Label
        CornerPot1ZEditField            matlab.ui.control.NumericEditField
        CornerPot1ZEditFieldLabel       matlab.ui.control.Label
        CornerPot2YEditField            matlab.ui.control.NumericEditField
        CornerPot2YLabel                matlab.ui.control.Label
        CornerPot1YEditField            matlab.ui.control.NumericEditField
        CornerPot1YEditFieldLabel       matlab.ui.control.Label
        CornerPot2XEditField            matlab.ui.control.NumericEditField
        CornerPot2XLabel                matlab.ui.control.Label
        CornerPot1XEditField            matlab.ui.control.NumericEditField
        CornerPot1XLabel                matlab.ui.control.Label
        EnableSelectionBoxCheckBox      matlab.ui.control.CheckBox
        AdditionalNodeSelectionOptionsButton  matlab.ui.control.Button
        DomainVoxelizationPanel         matlab.ui.container.Panel
        DOFsEditField                   matlab.ui.control.NumericEditField
        DOFsEditFieldLabel              matlab.ui.control.Label
        ElementsEditField               matlab.ui.control.NumericEditField
        ElementsEditFieldLabel          matlab.ui.control.Label
        VoxelizingButton                matlab.ui.control.Button
        CellSizeEditField               matlab.ui.control.NumericEditField
        CellSizeEditFieldLabel          matlab.ui.control.Label
        MaxResolutionEditField          matlab.ui.control.NumericEditField
        MaxResolutionEditFieldLabel     matlab.ui.control.Label
        SimulationTab                   matlab.ui.container.Tab
        StiffnessEvaluationPanel        matlab.ui.container.Panel
        EvaluationTasksDropDown         matlab.ui.control.DropDown
        EvaluationTasksDropDownLabel    matlab.ui.control.Label
        StressAnalysisButton            matlab.ui.control.Button
        FEAwithSolidDesignDomainButton  matlab.ui.control.Button
        SolidComplianceEditField        matlab.ui.control.NumericEditField
        SolidComplianceEditFieldLabel   matlab.ui.control.Label
        MaterialLayoutDesignPanel       matlab.ui.container.Panel
        SimulationTasksDropDown         matlab.ui.control.DropDown
        SimulationTasksDropDownLabel    matlab.ui.control.Label
        LinearSystemSolverPanel         matlab.ui.container.Panel
        MEXFuncCheckBox                 matlab.ui.control.CheckBox
        NonDyadicCheckBox               matlab.ui.control.CheckBox
        MaximumElementsontheCoarsestLevelEditField  matlab.ui.control.NumericEditField
        MaximumElementsontheCoarsestLevelEditFieldLabel  matlab.ui.control.Label
        WeightingFactorofJacobiSmoothingProcessEditField  matlab.ui.control.NumericEditField
        WeightingFactorofJacobiSmoothingProcessEditFieldLabel  matlab.ui.control.Label
        MaximumIterationsEditField      matlab.ui.control.NumericEditField
        MaximumIterationsEditFieldLabel  matlab.ui.control.Label
        ResidualThresholdEditField      matlab.ui.control.NumericEditField
        ResidualThresholdEditFieldLabel  matlab.ui.control.Label
        MaterialPropertiesPanel         matlab.ui.container.Panel
        YoungsModulusCompliantmaterialEditField  matlab.ui.control.NumericEditField
        YoungsModulusCompliantmaterialEditFieldLabel  matlab.ui.control.Label
        PoissonsRatioEditField          matlab.ui.control.NumericEditField
        PoissonsRatioEditFieldLabel     matlab.ui.control.Label
        YoungsModulusStiffmaterialEditField  matlab.ui.control.NumericEditField
        YoungsModulusStiffmaterialEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        %%app_LinearSystemSolver_settings % Reference to LSS settings
        app_ObjectiSelectionWindow_settings % Reference to Object Selection
        %%app_Optimizer_settings % Reference to Optimizer settings
        %%app_MaterialProperties_settings % Reference to Material Property settings
        comp_SimTask_EvaluateExternalVoxelBasedDesign
        comp_SimTask_TopologyOptimization_Func
        comp_SimTask_PSLsGuidedStructDesign_Func
        comp_SimTask_MeshGraphBasedStructDesign_Func
        comp_SimTask_StressAwareGeoSyn3rdParty_AroraMethod
        comp_SimTask_StressAwareGeoSyn3rdParty_GaoMethod
        comp_SimTask_StressAwareGeoSyn3rdParty_VoronoiMethod
        app_Mdl_CreateCuboidDesignDomain
        app_Mdl_CreateLshapeDesignDomain
        app_Mdl_CreateSphereShellDesignDomain
        app_Mdl_CreateCylinderDesignDomain
    end
    
    methods (Access = public)
        
        function ShowVoxelizedModel_Public(app)
            InputVoxelsMenuSelected(app);
        end

        function ShowProblemDescription_Public(app)
            ShowProblemDescriptionMenuSelected(app)
        end

        function ShowTriSurfaceMesh_public(app)
            InputTriSurfaceMeshMenuSelected(app);
        end

        function ShowDeformation_Public(app)
            TotalMenu_2Selected(app);
        end

        function ShowStressComponents_Public(app)
            VonMisesMenuSelected(app);
        end

        function ShowPSLs_Public(app)
            PSLsMenuSelected(app);
        end

        function ShowDesignDomain_Public(app)
            ShowDesignDomainMenuSelected(app);
        end
        
        function ShowDesignDensityLayout_Public(app)
            ShowDesignbyDensityFieldMenuSelected(app)
        end

        function FEA_Public(app)
            FEAwithSolidDesignDomainButtonPushed(app)
        end

        function StressAnalysis_Public(app)
            StressAnalysisButtonPushed(app)
        end
        
        function ShowEdgeVertexGraph_Public(app)
            InputEdgeVertexGraphMenuSelected(app)
        end

        function GatherLSSandMPsettings(app)
            global tol_;
            global maxIT_;
            global weightFactorJacobi_;
            global coarsestResolutionControl_;
            global nonDyadic_;
            global MEXfunc_;
            global modulus_;
            global poissonRatio_;
            global modulusMin_;
            global cellSize_;

            tol_ = app.ResidualThresholdEditField.Value;
            maxIT_ = app.MaximumIterationsEditField.Value;
            weightFactorJacobi_ = app.WeightingFactorofJacobiSmoothingProcessEditField.Value;
            coarsestResolutionControl_ = app.MaximumElementsontheCoarsestLevelEditField.Value;
            nonDyadic_ = app.NonDyadicCheckBox.Value;
            MEXfunc_ = app.MEXFuncCheckBox.Value;
            modulus_ = app.YoungsModulusStiffmaterialEditField.Value;
            poissonRatio_ = app.PoissonsRatioEditField.Value;            
            modulusMin_ = app.YoungsModulusCompliantmaterialEditField.Value;
            cellSize_ = app.CellSizeEditField.Value;            
        end

        function InitializeAppParameters(app)
            global finestResolutionControl_;
            global cellSize_;
            global modulus_;
            global poissonRatio_;
            global modulusMin_;
            global tol_;
            global maxIT_;
            global weightFactorJacobi_;
            global coarsestResolutionControl_;
            
            app.MaxResolutionEditField.Value = finestResolutionControl_;
            app.CellSizeEditField.Value = cellSize_;
            app.ElementsEditField.Value = 0;
            app.DOFsEditField.Value = 0;
            app.FxNEditField.Value = 0;
            app.FyNEditField.Value = 0;
            app.FzNEditField.Value = 0;
            app.BuiltinBCsforCuboidDesignDomainDropDown.Value = 'None';
            app.XDirFixedCheckBox.Value = 1;
            app.YDirFixedCheckBox.Value = 1;
            app.ZDirFixedCheckBox.Value = 1;
            app.YoungsModulusCompliantmaterialEditField.Value = modulusMin_;
            app.YoungsModulusStiffmaterialEditField.Value = modulus_;
            app.PoissonsRatioEditField.Value = poissonRatio_;
            app.ResidualThresholdEditField.Value = tol_;
            app.MaximumIterationsEditField.Value = maxIT_;
            app.WeightingFactorofJacobiSmoothingProcessEditField.Value = weightFactorJacobi_;
            app.MaximumElementsontheCoarsestLevelEditField.Value = coarsestResolutionControl_;
            app.SolidComplianceEditField.Value = 0;
        end

        function InitializeMainAppInterface(app)
            %%Prevent Accidental Touches            
            app.ExportDesigninTriangularMeshMenu.Enable = 'off';
            app.ExportVoxelModelTopVoxelMenu.Enable = 'off';
            app.ShowProblemDescriptionMenu.Enable = 'off';
            app.ShowStressScalarComponentsMenu.Enable = 'off';
            app.OptimizationHistoryMenu.Enable = 'off';
            app.FormattedDisplayMenu.Enable = 'off';
            app.InputTetHexSolidMeshMenu.Enable = 'off';
            app.InputEdgeVertexGraphMenu.Enable = 'off';
            app.InputTriSurfaceMeshMenu.Enable = 'off';
            app.InputVoxelsMenu.Enable = 'off';
            app.VoxelizingButton.Enable = 'off';
            app.AdditionalNodeSelectionOptionsButton.Enable = 'off';
            app.ApplyforLoadsButton.Enable = 'off';
            app.FxNEditField.Enable = 'off';
            app.FyNEditField.Enable = 'off';
            app.FzNEditField.Enable = 'off';
            app.ClearLoadsButton.Enable = 'off';
            app.ApplyforFixationButton.Enable = 'off';
            app.XDirFixedCheckBox.Enable = 'off';
            app.YDirFixedCheckBox.Enable = 'off';
            app.ZDirFixedCheckBox.Enable = 'off';            
            app.ClearFixationButton.Enable = 'off';
            app.BuiltinBCsforCuboidDesignDomainDropDown.Enable = 'off';
            app.ShowDesignDomainMenu.Enable = 'off';
            app.ShowDeformationMenu.Enable = 'off';
            app.ShowDesignbyDensityFieldMenu.Enable = 'off';
            % app.VisInputMeshGraphVoxelsMenu.Enable = 'off';            
            % app.FEAwithSolidDesignDomainButton.Enable = 'off';
            % app.StressAnalysisButton.Enable = 'off';
            % app.SimulationTasksDropDown.Enable = 'off';
            % app.EvaluationTasksDropDown.Enable = 'off';
            % app.EnableSelectionBoxCheckBox.Enable = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            clc;
            global axHandle_;            
            addpath('./GUIs/');
            addpath('./src/');
            addpath('./src/MEXfuncs/');
            % addpath('./src/external/Gao2017/');
            if ~exist('./out/', 'dir'), mkdir('./out/'); end
            % addpath('D:\MyProjects\TopOpti3D\VolumetricTruss-master\');
            % addpath('D:\MyProjects\TopOpti3D\VolumetricTruss-master\gptoolbox-master\mesh');
            % addpath('D:\MyProjects\TopOpti3D\VolumetricTruss-master\gptoolbox-master\matrix');
            %%Initialization
            Data_GlobalVariables;
            InitializeMainAppInterface(app);
            InitializeAppParameters(app);
            close all;
            figure; axHandle_ = gca; view(axHandle_,3);
            cla(axHandle_);
        end

        % Callback function
        function LinearSystemSolverMenuSelected(app, event)
            app.app_LinearSystemSolver_settings = Setting_LinearSystemSolver(app);
            app.LinearSystemSolverMenu.Enable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.app_ObjectiSelectionWindow_settings)
            %%delete(app.app_LinearSystemSolver_settings)
            %%delete(app.app_Optimizer_settings)
            %%delete(app.app_MaterialProperties_settings)
            delete(app.comp_SimTask_EvaluateExternalVoxelBasedDesign)
            delete(app.comp_SimTask_TopologyOptimization_Func)
            delete(app.comp_SimTask_PSLsGuidedStructDesign_Func)
            delete(app.comp_SimTask_MeshGraphBasedStructDesign_Func)
            delete(app.comp_SimTask_StressAwareGeoSyn3rdParty_AroraMethod)
            delete(app.comp_SimTask_StressAwareGeoSyn3rdParty_GaoMethod)
            delete(app.comp_SimTask_StressAwareGeoSyn3rdParty_VoronoiMethod)
            delete(app.app_Mdl_CreateCuboidDesignDomain)
            delete(app.app_Mdl_CreateLshapeDesignDomain)
            delete(app.app_Mdl_CreateSphereShellDesignDomain)
            delete(app.app_Mdl_CreateCylinderDesignDomain)
            delete(app)
            
        end

        % Menu selected function: SolidHexTetMeshmeshmshvtkMenu
        function SolidHexTetMeshmeshmshvtkMenuSelected(app, event)
            global axHandle_;
            %%Reset App
            Data_GlobalVariables;
            InitializeMainAppInterface(app);
            InitializeAppParameters(app);            
            [fileName, dataPath] = uigetfile({'*.mesh'; '*.msh'; '*.vtk'}, 'Select a Solid Mesh File to Open');
            if isnumeric(fileName) || isnumeric(dataPath), return; end
            [~,~,fileExtension] = fileparts(fileName);
            if ~(strcmp(fileExtension, '.vtk') || strcmp(fileExtension, '.mesh') || strcmp(fileExtension, '.msh'))
                warning('Un-supported Mesh Format!');
                return;
            end
            inputSolidMeshfileName = strcat(dataPath,fileName);
            IO_ImportSolidMesh(inputSolidMeshfileName);
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end             
            InputTetHexSolidMeshMenuSelected(app, event);
            app.InputTetHexSolidMeshMenu.Enable = 'on';
            app.InputTriSurfaceMeshMenu.Enable = 'on';
            % app.BuiltinShapesMenu.Enable = 'off';
            app.MaxResolutionEditField.Enable = 'on';
            app.VoxelizingButton.Enable = 'on';
        end

        % Menu selected function: InputTriSurfaceMeshMenu
        function InputTriSurfaceMeshMenuSelected(app, event)
            global axHandle_;
            global surfaceTriMesh_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_); colorbar(axHandle_, 'off'); 
            Vis_DrawMesh3D(axHandle_, surfaceTriMesh_.nodeCoords, surfaceTriMesh_.eNodMat, 1);
            view(axHandle_, az, el);
        end

        % Button pushed function: VoxelizingButton
        function VoxelizingButtonPushed(app, event)
            global meshHierarchy_;
            global nelx_; global nely_; global nelz_;
            global boundingBox_;
            tStart = tic;
            FEA_CreateVoxelizedModel(app.MaxResolutionEditField.Value);
            disp(['Voxelizing Domain Costs: ' sprintf('%10.3g',toc(tStart)) 's']);
            tStart = tic;
            FEA_VoxelBasedDiscretization();
            disp(['Setup Voxel-based FEA Model: ' sprintf('%10.3g',toc(tStart)) 's']);
            InputVoxelsMenuSelected(app, event);
            
            app.ElementsEditField.Value = meshHierarchy_(1).numElements;
            app.DOFsEditField.Value = meshHierarchy_(1).numDOFs;

            app.AdditionalNodeSelectionOptionsButton.Enable = 'on';
            app.InputVoxelsMenu.Enable = 'on';
            app.FxNEditField.Enable = 'on';
            app.FyNEditField.Enable = 'on';
            app.FzNEditField.Enable = 'on';
            app.XDirFixedCheckBox.Enable = 'on';
            app.YDirFixedCheckBox.Enable = 'on';
            app.ZDirFixedCheckBox.Enable = 'on';
            app.ApplyforLoadsButton.Enable = 'on';
            app.ClearLoadsButton.Enable = 'on';
            app.ApplyforFixationButton.Enable = 'on';
            app.ClearFixationButton.Enable = 'on';
            app.ShowProblemDescriptionMenu.Enable = 'on';
            app.ShowDesignDomainMenu.Enable = 'on';
            app.ExportVoxelModelTopVoxelMenu.Enable = 'on';
            app.EnableSelectionBoxCheckBox.Enable = 'on';
            if nelx_*nely_*nelz_ == meshHierarchy_(1).numElements
                app.BuiltinBCsforCuboidDesignDomainDropDown.Enable = 'on';
            end
            app.CornerPot1XEditField.Value = boundingBox_(1,1); 
            app.CornerPot1YEditField.Value = boundingBox_(1,2); 
            app.CornerPot1ZEditField.Value = boundingBox_(1,3);
            app.CornerPot2XEditField.Value = boundingBox_(2,1); 
            app.CornerPot2YEditField.Value = boundingBox_(2,2); 
            app.CornerPot2ZEditField.Value = boundingBox_(2,3);            
        end

        % Menu selected function: InputVoxelsMenu
        function InputVoxelsMenuSelected(app, event)
            global axHandle_;
            global meshHierarchy_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_); colorbar(axHandle_, 'off');
            Vis_DrawMesh3D(axHandle_, meshHierarchy_(1).boundaryNodeCoords, meshHierarchy_(1).boundaryEleFaces, 1);
            view(axHandle_, az, el);
        end

        % Button pushed function: AdditionalNodeSelectionOptionsButton
        function AdditionalNodeSelectionOptionsButtonPushed(app, event)
            app.app_ObjectiSelectionWindow_settings = ObjectSelection(app);
            app.AdditionalNodeSelectionOptionsButton.Enable = 'off';
        end

        % Menu selected function: InputTetHexSolidMeshMenu
        function InputTetHexSolidMeshMenuSelected(app, event)
            global axHandle_;
            global inputSolidMesh_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_); colorbar(axHandle_, 'off');
            Vis_DrawMesh3D(axHandle_, inputSolidMesh_.boundaryNodeCoords, inputSolidMesh_.boundaryPatchNodMat, 1);
            view(axHandle_, az, el);            
        end

        % Callback function
        function MaterialPropertiesMenuSelected(app, event)
            app.app_MaterialProperties_settings = Setting_MaterialProperties(app);
            app.MaterialPropertiesMenu.Enable = 'off';               
        end

        % Button pushed function: ApplyforLoadsButton
        function ApplyforLoadsButtonPushed(app, event)
            global axHandle_;
            global loadingCond_;
            global fixingCond_;             
            if 0==app.FxNEditField.Value && 0==app.FyNEditField.Value && 0==app.FzNEditField.Value, return; end
            forceVec = [app.FxNEditField.Value app.FyNEditField.Value app.FzNEditField.Value];
            iLoadingVec2Draw = FEA_Apply4Loads(forceVec);
            if isempty(iLoadingVec2Draw), return; end
            Interaction_ClearPickedNodes();
            Vis_ShowLoadingCondition(axHandle_, iLoadingVec2Draw);
            if ~isempty(loadingCond_) && ~isempty(fixingCond_)
                app.FEAwithSolidDesignDomainButton.Enable = 'on';
                app.SimulationTasksDropDown.Enable = 'on';
                app.EvaluationTasksDropDown.Enable = 'on';
            end
        end

        % Button pushed function: ClearLoadsButton
        function ClearLoadsButtonPushed(app, event)
            global loadingCond_;
            loadingCond_ = [];
            ShowProblemDescriptionMenuSelected(app, event);
        end

        % Button pushed function: ApplyforFixationButton
        function ApplyforFixationButtonPushed(app, event)
            global axHandle_;
            global loadingCond_;
            global fixingCond_;           
            if ~(app.XDirFixedCheckBox.Value || app.YDirFixedCheckBox.Value || app.ZDirFixedCheckBox.Value), return; end
            fixingOpt = zeros(1,3);
            if app.XDirFixedCheckBox.Value, fixingOpt(1) = 1; end
            if app.YDirFixedCheckBox.Value, fixingOpt(2) = 1; end
            if app.ZDirFixedCheckBox.Value, fixingOpt(3) = 1; end
            iFixingArr2Draw = FEA_Apply4Fixations(fixingOpt);
            if isempty(iFixingArr2Draw), return; end
            Interaction_ClearPickedNodes();
            Vis_ShowFixingCondition(axHandle_, iFixingArr2Draw);
            if ~isempty(loadingCond_) && ~isempty(fixingCond_)
                app.FEAwithSolidDesignDomainButton.Enable = 'on';
                app.SimulationTasksDropDown.Enable = 'on';
                app.EvaluationTasksDropDown.Enable = 'on';
            end            
        end

        % Menu selected function: ShowProblemDescriptionMenu
        function ShowProblemDescriptionMenuSelected(app, event)
            global axHandle_;
            global meshHierarchy_;
            global loadingCond_;
            global fixingCond_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_); colorbar(axHandle_, 'off');
            Vis_DrawMesh3D(axHandle_, meshHierarchy_(1).boundaryNodeCoords, meshHierarchy_(1).boundaryEleFaces, 1);
            Vis_ShowLoadingCondition(axHandle_, loadingCond_);
            Vis_ShowFixingCondition(axHandle_, fixingCond_);
            view(axHandle_, az, el);
            axis(axHandle_, 'on'); xlabel('X'); ylabel('Y'); zlabel('Z');            
        end

        % Button pushed function: ClearFixationButton
        function ClearFixationButtonPushed(app, event)
            global fixingCond_;
            fixingCond_ = [];
            ShowProblemDescriptionMenuSelected(app, event);            
        end

        % Value changed function: BuiltinBCsforCuboidDesignDomainDropDown
        function BuiltinBCsforCuboidDesignDomainDropDownValueChanged(app, event)
            value = app.BuiltinBCsforCuboidDesignDomainDropDown.Value;
            FEA_BuiltInBoundaryConditions4CuboidDesignDomain(value);
            ShowProblemDescriptionMenuSelected(app, event);
            app.SimulationTasksDropDown.Enable = 'on';
            app.EvaluationTasksDropDown.Enable = 'on';
            app.FEAwithSolidDesignDomainButton.Enable = 'on';
        end

        % Value changed function: SimulationTasksDropDown
        function SimulationTasksDropDownValueChanged(app, event)
            value = app.SimulationTasksDropDown.Value;
            switch value
                case 'None'
                    return;
                case 'Topology Optimization'
                    app.comp_SimTask_TopologyOptimization_Func = SimTask_TopologyOptimization(app);
                    app.SimulationTasksDropDown.Enable = "off";
                    app.EvaluationTasksDropDown.Enable = 'off';
                    app.FEAwithSolidDesignDomainButton.Enable = 'off';
                case 'PSLs-guided Infill Design'
                    app.comp_SimTask_PSLsGuidedStructDesign_Func = SimTask_PSLsGuidedStructDesign(app);
                    app.SimulationTasksDropDown.Enable = "off";
                    app.EvaluationTasksDropDown.Enable = 'off';
                    app.FEAwithSolidDesignDomainButton.Enable = 'off';
                case 'Stress-aligned Volumetric Michell Trusses Infill Design'
                    app.comp_SimTask_StressAwareGeoSyn3rdParty_AroraMethod = SimTask_StressAwareGeoSyn_3rdParty_AroraMethod(app);                   
                    app.SimulationTasksDropDown.Enable = "off";
                    app.EvaluationTasksDropDown.Enable = 'off';
                    app.FEAwithSolidDesignDomainButton.Enable = 'off';  
                case 'Stress-aligned Conforming Lattice Infill Design'
                    app.comp_SimTask_StressAwareGeoSyn3rdParty_GaoMethod = SimTask_StressAwareGeoSyn_3rdParty_GaoMethod(app);                   
                    app.SimulationTasksDropDown.Enable = "off";
                    app.EvaluationTasksDropDown.Enable = 'off';
                    app.FEAwithSolidDesignDomainButton.Enable = 'off';
                case 'Stress-aware Graded Voronoi Diagram Infill Design'
                    app.comp_SimTask_StressAwareGeoSyn3rdParty_VoronoiMethod = SimTask_StressAwareGeoSyn_3rdParty_GradedVoronoi(app);                   
                    app.SimulationTasksDropDown.Enable = "off";
                    app.EvaluationTasksDropDown.Enable = 'off';
                    app.FEAwithSolidDesignDomainButton.Enable = 'off';                    
            end
        end

        % Menu selected function: SurfaceTriMeshplyobjMenu
        function SurfaceTriMeshplyobjMenuSelected(app, event)
            global axHandle_;
            %%Reset App
            Data_GlobalVariables;
            InitializeMainAppInterface(app);
            InitializeAppParameters(app);              
            [fileName, dataPath] = uigetfile({'*.ply'; '*.obj'}, 'Select a Surface Mesh File to Open');
            if isnumeric(fileName) || isnumeric(dataPath), return; end
            [~,~,fileExtension] = fileparts(fileName);
            if ~(strcmp(fileExtension, '.ply') || strcmp(fileExtension, '.obj'))
                warning('Un-supported Mesh Format!');
                return;
            end
            inputSurfaceMeshfileName = strcat(dataPath,fileName);
            IO_ImportSurfaceMesh(inputSurfaceMeshfileName);
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            InputTriSurfaceMeshMenuSelected(app, event);
            app.InputTriSurfaceMeshMenu.Enable = 'on';
            % app.BuiltinShapesMenu.Enable = 'off';
            app.VoxelizingButton.Enable = 'on';
            app.MaxResolutionEditField.Enable = 'on';
        end

        % Menu selected function: EdgeVertexGraphobjMenu
        function EdgeVertexGraphobjMenuSelected(app, event)
            global axHandle_;
            [fileName, dataPath] = uigetfile('*.obj', 'Select a Vertex-Edge Graph File to Open');
            if isnumeric(fileName) || isnumeric(dataPath), return; end
            [~,~,fileExtension] = fileparts(fileName);
            if ~strcmp(fileExtension, '.obj')
                warning('Un-supported Graph Format!');
                return;
            end
            inputVertexEdgeGraphfileName = strcat(dataPath,fileName);
            IO_ImportVertexEdgeGraph(inputVertexEdgeGraphfileName);
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            InputEdgeVertexGraphMenuSelected(app, event);
            % app.InputTetHexSolidMeshMenu.Enable = 'on';
            % app.InputTriSurfaceMeshMenu.Enable = 'on';
            % app.BuiltinShapesMenu.Enable = 'off';
            % app.VoxelizingButton.Enable = 'on';  
        end

        % Menu selected function: InputEdgeVertexGraphMenu
        function InputEdgeVertexGraphMenuSelected(app, event)
            global axHandle_;
            global vertexEdgeGraph_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_); colorbar(axHandle_, 'off');
            Vis_DrawGraph3D(axHandle_, vertexEdgeGraph_.nodeCoords, vertexEdgeGraph_.eNodMat);
            view(axHandle_, az, el);
            Vis_UserLighting(axHandle_);
        end

        % Menu selected function: CuboidMenu
        function CuboidMenuSelected(app, event)
            global axHandle_;
            %%Reset App
            Data_GlobalVariables;
            InitializeMainAppInterface(app);
            InitializeAppParameters(app);              
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            app.app_Mdl_CreateCuboidDesignDomain = Mdl_CreateCuboidDesignDomain(app);
            app.MaxResolutionEditField.Enable = 'on';
        end

        % Menu selected function: LshapeMenu
        function LshapeMenuSelected(app, event)
            global axHandle_;
            %%Reset App
            Data_GlobalVariables;
            InitializeMainAppInterface(app);
            InitializeAppParameters(app);              
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            app.app_Mdl_CreateLshapeDesignDomain = Mdl_CreateLshapeDesignDomain(app);
            app.MaxResolutionEditField.Enable = 'on';
        end

        % Menu selected function: SphereMenu
        function SphereMenuSelected(app, event)
            global axHandle_;
            %%Reset App
            Data_GlobalVariables;
            InitializeMainAppInterface(app);
            InitializeAppParameters(app);              
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            app.app_Mdl_CreateSphereShellDesignDomain = Mdl_CreateSphereDesignDomain(app);
            app.MaxResolutionEditField.Enable = 'on';
        end

        % Menu selected function: CylinderMenu
        function CylinderMenuSelected(app, event)
            global axHandle_;
            %%Reset App
            Data_GlobalVariables;
            InitializeMainAppInterface(app);
            InitializeAppParameters(app);             
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            app.app_Mdl_CreateCylinderDesignDomain = Mdl_CreateCylinderDesignDomain(app);
            app.MaxResolutionEditField.Enable = 'on';
        end

        % Menu selected function: UxMenu
        function UxMenuSelected(app, event)
            global axHandle_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_);            
            Vis_ShowDeformation(axHandle_, 'Ux');
            view(axHandle_, az, el);            
        end

        % Menu selected function: Sigma_xxMenu
        function Sigma_xxMenuSelected(app, event)
            global axHandle_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_);            
            Vis_ShowScalarStressComponents(axHandle_, 'Sigma_xx', 0);
            view(axHandle_, az, el);              
        end

        % Menu selected function: UyMenu
        function UyMenuSelected(app, event)
            global axHandle_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_);            
            Vis_ShowDeformation(axHandle_, 'Uy');
            view(axHandle_, az, el);             
        end

        % Menu selected function: UzMenu
        function UzMenuSelected(app, event)
            global axHandle_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_);            
            Vis_ShowDeformation(axHandle_, 'Uz');
            view(axHandle_, az, el);              
        end

        % Menu selected function: TotalMenu_2
        function TotalMenu_2Selected(app, event)
            global axHandle_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_);            
            Vis_ShowDeformation(axHandle_, 'Total');
            view(axHandle_, az, el);             
        end

        % Menu selected function: Sigma_yyMenu
        function Sigma_yyMenuSelected(app, event)
            global axHandle_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_);            
            Vis_ShowScalarStressComponents(axHandle_, 'Sigma_yy', 0);
            view(axHandle_, az, el);             
        end

        % Menu selected function: Sigma_zzMenu
        function Sigma_zzMenuSelected(app, event)
            global axHandle_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_);            
            Vis_ShowScalarStressComponents(axHandle_, 'Sigma_zz', 0);
            view(axHandle_, az, el);             
        end

        % Menu selected function: Tau_yzMenu
        function Tau_yzMenuSelected(app, event)
            global axHandle_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_);            
            Vis_ShowScalarStressComponents(axHandle_, 'Tau_yz', 0);
            view(axHandle_, az, el);            
        end

        % Menu selected function: Tau_zxMenu
        function Tau_zxMenuSelected(app, event)
            global axHandle_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_);            
            Vis_ShowScalarStressComponents(axHandle_, 'Tau_zx', 0);
            view(axHandle_, az, el);            
        end

        % Menu selected function: Tau_xyMenu
        function Tau_xyMenuSelected(app, event)
            global axHandle_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_);            
            Vis_ShowScalarStressComponents(axHandle_, 'Tau_xy', 0);
            view(axHandle_, az, el);            
        end

        % Menu selected function: VonMisesMenu
        function VonMisesMenuSelected(app, event)
            global axHandle_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_);            
            Vis_ShowScalarStressComponents(axHandle_, 'Von Mises', 0);
            view(axHandle_, az, el);            
        end

        % Menu selected function: PSLsMenu
        function PSLsMenuSelected(app, event)
            global axHandle_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_); colorbar(axHandle_, 'off');           
            Vis_ShowPSLs(axHandle_);
            view(axHandle_, az, el);
            Vis_UserLighting(axHandle_);
        end

        % Button pushed function: FEAwithSolidDesignDomainButton
        function FEAwithSolidDesignDomainButtonPushed(app, event)
            global complianceSolid_;
            GatherLSSandMPsettings(app);
            app.SimulationTasksDropDown.Enable = 'off';
            app.EvaluationTasksDropDown.Enable = 'off';
            app.StressAnalysisButton.Enable = 'off'; 
            app.FEAwithSolidDesignDomainButton.Enable = 'off'; 
            pause(1);
            [complianceSolid_, ~] = FEA_ComputeComplianceVoxel();
            app.SimulationTasksDropDown.Enable = 'on';
            app.EvaluationTasksDropDown.Enable = 'on';
            app.FEAwithSolidDesignDomainButton.Enable = 'on';
            app.ShowDeformationMenu.Enable = 'on';
            app.StressAnalysisButton.Enable = 'on';
            app.SolidComplianceEditField.Value = complianceSolid_;

            TotalMenu_2Selected(app, event);
        end

        % Button pushed function: StressAnalysisButton
        function StressAnalysisButtonPushed(app, event)
            global outPath_;
            global cartesianStressField_;
	        global vonMisesStressField_;
            app.SimulationTasksDropDown.Enable = 'off';
            app.EvaluationTasksDropDown.Enable = 'off';
            app.StressAnalysisButton.Enable = 'off'; 
            app.FEAwithSolidDesignDomainButton.Enable = 'off';
            pause(1);
            [cartesianStressField_, vonMisesStressField_] = FEA_StressAnalysis();
            app.FEAwithSolidDesignDomainButton.Enable = 'on';
            app.SimulationTasksDropDown.Enable = 'on';
            app.EvaluationTasksDropDown.Enable = 'on';
            app.StressAnalysisButton.Enable = 'on';
            app.ShowStressScalarComponentsMenu.Enable = 'on';
            VonMisesMenuSelected(app);

            disp('Stress Analysis on Solid Domain ...');
            tStressAnalysis = tic;
            dominantDirSolid = Common_ExtractDominantDirectionsFromPrincipalStressDirections();
            niftiwrite(dominantDirSolid, strcat(outPath_, 'dominantDirSolid.nii'));
            disp(['Done with Stress Analysis after ', sprintf('%.f', toc(tStressAnalysis)), 's']);
        end

        % Menu selected function: ShowDesignbyDensityFieldMenu
        function ShowDesignbyDensityFieldMenuSelected(app, event)
            global axHandle_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_); colorbar(axHandle_, 'off');           
            Vis_ShowDesignByDensityLayoutInIsosurface(axHandle_);
            view(axHandle_, az, el);
            Vis_UserLighting(axHandle_);              
        end

        % Menu selected function: ShowDesignDomainMenu
        function ShowDesignDomainMenuSelected(app, event)
            global axHandle_;
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            [az, el] = view(axHandle_);
            cla(axHandle_); colorbar(axHandle_, 'off');           
            Vis_ShowDesignDomain(axHandle_)
            view(axHandle_, az, el);
            Vis_UserLighting(axHandle_);            
        end

        % Menu selected function: ExportVoxelModelTopVoxelMenu
        function ExportVoxelModelTopVoxelMenuSelected(app, event)
            [fileName, dataPath] = uiputfile('*.TopVoxel', 'Select a Path to Write');
            if isnumeric(fileName) || isnumeric(dataPath), return; end
            ofileName = strcat(dataPath,fileName);
            IO_ExportTopVoxels(ofileName);
        end

        % Menu selected function: ImportVoxelModelTopVoxelMenu
        function ImportVoxelModelTopVoxelMenuSelected(app, event)
            global axHandle_;
            global meshHierarchy_;
            global nelx_; global nely_; global nelz_;
            global loadingCond_;
            global fixingCond_;
            %%Reset App
            Data_GlobalVariables;
            InitializeMainAppInterface(app);
            InitializeAppParameters(app);              
            [fileName, dataPath] = uigetfile('*.TopVoxel', 'Select a Voxel File to Open');
            if isnumeric(fileName) || isnumeric(dataPath), return; end
            [~,~,fileExtension] = fileparts(fileName);
            if ~strcmp(fileExtension, '.TopVoxel')
                warning('Un-supported Mesh Format!');
                return;
            end
            inputVoxelfileName = strcat(dataPath,fileName);
            IO_ImportTopVoxels(inputVoxelfileName);
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            ShowProblemDescriptionMenuSelected(app, event)
            app.VoxelizingButton.Enable = 'off';
            app.ElementsEditField.Value = meshHierarchy_(1).numElements;
            app.DOFsEditField.Value = meshHierarchy_(1).numDOFs;
            app.MaxResolutionEditField.Value = max([meshHierarchy_(1).resX meshHierarchy_(1).resY meshHierarchy_(1).resZ]);
            app.MaxResolutionEditField.Enable = 'off';

            app.AdditionalNodeSelectionOptionsButton.Enable = 'on';
            app.InputVoxelsMenu.Enable = 'on';
            app.FxNEditField.Enable = 'on';
            app.FyNEditField.Enable = 'on';
            app.FzNEditField.Enable = 'on';
            app.XDirFixedCheckBox.Enable = 'on';
            app.YDirFixedCheckBox.Enable = 'on';
            app.ZDirFixedCheckBox.Enable = 'on';
            app.ApplyforLoadsButton.Enable = 'on';
            app.ClearLoadsButton.Enable = 'on';
            app.ApplyforFixationButton.Enable = 'on';
            app.ClearFixationButton.Enable = 'on';
            app.ShowProblemDescriptionMenu.Enable = 'on';
            app.ShowDesignDomainMenu.Enable = 'on';
            app.ExportVoxelModelTopVoxelMenu.Enable = 'on';
            app.EnableSelectionBoxCheckBox.Enable = 'on';
            if nelx_*nely_*nelz_ == meshHierarchy_(1).numElements
                app.BuiltinBCsforCuboidDesignDomainDropDown.Enable = 'on';
            end
            app.CornerPot1XEditField.Value = boundingBox_(1,1); 
            app.CornerPot1YEditField.Value = boundingBox_(1,2); 
            app.CornerPot1ZEditField.Value = boundingBox_(1,3);
            app.CornerPot2XEditField.Value = boundingBox_(2,1); 
            app.CornerPot2YEditField.Value = boundingBox_(2,2); 
            app.CornerPot2ZEditField.Value = boundingBox_(2,3);  
            if ~isempty(loadingCond_) && ~isempty(fixingCond_)
                app.SimulationTasksDropDown.Enable = 'on';
                app.EvaluationTasksDropDown.Enable = 'on';
                app.FEAwithSolidDesignDomainButton.Enable = 'on';
            end            
        end

        % Menu selected function: TempImportWrappedVoxelModeltxtMenu
        function TempImportWrappedVoxelModeltxtMenuSelected(app, event)
            global axHandle_;
            global meshHierarchy_;
            global nelx_; global nely_; global nelz_;
            global loadingCond_;
            global fixingCond_;
            %%Reset App
            Data_GlobalVariables;
            InitializeMainAppInterface(app);
            InitializeAppParameters(app);              
            [fileName, dataPath] = uigetfile('*.txt', 'Select a Voxel File to Open');
            if isnumeric(fileName) || isnumeric(dataPath), return; end
            [~,~,fileExtension] = fileparts(fileName);
            if ~strcmp(fileExtension, '.txt')
                warning('Un-supported Mesh Format!');
                return;
            end
            inputVoxelfileName = strcat(dataPath,fileName);
            Temp_CreateFromWrappedVoxelFEAModel(inputVoxelfileName);
            if ~isvalid(axHandle_), axHandle_ = gca; view(axHandle_,3); end
            ShowProblemDescriptionMenuSelected(app, event)
            app.VoxelizingButton.Enable = 'off';
            app.ElementsEditField.Value = meshHierarchy_(1).numElements;
            app.DOFsEditField.Value = meshHierarchy_(1).numDOFs;
            app.MaxResolutionEditField.Value = max([meshHierarchy_(1).resX meshHierarchy_(1).resY meshHierarchy_(1).resZ]);
            app.MaxResolutionEditField.Enable = 'off';

            app.AdditionalNodeSelectionOptionsButton.Enable = 'on';
            app.InputVoxelsMenu.Enable = 'on';
            app.FxNEditField.Enable = 'on';
            app.FyNEditField.Enable = 'on';
            app.FzNEditField.Enable = 'on';
            app.XDirFixedCheckBox.Enable = 'on';
            app.YDirFixedCheckBox.Enable = 'on';
            app.ZDirFixedCheckBox.Enable = 'on';
            app.ApplyforLoadsButton.Enable = 'on';
            app.ClearLoadsButton.Enable = 'on';
            app.ApplyforFixationButton.Enable = 'on';
            app.ClearFixationButton.Enable = 'on';
            app.ShowProblemDescriptionMenu.Enable = 'on';
            app.ShowDesignDomainMenu.Enable = 'on';
            app.ExportVoxelModelTopVoxelMenu.Enable = 'on';
            if nelx_*nely_*nelz_ == meshHierarchy_(1).numElements
                app.BuiltinBCsforCuboidDesignDomainDropDown.Enable = 'on';
            end
            if ~isempty(loadingCond_) && ~isempty(fixingCond_)
                app.SimulationTasksDropDown.Enable = 'on';
                app.EvaluationTasksDropDown.Enable = 'on';
                app.FEAwithSolidDesignDomainButton.Enable = 'on';
            end            
        end

        % Menu selected function: TempExportWrappedVoxelModeltxtMenu
        function TempExportWrappedVoxelModeltxtMenuSelected(app, event)
            [fileName, dataPath] = uiputfile('*.txt', 'Select a Path to Write');
            if isnumeric(fileName) || isnumeric(dataPath), return; end
            ofileName = strcat(dataPath,fileName);
            Temp_WrapVoxelFEAmodel(ofileName);            
        end

        % Menu selected function: ExportDesigninVolumeniiMenu
        function ExportDesigninVolumeniiMenuSelected(app, event)
            [fileName, dataPath] = uiputfile('*.nii', 'Select a Path to Write');
            if isnumeric(fileName) || isnumeric(dataPath), return; end
            ofileName = strcat(dataPath,fileName);
            IO_ExportDesignInVolume_nii(ofileName);               
        end

        % Value changed function: EvaluationTasksDropDown
        function EvaluationTasksDropDownValueChanged(app, event)
            value = app.EvaluationTasksDropDown.Value;
            switch value
                case 'None'
                    return;
                case 'External Density Layout'
                    app.comp_SimTask_EvaluateExternalVoxelBasedDesign = SimTask_EvaluateExternalVoxelBasedDesign(app);                                                                                               
                    app.EvaluationTasksDropDown.Enable = 'off';
                    app.SimulationTasksDropDown.Enable = 'off';
                    app.FEAwithSolidDesignDomainButton.Enable = 'off';
                case 'External Mesh/Graph-based Design'
                    app.comp_SimTask_MeshGraphBasedStructDesign_Func = SimTask_MeshGraphBasedStructDesign(app);
                    app.EvaluationTasksDropDown.Enable = 'off';
                    app.SimulationTasksDropDown.Enable = 'off';
                    app.FEAwithSolidDesignDomainButton.Enable = 'off';           
            end            
        end

        % Menu selected function: TempExportDensityLayouttopoptiMenu
        function TempExportDensityLayouttopoptiMenuSelected(app, event)
            [fileName, dataPath] = uiputfile('*.topopti', 'Select a Path to Write');
            if isnumeric(fileName) || isnumeric(dataPath), return; end
            ofileName = strcat(dataPath,fileName);
            Temp_ExportDensityLayout_topopti(ofileName);            
        end

        % Menu selected function: ShowDesignVolumeRenderingTmpMenu
        function ShowDesignVolumeRenderingTmpMenuSelected(app, event)
            global densityLayout_;
            targetVolumeFile = './out/DesignVolume.nii';
            if ~exist(targetVolumeFile, 'file')
                if isempty(densityLayout_), warning('No design is available!'); return; end
                IO_ExportDesignInVolume_nii(targetVolumeFile);
            end
            
            if 0 %%Temporary for single format
                valueInput = niftiread('./out/DesignVolume.nii');
                valueOutput = double(valueInput);
                niftiwrite(valueOutput, './out/DesignVolume_double.nii');
                system('"./src/vape4d.exe" ./out/DesignVolume_double.nii');
            else
                system('"./src/vape4d.exe" ./out/DesignVolume.nii');
            end            
        end

        % Menu selected function: 
        % ResultVisualAnalyticsVolumeRenderingIsosurfaceMenu
        function ResultVisualAnalyticsVolumeRenderingIsosurfaceMenuSelected(app, event)
            [fileName, dataPath] = uigetfile('*.nii', 'Select a Voxel File in NIFTI format to Open');
            if isnumeric(fileName) || isnumeric(dataPath), return; end
            [~,fileID,fileExtension] = fileparts(fileName);
            if ~strcmp(fileExtension, '.nii')
                warning('Un-supported Mesh Format!');
                return;
            end
            volumeFileName = strcat(dataPath,fileName);
            if 0 %%Temporary for single format
                valueInput = niftiread(volumeFileName);
                valueOutput = double(valueInput);
                outVolumeFileName = strcat(dataPath, fileID, '_double.nii');
                niftiwrite(valueOutput, outVolumeFileName);
                sysCmdChar = strcat('"./src/vape4d.exe"', " ", outVolumeFileName);
                sysCmdChar = convertStringsToChars(sysCmdChar);
                system(sysCmdChar);
            else
                sysCmdChar = strcat('"./src/vape4d.exe"', " ", volumeFileName);
                sysCmdChar = convertStringsToChars(sysCmdChar);                
                system(sysCmdChar);
            end            
        end

        % Value changed function: EnableSelectionBoxCheckBox
        function EnableSelectionBoxCheckBoxValueChanged(app, event)
            global hdSelectionBox_;
            value = app.EnableSelectionBoxCheckBox.Value;
            if value
                cP1 = zeros(1,3); cP2 = cP1;
                cP1(1) = app.CornerPot1XEditField.Value; cP1(2) = app.CornerPot1YEditField.Value; cP1(3) = app.CornerPot1ZEditField.Value;
                cP2(1) = app.CornerPot2XEditField.Value; cP2(2) = app.CornerPot2YEditField.Value; cP2(3) = app.CornerPot2ZEditField.Value;
                Vis_ShowSelectionBox(cP1, cP2);
            else
                set(hdSelectionBox_, 'visible', 'off');
            end
        end

        % Callback function: CornerPot1XEditField, CornerPot1YEditField, 
        % ...and 5 other components
        function UpdateSelectionBoxButtonPushed(app, event)
            app.EnableSelectionBoxCheckBox.Value = 1;
            EnableSelectionBoxCheckBoxValueChanged(app, event);
        end

        % Button pushed function: NodeSelectionButton_2
        function NodeSelectionButton_2Pushed(app, event)
            global axHandle_;
            UpdateSelectionBoxButtonPushed(app, event);
            cP1 = zeros(1,3); cP2 = cP1;
            cP1(1) = app.CornerPot1XEditField.Value; cP1(2) = app.CornerPot1YEditField.Value; cP1(3) = app.CornerPot1ZEditField.Value;
            cP2(1) = app.CornerPot2XEditField.Value; cP2(2) = app.CornerPot2YEditField.Value; cP2(3) = app.CornerPot2ZEditField.Value;            
            Interaction_PickBySelectionBox(axHandle_, cP1, cP2);
        end

        % Button pushed function: NodeUnSelectionButton
        function NodeUnSelectionButtonPushed(app, event)
            global axHandle_;
            UpdateSelectionBoxButtonPushed(app, event);
            cP1 = zeros(1,3); cP2 = cP1;
            cP1(1) = app.CornerPot1XEditField.Value; cP1(2) = app.CornerPot1YEditField.Value; cP1(3) = app.CornerPot1ZEditField.Value;
            cP2(1) = app.CornerPot2XEditField.Value; cP2(2) = app.CornerPot2YEditField.Value; cP2(3) = app.CornerPot2ZEditField.Value;            
            Interaction_UnPickBySelectionBox(axHandle_, cP1, cP2);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.BuiltinShapesMenu = uimenu(app.FileMenu);
            app.BuiltinShapesMenu.Text = 'Built-in Shapes';
            app.CuboidMenu = uimenu(app.BuiltinShapesMenu);
            app.CuboidMenu.MenuSelectedFcn = createCallbackFcn(app, @CuboidMenuSelected, true);
            app.CuboidMenu.Text = 'Cuboid';
            app.LshapeMenu = uimenu(app.BuiltinShapesMenu);
            app.LshapeMenu.MenuSelectedFcn = createCallbackFcn(app, @LshapeMenuSelected, true);
            app.LshapeMenu.Text = 'L-shape';
            app.SphereMenu = uimenu(app.BuiltinShapesMenu);
            app.SphereMenu.MenuSelectedFcn = createCallbackFcn(app, @SphereMenuSelected, true);
            app.SphereMenu.Text = 'Sphere';
            app.CylinderMenu = uimenu(app.BuiltinShapesMenu);
            app.CylinderMenu.MenuSelectedFcn = createCallbackFcn(app, @CylinderMenuSelected, true);
            app.CylinderMenu.Text = 'Cylinder';
            app.ImportArbitraryMeshASCIIMenu = uimenu(app.FileMenu);
            app.ImportArbitraryMeshASCIIMenu.Text = 'Import Arbitrary Mesh (ASCII)';
            app.SurfaceTriMeshplyobjMenu = uimenu(app.ImportArbitraryMeshASCIIMenu);
            app.SurfaceTriMeshplyobjMenu.MenuSelectedFcn = createCallbackFcn(app, @SurfaceTriMeshplyobjMenuSelected, true);
            app.SurfaceTriMeshplyobjMenu.Text = 'Surface Tri Mesh (*.ply, *.obj)';
            app.SolidHexTetMeshmeshmshvtkMenu = uimenu(app.ImportArbitraryMeshASCIIMenu);
            app.SolidHexTetMeshmeshmshvtkMenu.MenuSelectedFcn = createCallbackFcn(app, @SolidHexTetMeshmeshmshvtkMenuSelected, true);
            app.SolidHexTetMeshmeshmshvtkMenu.Text = 'Solid Hex/Tet Mesh (*.mesh, *.msh, *vtk)';
            app.EdgeVertexGraphobjMenu = uimenu(app.ImportArbitraryMeshASCIIMenu);
            app.EdgeVertexGraphobjMenu.MenuSelectedFcn = createCallbackFcn(app, @EdgeVertexGraphobjMenuSelected, true);
            app.EdgeVertexGraphobjMenu.Text = 'Edge-Vertex Graph (*.obj)';
            app.ImportVoxelModelTopVoxelMenu = uimenu(app.FileMenu);
            app.ImportVoxelModelTopVoxelMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportVoxelModelTopVoxelMenuSelected, true);
            app.ImportVoxelModelTopVoxelMenu.Text = 'Import Voxel Model (".TopVoxel")';
            app.ExportVoxelModelTopVoxelMenu = uimenu(app.FileMenu);
            app.ExportVoxelModelTopVoxelMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportVoxelModelTopVoxelMenuSelected, true);
            app.ExportVoxelModelTopVoxelMenu.Text = 'Export Voxel Model (".TopVoxel)';
            app.ExportDesigninVolumeniiMenu = uimenu(app.FileMenu);
            app.ExportDesigninVolumeniiMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDesigninVolumeniiMenuSelected, true);
            app.ExportDesigninVolumeniiMenu.Text = 'Export Design in Volume (*.nii)';
            app.ExportDesigninTriangularMeshMenu = uimenu(app.FileMenu);
            app.ExportDesigninTriangularMeshMenu.Text = 'Export Design in Triangular Mesh';
            app.stlMenu = uimenu(app.ExportDesigninTriangularMeshMenu);
            app.stlMenu.Text = '.stl';
            app.objMenu = uimenu(app.ExportDesigninTriangularMeshMenu);
            app.objMenu.Text = '.obj';
            app.plyMenu = uimenu(app.ExportDesigninTriangularMeshMenu);
            app.plyMenu.Text = '.ply';
            app.TempImportWrappedVoxelModeltxtMenu = uimenu(app.FileMenu);
            app.TempImportWrappedVoxelModeltxtMenu.MenuSelectedFcn = createCallbackFcn(app, @TempImportWrappedVoxelModeltxtMenuSelected, true);
            app.TempImportWrappedVoxelModeltxtMenu.Text = 'Temp Import Wrapped Voxel Model (*.txt)';
            app.TempExportWrappedVoxelModeltxtMenu = uimenu(app.FileMenu);
            app.TempExportWrappedVoxelModeltxtMenu.MenuSelectedFcn = createCallbackFcn(app, @TempExportWrappedVoxelModeltxtMenuSelected, true);
            app.TempExportWrappedVoxelModeltxtMenu.Text = 'Temp Export Wrapped Voxel Model (*.txt)';
            app.TempExportDensityLayouttopoptiMenu = uimenu(app.FileMenu);
            app.TempExportDensityLayouttopoptiMenu.MenuSelectedFcn = createCallbackFcn(app, @TempExportDensityLayouttopoptiMenuSelected, true);
            app.TempExportDensityLayouttopoptiMenu.Text = 'Temp Export Density Layout (*.topopti)';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.VisualizationMenu = uimenu(app.UIFigure);
            app.VisualizationMenu.Text = 'Visualization';
            app.ShowProblemDescriptionMenu = uimenu(app.VisualizationMenu);
            app.ShowProblemDescriptionMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowProblemDescriptionMenuSelected, true);
            app.ShowProblemDescriptionMenu.Text = 'Show Problem Description';
            app.ShowDesignDomainMenu = uimenu(app.VisualizationMenu);
            app.ShowDesignDomainMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowDesignDomainMenuSelected, true);
            app.ShowDesignDomainMenu.Text = 'Show Design Domain';
            app.ShowDeformationMenu = uimenu(app.VisualizationMenu);
            app.ShowDeformationMenu.Text = 'Show Deformation';
            app.UxMenu = uimenu(app.ShowDeformationMenu);
            app.UxMenu.MenuSelectedFcn = createCallbackFcn(app, @UxMenuSelected, true);
            app.UxMenu.Text = 'Ux';
            app.UyMenu = uimenu(app.ShowDeformationMenu);
            app.UyMenu.MenuSelectedFcn = createCallbackFcn(app, @UyMenuSelected, true);
            app.UyMenu.Text = 'Uy';
            app.UzMenu = uimenu(app.ShowDeformationMenu);
            app.UzMenu.MenuSelectedFcn = createCallbackFcn(app, @UzMenuSelected, true);
            app.UzMenu.Text = 'Uz';
            app.TotalMenu_2 = uimenu(app.ShowDeformationMenu);
            app.TotalMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TotalMenu_2Selected, true);
            app.TotalMenu_2.Text = 'Total';
            app.ShowStressScalarComponentsMenu = uimenu(app.VisualizationMenu);
            app.ShowStressScalarComponentsMenu.Text = 'Show Stress (Scalar Components)';
            app.PSLsMenu = uimenu(app.ShowStressScalarComponentsMenu);
            app.PSLsMenu.MenuSelectedFcn = createCallbackFcn(app, @PSLsMenuSelected, true);
            app.PSLsMenu.Text = 'PSLs';
            app.Sigma_xxMenu = uimenu(app.ShowStressScalarComponentsMenu);
            app.Sigma_xxMenu.MenuSelectedFcn = createCallbackFcn(app, @Sigma_xxMenuSelected, true);
            app.Sigma_xxMenu.Text = 'Sigma_xx';
            app.Sigma_yyMenu = uimenu(app.ShowStressScalarComponentsMenu);
            app.Sigma_yyMenu.MenuSelectedFcn = createCallbackFcn(app, @Sigma_yyMenuSelected, true);
            app.Sigma_yyMenu.Text = 'Sigma_yy';
            app.Sigma_zzMenu = uimenu(app.ShowStressScalarComponentsMenu);
            app.Sigma_zzMenu.MenuSelectedFcn = createCallbackFcn(app, @Sigma_zzMenuSelected, true);
            app.Sigma_zzMenu.Text = 'Sigma_zz';
            app.Tau_yzMenu = uimenu(app.ShowStressScalarComponentsMenu);
            app.Tau_yzMenu.MenuSelectedFcn = createCallbackFcn(app, @Tau_yzMenuSelected, true);
            app.Tau_yzMenu.Text = 'Tau_yz';
            app.Tau_zxMenu = uimenu(app.ShowStressScalarComponentsMenu);
            app.Tau_zxMenu.MenuSelectedFcn = createCallbackFcn(app, @Tau_zxMenuSelected, true);
            app.Tau_zxMenu.Text = 'Tau_zx';
            app.Tau_xyMenu = uimenu(app.ShowStressScalarComponentsMenu);
            app.Tau_xyMenu.MenuSelectedFcn = createCallbackFcn(app, @Tau_xyMenuSelected, true);
            app.Tau_xyMenu.Text = 'Tau_xy';
            app.VonMisesMenu = uimenu(app.ShowStressScalarComponentsMenu);
            app.VonMisesMenu.MenuSelectedFcn = createCallbackFcn(app, @VonMisesMenuSelected, true);
            app.VonMisesMenu.Text = 'Von Mises';
            app.ShowDesignbyDensityFieldMenu = uimenu(app.VisualizationMenu);
            app.ShowDesignbyDensityFieldMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowDesignbyDensityFieldMenuSelected, true);
            app.ShowDesignbyDensityFieldMenu.Text = 'Show Design by Density Field';
            app.ShowDesignVolumeRenderingTmpMenu = uimenu(app.VisualizationMenu);
            app.ShowDesignVolumeRenderingTmpMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowDesignVolumeRenderingTmpMenuSelected, true);
            app.ShowDesignVolumeRenderingTmpMenu.Text = 'Show Design (Volume Rendering Tmp)';
            app.ResultVisualAnalyticsVolumeRenderingIsosurfaceMenu = uimenu(app.VisualizationMenu);
            app.ResultVisualAnalyticsVolumeRenderingIsosurfaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ResultVisualAnalyticsVolumeRenderingIsosurfaceMenuSelected, true);
            app.ResultVisualAnalyticsVolumeRenderingIsosurfaceMenu.Text = 'Result Visual Analytics (Volume Rendering & Isosurface)';
            app.OptimizationHistoryMenu = uimenu(app.VisualizationMenu);
            app.OptimizationHistoryMenu.Text = 'Optimization History';
            app.FormattedDisplayMenu = uimenu(app.VisualizationMenu);
            app.FormattedDisplayMenu.Text = 'Formatted Display';
            app.VisInputMeshGraphVoxelsMenu = uimenu(app.VisualizationMenu);
            app.VisInputMeshGraphVoxelsMenu.Text = 'Vis. Input Mesh/Graph/Voxels';
            app.InputTetHexSolidMeshMenu = uimenu(app.VisInputMeshGraphVoxelsMenu);
            app.InputTetHexSolidMeshMenu.MenuSelectedFcn = createCallbackFcn(app, @InputTetHexSolidMeshMenuSelected, true);
            app.InputTetHexSolidMeshMenu.Text = 'Input Tet/Hex Solid Mesh';
            app.InputTriSurfaceMeshMenu = uimenu(app.VisInputMeshGraphVoxelsMenu);
            app.InputTriSurfaceMeshMenu.MenuSelectedFcn = createCallbackFcn(app, @InputTriSurfaceMeshMenuSelected, true);
            app.InputTriSurfaceMeshMenu.Text = 'Input Tri Surface Mesh';
            app.InputEdgeVertexGraphMenu = uimenu(app.VisInputMeshGraphVoxelsMenu);
            app.InputEdgeVertexGraphMenu.MenuSelectedFcn = createCallbackFcn(app, @InputEdgeVertexGraphMenuSelected, true);
            app.InputEdgeVertexGraphMenu.Text = 'Input Edge-Vertex Graph';
            app.InputVoxelsMenu = uimenu(app.VisInputMeshGraphVoxelsMenu);
            app.InputVoxelsMenu.MenuSelectedFcn = createCallbackFcn(app, @InputVoxelsMenuSelected, true);
            app.InputVoxelsMenu.Text = 'Input Voxels';
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.ModelingTab = uitab(app.TabGroup3);
            app.ModelingTab.Title = 'Modeling';
            app.DomainVoxelizationPanel = uipanel(app.ModelingTab);
            app.DomainVoxelizationPanel.Title = 'Domain Voxelization';
            app.MaxResolutionEditFieldLabel = uilabel(app.DomainVoxelizationPanel);
            app.MaxResolutionEditFieldLabel.Text = 'Max Resolution';
            app.MaxResolutionEditField = uieditfield(app.DomainVoxelizationPanel, 'numeric');
            app.MaxResolutionEditField.Value = 256;
            app.CellSizeEditFieldLabel = uilabel(app.DomainVoxelizationPanel);
            app.CellSizeEditFieldLabel.Text = 'Cell Size';
            app.CellSizeEditField = uieditfield(app.DomainVoxelizationPanel, 'numeric');
            app.CellSizeEditField.Value = 1;
            app.VoxelizingButton = uibutton(app.DomainVoxelizationPanel, 'push');
            app.VoxelizingButton.ButtonPushedFcn = createCallbackFcn(app, @VoxelizingButtonPushed, true);
            app.VoxelizingButton.Text = 'Voxelizing';
            app.ElementsEditFieldLabel = uilabel(app.DomainVoxelizationPanel);
            app.ElementsEditFieldLabel.Text = '#Elements';
            app.ElementsEditField = uieditfield(app.DomainVoxelizationPanel, 'numeric');
            app.DOFsEditFieldLabel = uilabel(app.DomainVoxelizationPanel);
            app.DOFsEditFieldLabel.Text = '#DOFs';
            app.DOFsEditField = uieditfield(app.DomainVoxelizationPanel, 'numeric');
            app.ApplyforBoundaryConditionsPanel = uipanel(app.ModelingTab);
            app.ApplyforBoundaryConditionsPanel.Title = 'Apply for Boundary Conditions';
            app.AdditionalNodeSelectionOptionsButton = uibutton(app.ApplyforBoundaryConditionsPanel, 'push');
            app.AdditionalNodeSelectionOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @AdditionalNodeSelectionOptionsButtonPushed, true);
            app.AdditionalNodeSelectionOptionsButton.Text = 'Additional Node Selection Options';
            app.EnableSelectionBoxCheckBox = uicheckbox(app.ApplyforBoundaryConditionsPanel);
            app.EnableSelectionBoxCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableSelectionBoxCheckBoxValueChanged, true);
            app.EnableSelectionBoxCheckBox.Text = 'Enable Selection Box';
            app.CornerPot1XLabel = uilabel(app.ApplyforBoundaryConditionsPanel);
            app.CornerPot1XLabel.Text = 'Corner Pot 1 (*): X';
            app.CornerPot1XEditField = uieditfield(app.ApplyforBoundaryConditionsPanel, 'numeric');
            app.CornerPot1XEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateSelectionBoxButtonPushed, true);
            app.CornerPot2XLabel = uilabel(app.ApplyforBoundaryConditionsPanel);
            app.CornerPot2XLabel.Text = 'Corner Pot 2 (+): X';
            app.CornerPot2XEditField = uieditfield(app.ApplyforBoundaryConditionsPanel, 'numeric');
            app.CornerPot2XEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateSelectionBoxButtonPushed, true);
            app.CornerPot1YEditFieldLabel = uilabel(app.ApplyforBoundaryConditionsPanel);
            app.CornerPot1YEditFieldLabel.Text = 'Corner Pot 1 (*): Y';
            app.CornerPot1YEditField = uieditfield(app.ApplyforBoundaryConditionsPanel, 'numeric');
            app.CornerPot1YEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateSelectionBoxButtonPushed, true);
            app.CornerPot2YLabel = uilabel(app.ApplyforBoundaryConditionsPanel);
            app.CornerPot2YLabel.Text = 'Corner Pot 2 (+): Y';
            app.CornerPot2YEditField = uieditfield(app.ApplyforBoundaryConditionsPanel, 'numeric');
            app.CornerPot2YEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateSelectionBoxButtonPushed, true);
            app.CornerPot1ZEditFieldLabel = uilabel(app.ApplyforBoundaryConditionsPanel);
            app.CornerPot1ZEditFieldLabel.Text = 'Corner Pot 1 (*): Z';
            app.CornerPot1ZEditField = uieditfield(app.ApplyforBoundaryConditionsPanel, 'numeric');
            app.CornerPot1ZEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateSelectionBoxButtonPushed, true);
            app.CornerPot2ZEditFieldLabel = uilabel(app.ApplyforBoundaryConditionsPanel);
            app.CornerPot2ZEditFieldLabel.Text = 'Corner Pot 2 (+): Z';
            app.CornerPot2ZEditField = uieditfield(app.ApplyforBoundaryConditionsPanel, 'numeric');
            app.CornerPot2ZEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateSelectionBoxButtonPushed, true);
            app.UpdateSelectionBoxButton = uibutton(app.ApplyforBoundaryConditionsPanel, 'push');
            app.UpdateSelectionBoxButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSelectionBoxButtonPushed, true);
            app.UpdateSelectionBoxButton.Text = 'Update Selection Box';
            app.NodeUnSelectionButton = uibutton(app.ApplyforBoundaryConditionsPanel, 'push');
            app.NodeUnSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @NodeUnSelectionButtonPushed, true);
            app.NodeUnSelectionButton.Text = 'Node Un-Selection';
            app.NodeSelectionButton_2 = uibutton(app.ApplyforBoundaryConditionsPanel, 'push');
            app.NodeSelectionButton_2.ButtonPushedFcn = createCallbackFcn(app, @NodeSelectionButton_2Pushed, true);
            app.NodeSelectionButton_2.Text = 'Node Selection';
            app.TabGroup2 = uitabgroup(app.ModelingTab);
            app.LoadingTab = uitab(app.TabGroup2);
            app.LoadingTab.Title = 'Loading';
            app.FxLabel = uilabel(app.LoadingTab);
            app.FxLabel.Text = 'Fx (N)';
            app.FxNEditField = uieditfield(app.LoadingTab, 'numeric');
            app.ApplyforLoadsButton = uibutton(app.LoadingTab, 'push');
            app.ApplyforLoadsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyforLoadsButtonPushed, true);
            app.ApplyforLoadsButton.Text = 'Apply for Loads';
            app.ClearLoadsButton = uibutton(app.LoadingTab, 'push');
            app.ClearLoadsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLoadsButtonPushed, true);
            app.ClearLoadsButton.Text = 'Clear Loads';
            app.FyNEditFieldLabel = uilabel(app.LoadingTab);
            app.FyNEditFieldLabel.Text = 'Fy (N)';
            app.FyNEditField = uieditfield(app.LoadingTab, 'numeric');
            app.FzNEditFieldLabel = uilabel(app.LoadingTab);
            app.FzNEditFieldLabel.Text = 'Fz (N)';
            app.FzNEditField = uieditfield(app.LoadingTab, 'numeric');
            app.FixingTab = uitab(app.TabGroup2);
            app.FixingTab.Title = 'Fixing';
            app.ZDirFixedCheckBox = uicheckbox(app.FixingTab);
            app.ZDirFixedCheckBox.Text = 'Z-Dir Fixed';
            app.ZDirFixedCheckBox.Value = true;
            app.ApplyforFixationButton = uibutton(app.FixingTab, 'push');
            app.ApplyforFixationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyforFixationButtonPushed, true);
            app.ApplyforFixationButton.Text = 'Apply for Fixation';
            app.ClearFixationButton = uibutton(app.FixingTab, 'push');
            app.ClearFixationButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFixationButtonPushed, true);
            app.ClearFixationButton.Text = 'Clear Fixation';
            app.YDirFixedCheckBox = uicheckbox(app.FixingTab);
            app.YDirFixedCheckBox.Text = 'Y-Dir Fixed';
            app.YDirFixedCheckBox.Value = true;
            app.XDirFixedCheckBox = uicheckbox(app.FixingTab);
            app.XDirFixedCheckBox.Text = 'X-Dir Fixed';
            app.XDirFixedCheckBox.Value = true;
            app.BuiltinBoundaryConditionsforCantileverModelPanel = uipanel(app.ModelingTab);
            app.BuiltinBoundaryConditionsforCantileverModelPanel.Title = 'Built-in Boundary Conditions for Cantilever Model';
            app.BuiltinBCsforCuboidDesignDomainDropDownLabel = uilabel(app.BuiltinBoundaryConditionsforCantileverModelPanel);
            app.BuiltinBCsforCuboidDesignDomainDropDownLabel.Text = 'Built-in BCs for Cuboid Design Domain';
            app.BuiltinBCsforCuboidDesignDomainDropDown = uidropdown(app.BuiltinBoundaryConditionsforCantileverModelPanel);
            app.BuiltinBCsforCuboidDesignDomainDropDown.Items = {'None', 'Cantilever 3', 'Cantilever 4', 'Cantilever 5', 'Cantilever 6', 'Cantilever 7', 'Cantilever 8', 'Cantilever 9', 'MBB 1', 'Michel'};
            app.BuiltinBCsforCuboidDesignDomainDropDown.ValueChangedFcn = createCallbackFcn(app, @BuiltinBCsforCuboidDesignDomainDropDownValueChanged, true);
            app.BuiltinBCsforCuboidDesignDomainDropDown.Value = 'None';
            app.SimulationTab = uitab(app.TabGroup3);
            app.SimulationTab.Title = 'Simulation';
            app.MaterialPropertiesPanel = uipanel(app.SimulationTab);
            app.MaterialPropertiesPanel.Title = 'Material Properties';
            app.YoungsModulusStiffmaterialEditFieldLabel = uilabel(app.MaterialPropertiesPanel);
            app.YoungsModulusStiffmaterialEditFieldLabel.Text = 'Young''s Modulus (Stiff material)';
            app.YoungsModulusStiffmaterialEditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.YoungsModulusStiffmaterialEditField.Value = 1;
            app.PoissonsRatioEditFieldLabel = uilabel(app.MaterialPropertiesPanel);
            app.PoissonsRatioEditFieldLabel.Text = 'Poisson''s Ratio';
            app.PoissonsRatioEditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.PoissonsRatioEditField.Value = 0.3;
            app.YoungsModulusCompliantmaterialEditFieldLabel = uilabel(app.MaterialPropertiesPanel);
            app.YoungsModulusCompliantmaterialEditFieldLabel.Text = 'Young''s Modulus (Compliant material)';
            app.YoungsModulusCompliantmaterialEditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.YoungsModulusCompliantmaterialEditField.Value = 1e-06;
            app.LinearSystemSolverPanel = uipanel(app.SimulationTab);
            app.LinearSystemSolverPanel.Title = 'Linear System Solver';
            app.ResidualThresholdEditFieldLabel = uilabel(app.LinearSystemSolverPanel);
            app.ResidualThresholdEditFieldLabel.Text = 'Residual Threshold';
            app.ResidualThresholdEditField = uieditfield(app.LinearSystemSolverPanel, 'numeric');
            app.ResidualThresholdEditField.Value = 0.001;
            app.MaximumIterationsEditFieldLabel = uilabel(app.LinearSystemSolverPanel);
            app.MaximumIterationsEditFieldLabel.Text = '#Maximum Iterations';
            app.MaximumIterationsEditField = uieditfield(app.LinearSystemSolverPanel, 'numeric');
            app.MaximumIterationsEditField.Value = 200;
            app.WeightingFactorofJacobiSmoothingProcessEditFieldLabel = uilabel(app.LinearSystemSolverPanel);
            app.WeightingFactorofJacobiSmoothingProcessEditFieldLabel.Text = 'Weighting Factor of Jacobi Smoothing Process';
            app.WeightingFactorofJacobiSmoothingProcessEditField = uieditfield(app.LinearSystemSolverPanel, 'numeric');
            app.WeightingFactorofJacobiSmoothingProcessEditField.Value = 0.35;
            app.MaximumElementsontheCoarsestLevelEditFieldLabel = uilabel(app.LinearSystemSolverPanel);
            app.MaximumElementsontheCoarsestLevelEditFieldLabel.Text = '#Maximum Elements on the Coarsest Level';
            app.MaximumElementsontheCoarsestLevelEditField = uieditfield(app.LinearSystemSolverPanel, 'numeric');
            app.MaximumElementsontheCoarsestLevelEditField.Value = 10000;
            app.NonDyadicCheckBox = uicheckbox(app.LinearSystemSolverPanel);
            app.NonDyadicCheckBox.Text = 'Non Dyadic';
            app.NonDyadicCheckBox.Value = true;
            app.MEXFuncCheckBox = uicheckbox(app.LinearSystemSolverPanel);
            app.MEXFuncCheckBox.Text = 'MEX Func.';
            app.MEXFuncCheckBox.Value = true;
            app.MaterialLayoutDesignPanel = uipanel(app.SimulationTab);
            app.MaterialLayoutDesignPanel.Title = 'Material Layout Design';
            app.SimulationTasksDropDownLabel = uilabel(app.MaterialLayoutDesignPanel);
            app.SimulationTasksDropDownLabel.Text = 'Simulation Tasks';
            app.SimulationTasksDropDown = uidropdown(app.MaterialLayoutDesignPanel);
            app.SimulationTasksDropDown.Items = {'None', 'Topology Optimization', 'PSLs-guided Infill Design', 'Stress-aligned Volumetric Michell Trusses Infill Design', 'Stress-aligned Conforming Lattice Infill Design', 'Stress-aware Graded Voronoi Diagram Infill Design'};
            app.SimulationTasksDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulationTasksDropDownValueChanged, true);
            app.SimulationTasksDropDown.Value = 'None';
            app.StiffnessEvaluationPanel = uipanel(app.SimulationTab);
            app.StiffnessEvaluationPanel.Title = 'Stiffness Evaluation';
            app.SolidComplianceEditFieldLabel = uilabel(app.StiffnessEvaluationPanel);
            app.SolidComplianceEditFieldLabel.Text = 'Solid Compliance';
            app.SolidComplianceEditField = uieditfield(app.StiffnessEvaluationPanel, 'numeric');
            app.FEAwithSolidDesignDomainButton = uibutton(app.StiffnessEvaluationPanel, 'push');
            app.FEAwithSolidDesignDomainButton.ButtonPushedFcn = createCallbackFcn(app, @FEAwithSolidDesignDomainButtonPushed, true);
            app.FEAwithSolidDesignDomainButton.Text = 'FEA with Solid Design Domain';
            app.StressAnalysisButton = uibutton(app.StiffnessEvaluationPanel, 'push');
            app.StressAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StressAnalysisButtonPushed, true);
            app.StressAnalysisButton.Text = 'Stress Analysis';
            app.EvaluationTasksDropDownLabel = uilabel(app.StiffnessEvaluationPanel);
            app.EvaluationTasksDropDownLabel.Text = 'Evaluation Tasks';
            app.EvaluationTasksDropDown = uidropdown(app.StiffnessEvaluationPanel);
            app.EvaluationTasksDropDown.Items = {'None', 'External Density Layout', 'External Mesh/Graph-based Design'};
            app.EvaluationTasksDropDown.ValueChangedFcn = createCallbackFcn(app, @EvaluationTasksDropDownValueChanged, true);
            app.EvaluationTasksDropDown.Value = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SGLDBench(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end